classdef TWS100 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TimescaleDropDownLabel       matlab.ui.control.Label
        TimescaleDropDown            matlab.ui.control.DropDown
        PlotTypeDropDown             matlab.ui.control.DropDown
        PlotTypeDropDownLabel        matlab.ui.control.Label
        BasinSelectionButton         matlab.ui.control.DropDown
        BasinDropDownLabel           matlab.ui.control.Label
        LoadDataButton               matlab.ui.control.StateButton
        TimeSpan19012025Slider       matlab.ui.control.RangeSlider
        TimeSpan19012025SliderLabel  matlab.ui.control.Label
        validation1                  matlab.ui.container.CheckBoxTree
        GroundwaterNode              matlab.ui.container.TreeNode
        CGWBNode                     matlab.ui.container.TreeNode
        GRACENode                    matlab.ui.container.TreeNode
        PrecipitationNode_2          matlab.ui.container.TreeNode
        IMDNode_2                    matlab.ui.container.TreeNode
        evalution_metrices           matlab.ui.container.CheckBoxTree
        EvaluationMetricsNode        matlab.ui.container.TreeNode
        CCNode                       matlab.ui.container.TreeNode
        NSENode                      matlab.ui.container.TreeNode
        RSMENode                     matlab.ui.container.TreeNode
        SensitivityAnalysisNode      matlab.ui.container.TreeNode
        ExportPlotButton             matlab.ui.control.StateButton
        Trend                        matlab.ui.container.CheckBoxTree
        TrendsNode                   matlab.ui.container.TreeNode
        LinearNode                   matlab.ui.container.TreeNode
        PolynomialNode               matlab.ui.container.TreeNode
        LoessNode                    matlab.ui.container.TreeNode
        SensslopeNode                matlab.ui.container.TreeNode
        Precipitation                matlab.ui.container.CheckBoxTree
        PrecipitationNode            matlab.ui.container.TreeNode
        IMDNode                      matlab.ui.container.TreeNode
        ERA5Node                     matlab.ui.container.TreeNode
        GPCPNode                     matlab.ui.container.TreeNode
        This_Study                   matlab.ui.container.CheckBoxTree
        ThisStudyNode                matlab.ui.container.TreeNode
        ANN_GNode                    matlab.ui.container.TreeNode
        RF_GNode                     matlab.ui.container.TreeNode
        ANN_IMDNode                  matlab.ui.container.TreeNode
        RF_IMDNode                   matlab.ui.container.TreeNode
        ReconstructionNode           matlab.ui.container.TreeNode
        RTWSA                        matlab.ui.container.CheckBoxTree
        Previous_TWSANode            matlab.ui.container.TreeNode
        HG19Node                     matlab.ui.container.TreeNode
        D23Node                      matlab.ui.container.TreeNode
        L21Node                      matlab.ui.container.TreeNode
        M25Node                      matlab.ui.container.TreeNode
        P25Node                      matlab.ui.container.TreeNode
        TWSA                         matlab.ui.container.CheckBoxTree
        GRACETWSANode                matlab.ui.container.TreeNode
        JPLNode                      matlab.ui.container.TreeNode
        CSRNode                      matlab.ui.container.TreeNode
        GSFCNode                     matlab.ui.container.TreeNode
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        MainAxesOriginalPosition
        % Variable to store IMD data
        IMDData;IMDLat;IMDLon;IMDTime;IMDMN
        % Variable to store GPCP data
        GPCPData;GPCPTime;GPCPLat;GPCPLon;GPCPMN
        % Variable to store ERA5 data
        ERA5Data;ERA5Time;ERA5Lat;ERA5Lon;ERA5MN
        % Variable to store Humphrey reconstructed data
        Hp_GERECData;Hp_GERECTime;Hp_GERECLat;Hp_GERECLon;Hp_GERECMN
        Hp_GGRECData;Hp_GGRECTime;Hp_GGRECLat;Hp_GGRECLon;Hp_GGRECMN
        Hp_GMRECData;Hp_GMRECTime;Hp_GMRECLat;Hp_GMRECLon;Hp_GMRECMN
        Hp_JERECData;Hp_JERECTime;Hp_JERECLat;Hp_JERECLon;Hp_JERECMN
        Hp_JGRECData;Hp_JGRECTime;Hp_JGRECLat;Hp_JGRECLon;Hp_JGRECMN
        Hp_JMRECData;Hp_JMRECTime;Hp_JMRECLat;Hp_JMRECLon;Hp_JMRECMN
        Hp_JPL_TWS_BA;Hp_CSR_TWS_BA
        % Variable to store Yin reconstructed data
        YinRECData
        % Variable to store Li reconstructed data
        LiRECData;LiRECTime;LiRECLat;LiRECLon;LiRECMN
        % Variable to store JPL TWS data
        JPLTWSData;JPLTWSTime;JPLTWSLat;JPLTWSLon;JPLTWSMN
        % Variable to store CSR TWS data
        CSRTWSData;CSRTWSTime;CSRTWSLat;CSRTWSLon;CSRTWSMN
        % Variable to store GFSC TWS data
        GFSCTWSData;GFSCTWSTime;GFSCTWSLat;GFSCTWSLon;GFSCTWSMN
        Basins;selectedStartTime;selectedEndTime;StartTimeLabel;EndTimeLabel;H_REC;IMD_BA;GPCP_BA;ERA5_BA
        % Deng 2023
        DengRECData;DengRECTime;DengRECLat;DengRECLon;DengRECMN
        % Mandal 2025
        MandalRECData;MandalRECTime;MandalRECLat;MandalRECLon;MandalRECMN
        % Nabakishor 2025
        NabakishorRECData;NabakishorRECTime;NabakishorRECLat;NabakishorRECLon;NabakishorRECMN

        % Palazzoli 2025 - multiple models
        PalazzoliLSTMData;PalazzoliLSTMTime;PalazzoliLSTMLat;PalazzoliLSTMLon;PalazzoliLSTMMN

        PalazzoliLSTMnoLCSIFData;PalazzoliLSTMnoLCSIFTime;PalazzoliLSTMnoLCSIFLat;PalazzoliLSTMnoLCSIFLon;PalazzoliLSTMnoLCSIFMN

        PalazzoliBiLSTMData;PalazzoliBiLSTMTime;PalazzoliBiLSTMLat;PalazzoliBiLSTMLon;PalazzoliBiLSTMMN

        PalazzoliBiLSTMnoLCSIFData;PalazzoliBiLSTMnoLCSIFTime;PalazzoliBiLSTMnoLCSIFLat;PalazzoliBiLSTMnoLCSIFLon;PalazzoliBiLSTMnoLCSIFMN

        % ================= THIS STUDY - multiple models =================
        ThisStudyBestData;ThisStudyBestTime;ThisStudyBestLat;ThisStudyBestLon;ThisStudyBestMN

        ThisStudyANNData;ThisStudyANNTime;ThisStudyANNLat;ThisStudyANNLon;ThisStudyANNMN

        ThisStudyLSTMData;ThisStudyLSTMTime;ThisStudyLSTMLat;ThisStudyLSTMLon;ThisStudyLSTMMN

        ThisStudyRFData;ThisStudyRFTime;ThisStudyRFLat;ThisStudyRFLon;ThisStudyRFMN
        GLDAS_PData;GLDAS_PTime;GLDAS_PLat;GLDAS_PLon;GLDAS_PMN

        GLDAS_ETData; GLDAS_ETTime; GLDAS_ETLat; GLDAS_ETLon; GLDAS_ETMN

        GLDAS_SMData; GLDAS_SMTime; GLDAS_SMLat; GLDAS_SMLon; GLDAS_SMMN

        GLDAS_TData; GLDAS_TTime; GLDAS_TLat; GLDAS_TLon; GLDAS_TMN

        GLDAS_WSData; GLDAS_WSTime; GLDAS_WSLat; GLDAS_WSLon; GLDAS_WSMN

        GLDAS_SHData; GLDAS_SHTime; GLDAS_SHLat; GLDAS_SHLon; GLDAS_SHMN

        GLDAS_LWData; GLDAS_LWTime; GLDAS_LWLat; GLDAS_LWLon; GLDAS_LWMN

        GLDAS_SWData; GLDAS_SWTime; GLDAS_SWLat; GLDAS_SWLon; GLDAS_SWMN

        CGWBData;CGWBAnomalies; CGWBAvg; CGWBTime; CGWBLat; CGWBLon; SensitivityData; SensitivityNavHandles; tornadoAxes
        MainAxesOriginalUnits
        NewAxes matlab.ui.control.UIAxes
        CGWBAxes matlab.ui.control.UIAxes
        GRACEAxes matlab.ui.control.UIAxes
        PrecipitationAxes matlab.ui.control.UIAxes
        DynamicAxes matlab.ui.control.UIAxes
        IMD_datetime; JPL_datetime; GLDAS_datetime;CurrentBasin
    end
    properties (Access = public)
        ReconstructionConfig struct
    end
    methods (Access = private)
     function config = showReconstructionConfigDialog(app, methodType)
    % Creates a modal popup for hyperparameter and dataset selection
    % Returns a struct with all user choices, or empty if cancelled

    config = struct();
    config.cancelled = true;

    d = uifigure('Name', sprintf('%s Configuration', methodType), ...
        'Position', [500 300 400 460], 'WindowStyle', 'modal');

    % ==================== DATASET SELECTION ====================
    lbl0 = uilabel(d, 'Text', 'Forcing Dataset:', ...
        'Position', [20 420 150 22], 'FontWeight', 'bold');
    ddDataset = uidropdown(d, 'Position', [180 420 180 22], ...
        'Items', {'GLDAS only', 'IMD Precipitation + GLDAS'}, ...
        'Value', 'GLDAS only');

    lbl0b = uilabel(d, 'Text', 'GRACE Product:', ...
        'Position', [20 385 150 22], 'FontWeight', 'bold');
    ddGrace = uidropdown(d, 'Position', [180 385 180 22], ...
        'Items', {'JPL', 'CSR', 'GSFC'}, 'Value', 'JPL');

    if strcmpi(methodType, 'RF')
        % ==================== RF HYPERPARAMETERS ====================
        lbl1 = uilabel(d, 'Text', 'Number of Trees:', ...
            'Position', [20 330 150 22]);
        spNTrees = uispinner(d, 'Position', [180 330 180 22], ...
            'Limits', [50 1000], 'Value', 300, 'Step', 50);

        lbl2 = uilabel(d, 'Text', 'Min Leaf Size:', ...
            'Position', [20 290 150 22]);
        spMinLeaf = uispinner(d, 'Position', [180 290 180 22], ...
            'Limits', [1 100], 'Value', 3, 'Step', 1);

        lbl3 = uilabel(d, 'Text', 'Max Num Splits:', ...
            'Position', [20 250 150 22]);
        spMaxSplits = uispinner(d, 'Position', [180 250 180 22], ...
            'Limits', [10 500], 'Value', 50, 'Step', 10);

    elseif strcmpi(methodType, 'ANN')
        % ==================== ANN HYPERPARAMETERS ====================
        lbl1 = uilabel(d, 'Text', 'Hidden Layers (comma-sep):', ...
            'Position', [20 330 180 22]);
        efLayers = uieditfield(d, 'text', 'Position', [200 330 160 22], ...
            'Value', '20,10');

        lbl2 = uilabel(d, 'Text', 'Max Epochs:', ...
            'Position', [20 290 150 22]);
        spEpochs = uispinner(d, 'Position', [180 290 180 22], ...
            'Limits', [50 2000], 'Value', 500, 'Step', 50);

        lbl3 = uilabel(d, 'Text', 'Early Stop Patience:', ...
            'Position', [20 250 150 22]);
        spMaxFail = uispinner(d, 'Position', [180 250 180 22], ...
            'Limits', [5 100], 'Value', 20, 'Step', 5);
    end

    % ==================== LAG MONTHS (shared by RF and ANN) ====================
    % THIS WAS MISSING — the spinner control itself, not just the read-line.
    lbl5 = uilabel(d, 'Text', 'Lag Months:', ...
        'Position', [20 205 150 22], 'FontWeight', 'bold');
    spLagMonths = uispinner(d, 'Position', [180 205 180 22], ...
        'Limits', [1 12], 'Value', 2, 'Step', 1, ...
        'Tooltip', 'Number of preceding months used as lagged predictors (e.g. 2 = t-1 and t-2). Default: 2.');

    % ==================== CAUSAL MODE TOGGLE ====================
    lbl4 = uilabel(d, 'Text', 'Predictor Strategy:', ...
        'Position', [20 160 150 22], 'FontWeight', 'bold');
    ddCausal = uidropdown(d, 'Position', [180 160 180 22], ...
        'Items', {'Lags only (causal, recommended)', 'Current month + lags'}, ...
        'Value', 'Lags only (causal, recommended)');

    % ==================== OK / CANCEL BUTTONS ====================
    btnOK = uibutton(d, 'push', 'Text', 'Run Reconstruction', ...
        'Position', [200 30 180 35], 'BackgroundColor', [0.2 0.6 0.3], ...
        'FontColor', 'white', 'FontWeight', 'bold', ...
        'ButtonPushedFcn', @(btn,event) okPressed());
    btnCancel = uibutton(d, 'push', 'Text', 'Cancel', ...
        'Position', [20 30 150 35], ...
        'ButtonPushedFcn', @(btn,event) cancelPressed());

    uiwait(d);

    function okPressed()
        config.cancelled = false;
        config.datasetChoice = ddDataset.Value;
        config.graceProduct = ddGrace.Value;
        config.useCurrentMonth = strcmp(ddCausal.Value, 'Current month + lags');
        config.LagMonths = round(spLagMonths.Value);   % now spLagMonths actually exists

        if strcmpi(methodType, 'RF')
            config.NTrees = round(spNTrees.Value);
            config.MinLeafSize = round(spMinLeaf.Value);
            config.MaxNumSplits = round(spMaxSplits.Value);
        elseif strcmpi(methodType, 'ANN')
            tokens = strsplit(efLayers.Value, ',');
            layers = zeros(1, numel(tokens));
            for k = 1:numel(tokens)
                v = str2double(strtrim(tokens{k}));
                if isnan(v) || v < 1
                    v = 10;
                end
                layers(k) = round(v);
            end
            config.HiddenLayers = layers;
            config.MaxEpochs = round(spEpochs.Value);
            config.MaxFail = round(spMaxFail.Value);
        end

        delete(d);
    end

    function cancelPressed()
        config.cancelled = true;
        delete(d);
    end
end
        function [data, time, lat, lon, mappedTime] = loadNetCDFData(app, filepath, varname, latname, longname, timename)
            % Load data, coordinates, and convert NetCDF time to MATLAB datetime

            % Initialize outputs to avoid returning nothing after errors
            data = [];
            time = [];
            lat = [];
            lon = [];
            mappedTime = [];

            try
                % Load main variable and coordinates
                data = ncread(filepath, varname);
                lat  = ncread(filepath, latname);
                lon  = ncread(filepath, longname);
                rawTime = ncread(filepath, timename);

                % Clear UIAxes
                cla(app.UIAxes);

                % Get time units attribute
                info = ncinfo(filepath);
                varIndex = strcmp({info.Variables.Name}, timename);

                if ~any(varIndex)
                    errordlg(sprintf('Time variable "%s" not found in file.', timename));
                    return;
                end

                timeVar = info.Variables(varIndex);

                % Extract time units
                timeUnits = '';
                for i = 1:length(timeVar.Attributes)
                    if strcmpi(timeVar.Attributes(i).Name, 'units')
                        timeUnits = timeVar.Attributes(i).Value;
                        break;
                    end
                end

                if isempty(timeUnits)
                    errordlg('Time units attribute missing in NetCDF file.');
                    return;
                end

                % Parse base date
                if contains(timeUnits, 'since', 'IgnoreCase', true)
                    tokens = regexp(timeUnits, 'since\s*(\d{4}-\d{2}-\d{2})', 'tokens', 'once');
                    if isempty(tokens)
                        errordlg(sprintf('Unable to parse base date from units: %s', timeUnits));
                        return;
                    end
                    baseDate = datetime(tokens{1}, 'InputFormat','yyyy-MM-dd');
                else
                    errordlg(sprintf('Unsupported time unit format: %s', timeUnits));
                    return;
                end

                % Convert raw time values to MATLAB datetime
                if contains(timeUnits, 'days', 'IgnoreCase', true)
                    time = baseDate + days(rawTime);
                elseif contains(timeUnits, 'hours', 'IgnoreCase', true)
                    time = baseDate + hours(rawTime);
                elseif contains(timeUnits, 'minutes', 'IgnoreCase', true)
                    time = baseDate + minutes(rawTime);
                elseif contains(timeUnits, 'seconds', 'IgnoreCase', true)
                    time = baseDate + seconds(rawTime);
                else
                    errordlg(sprintf('Unsupported time step units in file: %s', timeUnits));
                    return;
                end

                % Create MM-yyyy formatted strings
                timeFormatted = string(datestr(time, 'mm-yyyy'));

                % Build mapping range 1901–2100
                startDate = datetime(1901,1,1);
                endDate   = datetime(2100,12,1);
                allMonths = startDate : calmonths(1) : endDate;
                allMonthStrings = string(datestr(allMonths, 'mm-yyyy'));

                % Map to indices
                mappedTime = arrayfun(@(t) find(allMonthStrings == t, 1,'first'), timeFormatted);

                if any(isnan(mappedTime))
                    errordlg('Some time values could not be mapped between 1901–2100.');
                end

            catch ME
                errordlg(sprintf('Error reading NetCDF file:\n\n%s', ME.message));
            end
        end
        function clearBasinData(app)
            % Clear all basin-specific data properties to free memory
            propertiesToClear = {
                app.IMDData;           app.IMDLat;           app.IMDLon;           app.IMDTime;           app.IMDMN;
                app.GPCPData;          app.GPCPTime;         app.GPCPLat;          app.GPCPLon;          app.GPCPMN;
                app.ERA5Data;          app.ERA5Time;         app.ERA5Lat;          app.ERA5Lon;          app.ERA5MN;

                app.Hp_GERECData;      app.Hp_GERECTime;     app.Hp_GERECLat;      app.Hp_GERECLon;      app.Hp_GERECMN;
                app.Hp_GGRECData;      app.Hp_GGRECTime;     app.Hp_GGRECLat;      app.Hp_GGRECLon;      app.Hp_GGRECMN;
                app.Hp_GMRECData;      app.Hp_GMRECTime;     app.Hp_GMRECLat;      app.Hp_GMRECLon;      app.Hp_GMRECMN;

                app.Hp_JERECData;      app.Hp_JERECTime;     app.Hp_JERECLat;      app.Hp_JERECLon;      app.Hp_JERECMN;
                app.Hp_JGRECData;      app.Hp_JGRECTime;     app.Hp_JGRECLat;      app.Hp_JGRECLon;      app.Hp_JGRECMN;
                app.Hp_JMRECData;      app.Hp_JMRECTime;     app.Hp_JMRECLat;      app.Hp_JMRECLon;      app.Hp_JMRECMN;

                app.Hp_JPL_TWS_BA;     app.Hp_CSR_TWS_BA;
                app.YinRECData;

                app.LiRECData;         app.LiRECTime;        app.LiRECLat;         app.LiRECLon;         app.LiRECMN;

                app.JPLTWSData;        app.JPLTWSTime;       app.JPLTWSLat;        app.JPLTWSLon;        app.JPLTWSMN;
                app.CSRTWSData;        app.CSRTWSTime;       app.CSRTWSLat;        app.CSRTWSLon;        app.CSRTWSMN;
                app.GFSCTWSData;       app.GFSCTWSTime;      app.GFSCTWSLat;       app.GFSCTWSLon;       app.GFSCTWSMN;

                app.Basins;
                app.selectedStartTime; app.selectedEndTime;
                app.StartTimeLabel;    app.EndTimeLabel;

                app.H_REC;
                app.IMD_BA;            app.GPCP_BA;          app.ERA5_BA;

                app.DengRECData;      app.DengRECTime;       app.DengRECLat;      app.DengRECLon;      app.DengRECMN;
                app.MandalRECData;    app.MandalRECTime;     app.MandalRECLat;    app.MandalRECLon;    app.MandalRECMN;
                app.NabakishorRECData; app.NabakishorRECTime; app.NabakishorRECLat; app.NabakishorRECLon; app.NabakishorRECMN;

                app.PalazzoliLSTMData;         app.PalazzoliLSTMTime;         app.PalazzoliLSTMLat;         app.PalazzoliLSTMLon;         app.PalazzoliLSTMMN;
                app.PalazzoliLSTMnoLCSIFData;  app.PalazzoliLSTMnoLCSIFTime;  app.PalazzoliLSTMnoLCSIFLat;  app.PalazzoliLSTMnoLCSIFLon;  app.PalazzoliLSTMnoLCSIFMN;

                app.PalazzoliBiLSTMData;       app.PalazzoliBiLSTMTime;       app.PalazzoliBiLSTMLat;       app.PalazzoliBiLSTMLon;       app.PalazzoliBiLSTMMN;
                app.PalazzoliBiLSTMnoLCSIFData; app.PalazzoliBiLSTMnoLCSIFTime; app.PalazzoliBiLSTMnoLCSIFLat; app.PalazzoliBiLSTMnoLCSIFLon; app.PalazzoliBiLSTMnoLCSIFMN;

                app.ThisStudyBestData;   app.ThisStudyBestTime;   app.ThisStudyBestLat;   app.ThisStudyBestLon;   app.ThisStudyBestMN;
                app.ThisStudyANNData;    app.ThisStudyANNTime;    app.ThisStudyANNLat;    app.ThisStudyANNLon;    app.ThisStudyANNMN;
                app.ThisStudyLSTMData;   app.ThisStudyLSTMTime;   app.ThisStudyLSTMLat;   app.ThisStudyLSTMLon;   app.ThisStudyLSTMMN;
                app.ThisStudyRFData;     app.ThisStudyRFTime;     app.ThisStudyRFLat;     app.ThisStudyRFLon;     app.ThisStudyRFMN;

                app.GLDAS_PData;   app.GLDAS_PTime;   app.GLDAS_PLat;   app.GLDAS_PLon;   app.GLDAS_PMN;
                app.GLDAS_ETData;  app.GLDAS_ETTime;  app.GLDAS_ETLat;  app.GLDAS_ETLon;  app.GLDAS_ETMN;
                app.GLDAS_SMData;  app.GLDAS_SMTime;  app.GLDAS_SMLat;  app.GLDAS_SMLon;  app.GLDAS_SMMN;
                app.GLDAS_TData;   app.GLDAS_TTime;   app.GLDAS_TLat;   app.GLDAS_TLon;   app.GLDAS_TMN;
                app.GLDAS_WSData;  app.GLDAS_WSTime;  app.GLDAS_WSLat;  app.GLDAS_WSLon;  app.GLDAS_WSMN;
                app.GLDAS_SHData;  app.GLDAS_SHTime;  app.GLDAS_SHLat;  app.GLDAS_SHLon;  app.GLDAS_SHMN;
                app.GLDAS_LWData;  app.GLDAS_LWTime;  app.GLDAS_LWLat;  app.GLDAS_LWLon;  app.GLDAS_LWMN;
                app.GLDAS_SWData;  app.GLDAS_SWTime;  app.GLDAS_SWLat;  app.GLDAS_SWLon;  app.GLDAS_SWMN;

                app.CGWBData;      app.CGWBAnomalies; app.CGWBAvg;
                app.CGWBTime;      app.CGWBLat;       app.CGWBLon
                };

            for i = 1:size(propertiesToClear, 1)
                for j = 1:size(propertiesToClear, 2)
                    propName = propertiesToClear{i,j};
                    if isprop(app, propName)
                        app.(propName) = [];
                    end
                end
            end

            % Clear current basin reference
            if isprop(app, 'CurrentBasin')
                app.CurrentBasin = [];
            end

            % Force garbage collection
            pause(0.1);
        end
        function [basinData, basinTime, basinLat, basinLon] = extractBasinData(app, data, time, lat, lon, basinPolygon, dataName)
            % Extract only data within the specified basin polygon
            % Inputs:
            %   app - app object
            %   data - data array
            %   time - time vector
            %   lat - latitude vector
            %   lon - longitude vector
            %   basinPolygon - basin boundary coordinates [lon, lat]
            %   dataName - string name of the dataset for meaningful error messages

            % Check if dataName is provided
            if nargin < 7
                dataName = 'unknown dataset';
            end

            % Store original lon range for reference
            lonOriginal = lon;

            % Convert longitudes if needed - determine if lon is in 0-360 range
            lonNeedsConversion = any(lon > 180);

            if lonNeedsConversion
                % Convert lon from 0-360 to -180 to 180
                lon = lon - 360;
            end

            % Ensure basin polygon longitudes match the data's lon coordinate system
            if lonNeedsConversion
                % If data lon is 0-360, basin polygon should be converted to 0-360 too
                % OR we need to handle both cases

                % Check basin polygon range
                if any(basinPolygon(:,1) < 0)
                    % Basin polygon is in -180 to 180, data is in 0-360
                    % Convert basin polygon to 0-360
                    basinPolygon(:,1) = mod(basinPolygon(:,1), 360);
                    % Now we need to convert lon back to 0-360 for consistency
                    lon = lonOriginal; % Use original 0-360 lon
                else
                    % Basin polygon is already in 0-360, data is in 0-360
                    % Keep as is, but convert lon back to 0-360
                    lon = lonOriginal; % Use original 0-360 lon
                end
            else
                % Data lon is in -180 to 180
                % Ensure basin polygon is also in -180 to 180
                basinPolygon(:,1) = mod(basinPolygon(:,1) + 180, 360) - 180;
            end

            % Create meshgrid for coordinates
            [LonGrid, LatGrid] = meshgrid(lon, lat);

            % Create mask for points inside basin
            inBasin = inpolygon(LonGrid(:), LatGrid(:), basinPolygon(:,1), basinPolygon(:,2));

            % Check if any points are inside basin
            if sum(inBasin) == 0
                warningMessage = sprintf('No data points found within the selected basin for %s data.', dataName);
                warning(warningMessage);

                % Also display in app if it has a UIStatusLabel property
                if isprop(app, 'UIStatusLabel')
                    app.UIStatusLabel.Text = warningMessage;
                    app.UIStatusLabel.FontColor = [0.8, 0, 0]; % Red color for error
                end

                basinData = [];
                basinTime = [];
                basinLat = [];
                basinLon = [];
                return;
            end

            % Handle different data dimensions
            dataSize = size(data);

            if length(dataSize) == 3
                % 3D data (lat x lon x time or lon x lat x time)
                if dataSize(1) == numel(lon) && dataSize(2) == numel(lat)
                    % Data is lon x lat x time, permute to lat x lon x time
                    data = permute(data, [2, 1, 3]);
                    dataSize = size(data);
                end

                % Reshape to (space x time)
                data2D = reshape(data, [], dataSize(3));
                basinData = data2D(inBasin, :);

            elseif length(dataSize) == 2
                % 2D data (already space x time)
                if size(data, 1) ~= numel(lat) * numel(lon)
                    error('2D data dimensions do not match lat/lon grid for %s data', dataName);
                end
                basinData = data(inBasin, :);
            else
                error('Unsupported data dimensions for %s: %s', dataName, mat2str(dataSize));
            end

            % Extract coordinates of points inside basin
            basinLat = LatGrid(inBasin);
            basinLon = LonGrid(inBasin);
            basinTime = time;

            % Reshape if needed for consistency
            if length(dataSize) == 3
                basinData = reshape(basinData, [sum(inBasin), dataSize(3)]);
            end

            % Success message
            successMessage = sprintf('%s: Extracted %d points from basin (out of %d total grid points)', ...
                dataName, sum(inBasin), numel(LatGrid));
            fprintf('%s\n', successMessage);

            % Update app status if available
            if isprop(app, 'UIStatusLabel')
                app.UIStatusLabel.Text = successMessage;
                app.UIStatusLabel.FontColor = [0, 0.5, 0]; % Green color for success
            end
        end

        % Helper ternary function (inline)
        function out = ternary(~,cond,a,b)
            if cond, out=a; else, out=b; end
        end

        function plotSpatialBasinMap(app, lonPts, latPts, values, basinPolygon, plotTitle)
            % % Get dropdown selection
            screenSize = get(0,'ScreenSize');

            % Design size (from your UIFigure in Design View)
            designWidth = 1212;
            designHeight = 764;

            % Compute scale factor relative to screen size
            scale = min(screenSize(3)/designWidth, screenSize(4)/designHeight);
            % 1. Define fine grid
            res = 0.025;
            lonRange = min(basinPolygon(:,1)):res:max(basinPolygon(:,1));
            latRange = min(basinPolygon(:,2)):res:max(basinPolygon(:,2));
            [LonGrid, LatGrid] = meshgrid(lonRange, latRange);

            % 2. Interpolate
            F = scatteredInterpolant(lonPts, latPts, values, 'natural','linear');
            GridVals = F(LonGrid, LatGrid);

            % 3. Mask outside
            inMask = inpolygon(LonGrid, LatGrid, basinPolygon(:,1), basinPolygon(:,2));
            GridVals(~inMask) = NaN;

            % 4. Plot
            cla(app.UIAxes);
            pcolor(app.UIAxes, LonGrid, LatGrid, GridVals);
            shading(app.UIAxes,'flat');

            baseColors = [...
                103 0   31;   % dark red
                178 24  43;   % medium red
                214 96  77;   % light red
                244 165 130;  % very light red
                247 247 247;  % white
                146 197 222;  % light blue
                67  147 195;  % medium blue
                33  102 172;  % darker blue
                5   48  97];  % darkest blue

            % Normalize to [0,1]
            baseColors = baseColors ./ 255;

            % --- Interpolate colormap ---
            x  = linspace(0, 1, size(baseColors,1));
            xi = linspace(0, 1, 256);
            cmap = interp1(x, baseColors, xi, 'linear');
            colormap(app.UIAxes, cmap);

            % --- Symmetric color axis ---
            cmin = min(values(:));
            cmax = max(values(:));
            cabs = max(abs([cmin cmax]));
            clim(app.UIAxes, [-cabs cabs]);

            % --- Colorbar below, centered ---
            c = colorbar(app.UIAxes,'southoutside');
            c.Label.String     = 'TWSA (mm/month)';
            c.Label.FontWeight = 'bold';
            c.Label.FontName   = 'Times New Roman';
            c.Label.FontSize   = 12;

            % Center the colorbar (adjust numbers if needed)
            cpos = c.Position;   % [x y w h]
            cpos(1) = 0.35;      % shift horizontally
            cpos(2) = cpos(2)-0.06;
            cpos(3) = 0.3;       % shrink width
            c.Position = cpos;


            % Labels & title
            xlabel(app.UIAxes,'Longitude','FontWeight','bold');
            ylabel(app.UIAxes,'Latitude','FontWeight','bold');
            %title(app.UIAxes, plotTitle,'FontWeight','bold');

            % Fix aspect
            axis(app.UIAxes,'equal','tight');

            % Overlay polygon
            hold(app.UIAxes,'on');
            plot(app.UIAxes, basinPolygon(:,1), basinPolygon(:,2), 'k-', 'LineWidth',2);
            hold(app.UIAxes,'off');

            % Format ticks (°E/°W, °N/°S)
            xticks = get(app.UIAxes,'XTick');
            yticks = get(app.UIAxes,'YTick');
            xticklabels = arrayfun(@(x) sprintf('%.1f°%s', abs(x), ternary(app,x>=0,'E','W')), xticks, 'UniformOutput', false);
            yticklabels = arrayfun(@(y) sprintf('%.1f°%s', abs(y), ternary(app,y>=0,'N','S')), yticks, 'UniformOutput', false);
            set(app.UIAxes,'XTickLabel',xticklabels,'YTickLabel',yticklabels);
            % ✅ Rotate Y-axis labels and set font
            %app.UIAxes.XAxis.TickLabelRotation = 90;         % rotate 90 degrees
            app.UIAxes.XAxis.FontName = 'Times New Roman';   % font style
            app.UIAxes.XAxis.FontSize = 12;                  % (optional) font size
            app.UIAxes.XAxis.FontWeight = 'normal';          % or 'bold' if you want bold
            % ✅ Rotate Y-axis labels and set font
            app.UIAxes.YAxis.TickLabelRotation = 90;         % rotate 90 degrees
            app.UIAxes.YAxis.FontName = 'Times New Roman';   % font style
            app.UIAxes.YAxis.FontSize = 12;                  % (optional) font size
            app.UIAxes.YAxis.FontWeight = 'normal';          % or 'bold' if you want bold
        end

        function plotSpatialBasinMapDynamic(app, lonPts, latPts, values, basinPolygon, plotTitle)
            % 1. Define fine grid
            res = 0.025;
            lonRange = min(basinPolygon(:,1)):res:max(basinPolygon(:,1));
            latRange = min(basinPolygon(:,2)):res:max(basinPolygon(:,2));
            [LonGrid, LatGrid] = meshgrid(lonRange, latRange);

            % 2. Interpolate scattered points
            F = scatteredInterpolant(lonPts, latPts, values, 'natural', 'linear');
            GridVals = F(LonGrid, LatGrid);

            % 3. Mask outside the basin
            inMask = inpolygon(LonGrid, LatGrid, basinPolygon(:,1), basinPolygon(:,2));
            GridVals(~inMask) = NaN;

            % 4. Plot on dynamic axes
            cla(app.DynamicAxes);
            pcolor(app.DynamicAxes, LonGrid, LatGrid, GridVals);
            shading(app.DynamicAxes, 'flat');

            % 5. Custom colormap
            baseColors = [...
                103 0   31;   % dark red
                178 24  43;   % medium red
                214 96  77;   % light red
                244 165 130;  % very light red
                247 247 247;  % white
                146 197 222;  % light blue
                67  147 195;  % medium blue
                33  102 172;  % darker blue
                5   48  97];  % darkest blue

            baseColors = baseColors ./ 255;
            x  = linspace(0, 1, size(baseColors,1));
            xi = linspace(0, 1, 256);
            cmap = interp1(x, baseColors, xi, 'linear');
            colormap(app.DynamicAxes, cmap);

            % 6. Symmetric color axis
            cmin = min(values(:));
            cmax = max(values(:));
            cabs = max(abs([cmin cmax]));
            clim(app.DynamicAxes, [-cabs cabs]);

            % 7. Colorbar
            c = colorbar(app.DynamicAxes, 'southoutside');
            c.Label.String = 'GWSA (mm/month)';
            c.Label.FontWeight = 'bold';
            c.Label.FontName   = 'Times New Roman';
            c.Label.FontSize   = 12;
            % Adjust position
            cpos = c.Position;
            cpos(1) = 0.72;
            cpos(2) = cpos(2)+0.01;
            cpos(3) = 0.2;
            c.Position = cpos;

            % 8. Labels & title
            xlabel(app.DynamicAxes, 'Longitude');
            ylabel(app.DynamicAxes, 'Latitude');
            title(app.DynamicAxes, plotTitle);

            % 9. Grid & aspect
            grid(app.DynamicAxes, 'on');
            axis(app.DynamicAxes, 'equal');

            % 10. Overlay polygon
            hold(app.DynamicAxes, 'on');
            plot(app.DynamicAxes, basinPolygon(:,1), basinPolygon(:,2), 'k-', 'LineWidth', 2);
            hold(app.DynamicAxes, 'off');

            % 11. Tick labels formatting
            xticks = get(app.DynamicAxes,'XTick');
            yticks = get(app.DynamicAxes,'YTick');
            xticklabels = arrayfun(@(x) sprintf('%.1f°%s', abs(x), ternary(app,x>=0,'E','W')), xticks, 'UniformOutput', false);
            yticklabels = arrayfun(@(y) sprintf('%.1f°%s', abs(y), ternary(app,y>=0,'N','S')), yticks, 'UniformOutput', false);
            set(app.DynamicAxes,'XTickLabel',xticklabels,'YTickLabel',yticklabels);

            app.DynamicAxes.XAxis.TickLabelRotation = 0;  % optional
            app.DynamicAxes.XAxis.FontName = 'Times New Roman';
            app.DynamicAxes.XAxis.FontSize = 12;
            app.DynamicAxes.XAxis.FontWeight = 'normal';
            app.DynamicAxes.YAxis.TickLabelRotation = 90;
            app.DynamicAxes.YAxis.FontName = 'Times New Roman';
            app.DynamicAxes.YAxis.FontSize = 12;
            app.DynamicAxes.YAxis.FontWeight = 'normal';
        end
        %% ==================== HELPER FUNCTIONS ====================
        function [trendLine, slope, intercept, r2, pval] = calculateTrend(app, x, y, method)
            % Calculate trend line and statistics based on selected method
            trendLine = [];
            slope = NaN;
            intercept = NaN;
            r2 = NaN;
            pval = NaN;

            n = length(x);
            if n < 2
                return;
            end

            switch method
                case 'linear'
                    % Linear regression
                    p = polyfit(x, y, 1);
                    slope = p(1);
                    intercept = p(2);
                    trendLine = polyval(p, x);

                case 'polynomial'
                    % Polynomial fit (2nd degree)
                    p = polyfit(x, y, 2);
                    trendLine = polyval(p, x);
                    % For polynomial, use linear slope for consistency in reporting
                    p_linear = polyfit(x, y, 1);
                    slope = p_linear(1);
                    intercept = p_linear(2);

                case 'loess'
                    % LOESS smoothing
                    span = 0.5; % Span parameter
                    if length(x) > 5
                        trendLine = smooth(x, y, span, 'loess');
                        % Calculate linear trend of the LOESS curve for slope
                        p_linear = polyfit(x, trendLine, 1);
                        slope = p_linear(1);
                        intercept = p_linear(2);
                    else
                        % Fallback to linear for small datasets
                        p = polyfit(x, y, 1);
                        slope = p(1);
                        intercept = p(2);
                        trendLine = polyval(p, x);
                    end

                case 'sens slope'
                    % Sen's slope (non-parametric)
                    slopes = zeros(n*(n-1)/2, 1);
                    k = 0;

                    for i = 1:n-1
                        for j = i+1:n
                            k = k + 1;
                            slopes(k) = (y(j) - y(i)) / (x(j) - x(i));
                        end
                    end

                    % Sen's slope is the median of all slopes
                    slope = median(slopes, 'omitnan');

                    % Calculate intercept using median of y - slope*x
                    intercepts = y - slope * x;
                    intercept = median(intercepts, 'omitnan');

                    trendLine = slope * x + intercept;
            end

            % Calculate R-squared and p-value (for all methods)
            if ~isempty(trendLine)
                % R-squared calculation
                y_fit = trendLine;
                y_mean = mean(y);
                ss_res = sum((y - y_fit).^2);
                ss_tot = sum((y - y_mean).^2);
                if ss_tot > 0
                    r2 = 1 - (ss_res / ss_tot);
                else
                    r2 = NaN;
                end

                % p-value calculation (using linear model for consistency)
                if n > 2
                    [~, ~, ~, ~, stats] = regress(y(:), [ones(length(x), 1), x(:)]);
                    if ~isempty(stats)
                        pval = stats(3);
                    else
                        pval = NaN;
                    end
                else
                    pval = NaN;
                end
            end
        end

        function [y1_aligned, y2_aligned] = alignData(app,y1, y2)
            % Align two datasets by removing time points where either has NaN
            validMask = ~isnan(y1) & ~isnan(y2);
            y1_aligned = y1(validMask);
            y2_aligned = y2(validMask);
        end

        function cc = calculateCorrelationCoefficient(app,y1, y2)
            % Calculate correlation coefficient
            if length(y1) < 2
                cc = NaN;
                return;
            end
            R = corrcoef(y1, y2);
            cc = R(1,2);
        end

        function nse = calculateNSE(app,observed, simulated)
            % Calculate Nash-Sutcliffe Efficiency
            % NSE = 1 - (sum((observed - simulated)^2) / sum((observed - mean(observed))^2))
            if length(observed) < 2
                nse = NaN;
                return;
            end
            numerator = sum((observed - simulated).^2);
            denominator = sum((observed - mean(observed)).^2);

            if denominator == 0
                nse = NaN;
            else
                nse = 1 - (numerator / denominator);
            end
        end

        % --- Example RMSE function ---
        function rmse = calculateRMSE(app,observed, simulated)
            % Calculate Root Mean Square Error with robust error handling

            % Input validation
            if isempty(observed) || isempty(simulated)
                rmse = NaN;
                return;
            end

            % Ensure both inputs are vectors of the same size
            observed = observed(:);
            simulated = simulated(:);

            if length(observed) ~= length(simulated)
                warning('Observed and simulated data have different lengths. Using common valid indices.');
                % Find common non-NaN indices
                valid_obs = ~isnan(observed);
                valid_sim = ~isnan(simulated);
                valid_idx = valid_obs & valid_sim;

                if sum(valid_idx) < 1
                    rmse = NaN;
                    return;
                end

                observed = observed(valid_idx);
                simulated = simulated(valid_idx);
            else
                % Remove NaN pairs
                valid_idx = ~isnan(observed) & ~isnan(simulated);
                if sum(valid_idx) < 1
                    rmse = NaN;
                    return;
                end
                observed = observed(valid_idx);
                simulated = simulated(valid_idx);
            end

            % Check if we have enough data points
            if length(observed) < 2
                rmse = NaN;
                return;
            end

            try
                % Calculate RMSE
                squared_errors = (observed - simulated).^2;
                mean_squared_error = mean(squared_errors, 'omitnan');
                rmse = sqrt(mean_squared_error);
            catch ME
                warning(ME.identifier,'Error calculating RMSE: %s', ME.message);
                rmse = NaN;
            end
        end

        function [features, target, valid_time] = createLaggedFeatures2D(app, gldas_vars, tws_data, time_data, lag_months, point_idx, include_current_month)
            % Create lagged features for a SINGLE point
            % IMPORTANT: Training and reconstruction MUST use the same include_current_month setting
            %
            % Inputs:
            %   gldas_vars: cell array where each variable is [n_time x n_points]
            %   tws_data: [n_time x n_points] or empty for prediction-only
            %   time_data: [n_time x 1] datetime vector
            %   lag_months: number of lag months (e.g., 2 for t-1, t-2)
            %   point_idx: scalar index of the point to extract features for
            %   include_current_month:
            %       false (training): use only lags (t-1, t-2) to predict TWSA(t)
            %       true (reconstruction): use current + lags (t, t-1, t-2) to predict TWSA(t)
            %       MUST BE CONSISTENT between training and reconstruction!
            %
            % Outputs:
            %   features: [n_samples x n_features] matrix (NaN-initialized)
            %   target: [n_samples x 1] vector (or empty if tws_data is empty)
            %   valid_time: [n_samples x 1] datetime vector

            if nargin < 7
                include_current_month = false; % Default: only lags for training
            end

            % Check input dimensions
            n_time = size(gldas_vars{1}, 1);
            n_vars = length(gldas_vars);

            % CRITICAL GUARD: Check if enough time points for lag
            if n_time <= lag_months
                warning('Point %d: Not enough time steps (%d) for lag %d. Returning empty.', ...
                    point_idx, n_time, lag_months);
                features = [];
                target = [];
                valid_time = [];
                return;
            end

            % Validate point index
            if point_idx < 1 || point_idx > size(gldas_vars{1}, 2)
                error('Point index %d is out of bounds (1-%d)', point_idx, size(gldas_vars{1}, 2));
            end

            % Number of features per variable
            if include_current_month
                features_per_var = lag_months + 1; % t, t-1, t-2, ...
            else
                features_per_var = lag_months;     % t-1, t-2, ...
            end

            total_features = n_vars * features_per_var;

            % CRITICAL FIX: Initialize with NaN, not zeros
            features = NaN(n_time - lag_months, total_features);

            if ~isempty(tws_data)
                target = NaN(n_time - lag_months, 1);
            else
                target = [];
            end

            % CRITICAL WARNING for reconstruction without current month
            if isempty(tws_data) && ~include_current_month
                warning(['Reconstruction mode without current month predictors: ', ...
                    'Early months will be missing and model may underperform. ', ...
                    'Ensure consistency with training mode.']);
            end

            % Create features for this specific point
            for t = (lag_months + 1):n_time
                feature_idx = 1;

                % For each variable
                for var_idx = 1:n_vars
                    % Feature order depends on include_current_month
                    if include_current_month
                        % Include current month (t) FIRST
                        if t <= size(gldas_vars{var_idx}, 1)
                            features(t - lag_months, feature_idx) = gldas_vars{var_idx}(t, point_idx);
                        end
                        feature_idx = feature_idx + 1;
                    end

                    % Then include lag months (t-1, t-2, ...)
                    for lag = 1:lag_months
                        time_idx = t - lag; % Previous month

                        if time_idx >= 1 && time_idx <= size(gldas_vars{var_idx}, 1)
                            features(t - lag_months, feature_idx) = gldas_vars{var_idx}(time_idx, point_idx);
                        end
                        feature_idx = feature_idx + 1;
                    end
                end

                % Target (current month TWSA for this point) - only for training
                if ~isempty(tws_data) && t <= size(tws_data, 1)
                    target(t - lag_months) = tws_data(t, point_idx);
                end
            end

            valid_time = time_data(lag_months + 1:end);

            % Remove any rows with NaN features
            nan_rows = any(isnan(features), 2);
            if any(nan_rows)
                fprintf('  Point %d: Removing %d rows with NaN features\n', point_idx, sum(nan_rows));
                features = features(~nan_rows, :);
                if ~isempty(target)
                    target = target(~nan_rows, :);
                end
                valid_time = valid_time(~nan_rows);
            end

            % Debug output - be explicit about what we're creating
            if ~isempty(target)
                if include_current_month
                    fprintf('  Point %d: TRAINING with current month + %d lags: %d samples × %d features\n', ...
                        point_idx, lag_months, size(features, 1), size(features, 2));
                else
                    fprintf('  Point %d: TRAINING with %d lags only: %d samples × %d features\n', ...
                        point_idx, lag_months, size(features, 1), size(features, 2));
                end
            else
                if include_current_month
                    fprintf('  Point %d: RECONSTRUCTION with current month + %d lags: %d samples × %d features\n', ...
                        point_idx, lag_months, size(features, 1), size(features, 2));
                else
                    fprintf('  Point %d: RECONSTRUCTION with %d lags only: %d samples × %d features\n', ...
                        point_idx, lag_months, size(features, 1), size(features, 2));
                end
            end
        end

        function saveReconstructedTWSToExcel2D(app, reconstructed_tws, time_data, point_names, reconstructionType, lat_masked, lon_masked, methodType)
            % Save reconstructed TWSA to Excel - FIXED VERSION for basin average

            % Check if methodType is provided
            if nargin < 8
                methodType = 'unknown';
            end

            % Determine method string for filename
            if strcmpi(methodType, 'RF')
                method_str = 'RF';
            elseif strcmpi(methodType, 'ANN') || strcmpi(methodType, 'ann')
                method_str = 'ANN';
            else
                method_str = upper(methodType);
            end

            % Get dimensions - FIXED: Check orientation
            [n_rows, n_cols] = size(reconstructed_tws);

            % For basin average, ensure we have the right orientation
            if strcmp(reconstructionType, 'basin_average')
                % reconstructed_tws should be Points × Time = 1 × Time
                % So it should have 1 row and n_time columns
                if n_rows == 1
                    % It's already 1 × Time (row vector)
                    n_time = n_cols;
                    % Transpose to Time × 1 for saving
                    basin_tws = reconstructed_tws';
                elseif n_cols == 1
                    % It's already Time × 1 (column vector)
                    n_time = n_rows;
                    basin_tws = reconstructed_tws;
                else
                    % Unexpected shape, try to fix
                    warning('Unexpected shape for basin average. Trying to reshape...');
                    basin_tws = reconstructed_tws(:);
                    n_time = length(basin_tws);
                end
            else
                % For all points, it should be Points × Time
                n_points = n_rows;
                n_time = n_cols;
            end

            % Ensure time_data has the right length
            if length(time_data) ~= n_time
                warning('Time data length (%d) does not match TWSA length (%d). Truncating...', ...
                    length(time_data), n_time);
                if length(time_data) > n_time
                    time_data = time_data(1:n_time);
                else
                    time_data = [time_data; NaT(n_time - length(time_data), 1)];
                end
            end

            if strcmp(reconstructionType, 'all_points')
                % For all points in the basin - create separate files

                % 1. Coordinates file
                coord_data = table();
                coord_data.Latitude = lat_masked(:);
                coord_data.Longitude = lon_masked(:);
                coord_data.PointName = point_names(:);
                coord_data.Mean_TWSA = mean(reconstructed_tws, 2, 'omitnan');  % Mean across time (columns)
                coord_data.Std_TWSA = std(reconstructed_tws, 0, 2, 'omitnan');  % Std across time
                coord_data.Min_TWSA = min(reconstructed_tws, [], 2);
                coord_data.Max_TWSA = max(reconstructed_tws, [], 2);

                coord_filename = sprintf('Reconstructed_TWSA_%s_AllPoints_Coordinates.xlsx', method_str);
                writetable(coord_data, coord_filename);

                % 2. Time series file (transpose to Time × Points)
                time_series_data = table();
                time_series_data.Time = time_data;
                time_series_data.Year = year(time_data);
                time_series_data.Month = month(time_data);

                % Transpose reconstructed_tws to Time × Points
                tws_transposed = reconstructed_tws';

                for point_idx = 1:n_points
                    time_series_data.(point_names{point_idx}) = tws_transposed(:, point_idx);
                end

                ts_filename = sprintf('Reconstructed_TWSA_%s_AllPoints_TimeSeries.xlsx', method_str);
                writetable(time_series_data, ts_filename);

                % 3. Summary statistics file
                summary_data = table();
                summary_data.Statistic = {'Mean'; 'Std'; 'Min'; 'Max'};
                for point_idx = 1:n_points
                    ts = reconstructed_tws(point_idx, :);  % Get row for this point
                    ts_valid = ts(~isnan(ts));

                    if ~isempty(ts_valid)
                        summary_data.(point_names{point_idx}) = [
                            mean(ts_valid);
                            std(ts_valid);
                            min(ts_valid);
                            max(ts_valid);
                            ];
                    else
                        summary_data.(point_names{point_idx}) = NaN(4, 1);
                    end
                end

                summary_filename = sprintf('Reconstructed_TWSA_%s_AllPoints_Statistics.xlsx', method_str);
                writetable(summary_data, summary_filename);

                fprintf('Reconstructed TWSA saved to 3 files:\n');
                fprintf('  1. %s (coordinates and statistics)\n', coord_filename);
                fprintf('  2. %s (time series data)\n', ts_filename);
                fprintf('  3. %s (summary statistics)\n', summary_filename);

            else
                % For basin average - FIXED VERSION
                basin_data = table();
                basin_data.Time = time_data;
                basin_data.Year = year(time_data);
                basin_data.Month = month(time_data);

                % Ensure basin_tws is a column vector with the right length
                if length(basin_tws) ~= length(time_data)
                    if length(basin_tws) > length(time_data)
                        basin_tws = basin_tws(1:length(time_data));
                    else
                        basin_tws = [basin_tws; NaN(length(time_data) - length(basin_tws), 1)];
                    end
                end

                basin_data.Basin_Average_TWSA = basin_tws;

                % Calculate season (simplified version)
                seasons = cell(size(time_data));
                months = month(time_data);
                for i = 1:length(months)
                    m = months(i);
                    if m >= 3 && m <= 5
                        seasons{i} = 'Spring';
                    elseif m >= 6 && m <= 8
                        seasons{i} = 'Summer';
                    elseif m >= 9 && m <= 11
                        seasons{i} = 'Summer';  % Monsoon in Indian context
                    else
                        seasons{i} = 'Winter';
                    end
                end
                basin_data.Season = seasons;
                basin_data.Quarter = ceil(month(time_data)/3);

                % Save main basin average data
                basin_filename = sprintf('Reconstructed_TWSA_%s_BasinAverage.xlsx', method_str);
                writetable(basin_data, basin_filename);

                % Create seasonal summary file
                unique_seasons = unique(seasons);
                seasonal_summary = table();
                seasonal_summary.Season = unique_seasons;
                seasonal_summary.Mean_TWSA = zeros(length(unique_seasons), 1);
                seasonal_summary.Std_TWSA = zeros(length(unique_seasons), 1);
                seasonal_summary.Min_TWSA = zeros(length(unique_seasons), 1);
                seasonal_summary.Max_TWSA = zeros(length(unique_seasons), 1);

                for s = 1:length(unique_seasons)
                    season_mask = strcmp(seasons, unique_seasons{s});
                    season_vals = basin_data.Basin_Average_TWSA(season_mask);
                    seasonal_summary.Mean_TWSA(s) = mean(season_vals, 'omitnan');
                    seasonal_summary.Std_TWSA(s) = std(season_vals, 'omitnan');
                    seasonal_summary.Min_TWSA(s) = min(season_vals, [], 'omitnan');
                    seasonal_summary.Max_TWSA(s) = max(season_vals, [], 'omitnan');
                end

                seasonal_filename = sprintf('Reconstructed_TWSA_%s_BasinAverage_Seasonal.xlsx', method_str);
                writetable(seasonal_summary, seasonal_filename);

                fprintf('Reconstructed TWSA saved to:\n');
                fprintf('  1. %s (time series data)\n', basin_filename);
                fprintf('  2. %s (seasonal statistics)\n', seasonal_filename);

                % Create annual summary if we have full years
                years = unique(year(time_data));
                if length(years) >= 2
                    annual_data = table();
                    annual_data.Year = years;
                    annual_data.Mean_TWSA = zeros(length(years), 1);
                    annual_data.Std_TWSA = zeros(length(years), 1);
                    annual_data.Min_TWSA = zeros(length(years), 1);
                    annual_data.Max_TWSA = zeros(length(years), 1);
                    annual_data.Trend = zeros(length(years), 1);

                    for y = 1:length(years)
                        year_mask = year(time_data) == years(y);
                        year_vals = basin_data.Basin_Average_TWSA(year_mask);
                        annual_data.Mean_TWSA(y) = mean(year_vals, 'omitnan');
                        annual_data.Std_TWSA(y) = std(year_vals, 'omitnan');
                        annual_data.Min_TWSA(y) = min(year_vals, [], 'omitnan');
                        annual_data.Max_TWSA(y) = max(year_vals, [], 'omitnan');

                        % Simple trend (difference from previous year)
                        if y > 1
                            annual_data.Trend(y) = annual_data.Mean_TWSA(y) - annual_data.Mean_TWSA(y-1);
                        end
                    end

                    annual_filename = sprintf('Reconstructed_TWSA_%s_BasinAverage_Annual.xlsx', method_str);
                    writetable(annual_data, annual_filename);

                    fprintf('  3. %s (annual statistics)\n', annual_filename);
                end
            end
        end

        function season = getSeason(app,month)
            % Helper function to determine season from month
            season = cell(size(month));

            for i = 1:length(month)
                m = month(i);
                if m >= 3 && m <= 5
                    season{i} = 'Spring';
                elseif m >= 6 && m <= 8
                    season{i} = 'Summer';
                elseif m >= 9 && m <= 11
                    season{i} = 'Summer';  % Monsoon in Indian context
                else
                    season{i} = 'Winter';
                end
            end
        end

        function [train_rmse, test_rmse, train_corr, test_corr] = calculatePerformanceMetrics2D(~, y_train, y_pred_train, y_test, y_pred_test)
            % Calculate performance metrics for 2D data - CORRECTED VERSION

            n_points = size(y_train, 2);
            train_rmse = zeros(1, n_points);
            test_rmse = zeros(1, n_points);
            train_corr = zeros(1, n_points);
            test_corr = zeros(1, n_points);

            for point_idx = 1:n_points
                % Training metrics
                valid_train = ~isnan(y_train(:, point_idx)) & ~isnan(y_pred_train(:, point_idx));
                if sum(valid_train) > 2
                    train_actual = y_train(valid_train, point_idx);
                    train_pred = y_pred_train(valid_train, point_idx);

                    train_rmse(point_idx) = sqrt(mean((train_actual - train_pred).^2));

                    % Correlation with safety check
                    if length(train_actual) > 1 && ~all(train_actual == train_actual(1))
                        corr_matrix = corrcoef(train_actual, train_pred, 'Rows', 'complete');
                        if all(size(corr_matrix) == [2, 2]) && ~any(isnan(corr_matrix(:)))
                            train_corr(point_idx) = corr_matrix(1, 2);
                        else
                            train_corr(point_idx) = NaN;
                        end
                    else
                        train_corr(point_idx) = NaN;
                    end
                else
                    train_rmse(point_idx) = NaN;
                    train_corr(point_idx) = NaN;
                end

                % Testing metrics
                valid_test = ~isnan(y_test(:, point_idx)) & ~isnan(y_pred_test(:, point_idx));
                if sum(valid_test) > 2
                    test_actual = y_test(valid_test, point_idx);
                    test_pred = y_pred_test(valid_test, point_idx);

                    test_rmse(point_idx) = sqrt(mean((test_actual - test_pred).^2));

                    % Correlation with safety check
                    if length(test_actual) > 1 && ~all(test_actual == test_actual(1))
                        corr_matrix = corrcoef(test_actual, test_pred, 'Rows', 'complete');
                        if all(size(corr_matrix) == [2, 2]) && ~any(isnan(corr_matrix(:)))
                            test_corr(point_idx) = corr_matrix(1, 2);
                        else
                            test_corr(point_idx) = NaN;
                        end
                    else
                        test_corr(point_idx) = NaN;
                    end
                else
                    test_rmse(point_idx) = NaN;
                    test_corr(point_idx) = NaN;
                end
            end

            % Display summary statistics
            fprintf('=== PERFORMANCE SUMMARY ===\n');
            fprintf('Training - Mean RMSE: %.4f, Mean Correlation: %.4f\n', ...
                nanmean(train_rmse), nanmean(train_corr));
            fprintf('Testing  - Mean RMSE: %.4f, Mean Correlation: %.4f\n', ...
                nanmean(test_rmse), nanmean(test_corr));

            % Warn if testing correlation is poor
            if nanmean(test_corr) < 0.3
                fprintf('WARNING: Testing correlation is low. Model may not be generalizing well.\n');
            end
        end

        %% Reconstruction
       %% Reconstruction
function reconstructTWSWithMethod(app, reconstructionType, methodType)
    % reconstructionType: 'basin_average' or 'all_points'
    % methodType: 'RF' or 'ANN'
    % CORRECTED VERSION: fixes GFSC property typo, CSR/GSFC time-vector
    % misalignment, unwired IMD substitution, and output filename collisions.

    % ==================== SHOW CONFIGURATION POPUP ====================
    config = app.showReconstructionConfigDialog(methodType);
    if config.cancelled
        fprintf('Reconstruction cancelled by user.\n');
        return;
    end

    try
        % ==================== BASIN MASKING ====================
        fprintf('Applying basin mask...\n');

        % Select GRACE product based on user choice.
        % FIX: property name is GFSCTWSData (not GSFCTWSData) per app properties.
        % FIX: product_time now tracks the TIME VECTOR matching whichever
        % product was selected, instead of always using JPLTWSTime.
        switch config.graceProduct
            case 'JPL'
                jpl_tws_masked = 10 * app.JPLTWSData;
                lat_masked     = app.JPLTWSLat;
                lon_masked     = app.JPLTWSLon;
                product_time   = app.JPLTWSTime(:);
            case 'CSR'
                jpl_tws_masked = 10 * app.CSRTWSData;
                lat_masked     = app.CSRTWSLat;
                lon_masked     = app.CSRTWSLon;
                product_time   = app.CSRTWSTime(:);
            case 'GSFC'
                jpl_tws_masked = 10 * app.GFSCTWSData;   % GFSC, matches app property
                lat_masked     = app.GFSCTWSLat;
                lon_masked     = app.GFSCTWSLon;
                product_time   = app.GFSCTWSTime(:);
            otherwise
                error('Unknown GRACE product selected: %s', config.graceProduct);
        end

        % GLDAS variables (already masked from extractBasinData)
        gldas_data_vars = {app.GLDAS_PData, app.GLDAS_ETData, app.GLDAS_SMData, ...
            app.GLDAS_TData, app.GLDAS_WSData, app.GLDAS_SHData, ...
            app.GLDAS_LWData, app.GLDAS_SWData};
        gldas_var_names = {'P', 'ET', 'SM', 'T', 'WS', 'SH', 'LW', 'SW'};

        % ==================== APPLY DATASET CHOICE (FIX: now actually wired in) ====================
        if strcmp(config.datasetChoice, 'IMD Precipitation + GLDAS')
            fprintf('Substituting IMD observed precipitation for GLDAS precipitation.\n');
            if ~isempty(app.IMDData)
                gldas_data_vars{1} = app.IMDData;  % IMD replaces GLDAS precipitation
            else
                warning('IMD precipitation not loaded (app.IMDData is empty). Falling back to GLDAS.');
            end
        else
            fprintf('Using GLDAS-only forcing (no substitution).\n');
        end

        gldas_vars_masked = gldas_data_vars;  % already masked, direct assignment

        n_points_initial = size(jpl_tws_masked, 1);
        fprintf('Using already masked data. Points in basin: %d\n', n_points_initial);
        fprintf('Selected reconstruction type: %s\n', reconstructionType);
        fprintf('Selected method: %s\n', methodType);
        fprintf('Selected GRACE product: %s\n', config.graceProduct);
        fprintf('Selected forcing: %s\n', config.datasetChoice);

        % ==================== METHODOLOGICAL CHOICE ====================
        USE_CURRENT_MONTH_IN_FEATURES = config.useCurrentMonth;
        fprintf('\n=== METHODOLOGICAL SETTINGS ===\n');
        if USE_CURRENT_MONTH_IN_FEATURES
            fprintf('Using Strategy B: Current month + lags as predictors\n');
        else
            fprintf('Using Strategy A: Lags only as predictors (causal)\n');
        end

        % ==================== DATA PREPROCESSING ====================
        all_vals = jpl_tws_masked(:);
        nonnan = ~isnan(all_vals);
        if any(nonnan)
            fprintf('Original %s data statistics:\n', config.graceProduct);
            fprintf('  Min: %.4f, Max: %.4f, Mean: %.4f\n', ...
                min(all_vals(nonnan)), max(all_vals(nonnan)), mean(all_vals(nonnan)));
            fprintf('  Negative values percentage: %.2f%%\n', ...
                sum(all_vals(nonnan) < 0) / numel(all_vals(nonnan)) * 100);
        else
            fprintf('Original %s data is all NaN - aborting.\n', config.graceProduct);
            return;
        end

        % FIX: use product_time (matches selected GRACE product) instead of
        % always reading app.JPLTWSTime regardless of selection.
        jpl_time = product_time;
        gldas_time = app.GLDAS_PTime(:);

        % ==================== PROCESS GRACE DATA (2002-2024) ====================
        fprintf('\n=== PROCESSING %s DATA (2002-2024) ===\n', config.graceProduct);

        jpl_start_year = 2002;
        jpl_end_year = 2024;
        jpl_mask = year(jpl_time) >= jpl_start_year & year(jpl_time) <= jpl_end_year;
        jpl_tws_filtered = jpl_tws_masked(:, jpl_mask);  % Points x Time
        jpl_time_filtered = jpl_time(jpl_mask);

        if isempty(jpl_time_filtered)
            error('No %s timepoints found between %d and %d.', config.graceProduct, jpl_start_year, jpl_end_year);
        end

        jpl_years = year(jpl_time_filtered);
        jpl_months = month(jpl_time_filtered);
        [unique_pairs, ~, ic] = unique([jpl_years jpl_months], 'rows', 'stable');

        n_unique = size(unique_pairs, 1);
        jpl_tws_processed = zeros(size(jpl_tws_filtered, 1), n_unique);

        for k = 1:n_unique
            idxs = find(ic == k);
            jpl_tws_processed(:, k) = mean(jpl_tws_filtered(:, idxs), 2, 'omitnan');
        end

        jpl_time_processed = datetime(unique_pairs(:,1), unique_pairs(:,2), 1);

        fprintf('%s processed time range: %s to %s (%d months)\n', config.graceProduct, ...
            datestr(min(jpl_time_processed), 'mmm yyyy'), ...
            datestr(max(jpl_time_processed), 'mmm yyyy'), ...
            length(jpl_time_processed));

        fprintf('Creating complete monthly series (Jan 2002 - Dec 2024)...\n');
        jpl_complete_months = (datetime(jpl_start_year,1,1):calmonths(1):datetime(jpl_end_year,12,1))';
        fprintf('Generated %d months (Jan %d - Dec %d)\n', length(jpl_complete_months), jpl_start_year, jpl_end_year);

        jpl_tws_complete = NaN(size(jpl_tws_processed, 1), length(jpl_complete_months));
        [lia, locb] = ismember(jpl_time_processed, jpl_complete_months);
        locb = locb(lia);
        jpl_tws_complete(:, locb) = jpl_tws_processed(:, lia);

        % Fill missing months with monthly climatology (per calendar month).
        % Note: GRACE gaps (incl. the 2017-2018 inter-mission gap) use
        % climatological infilling; GLDAS forcing gaps use linear interpolation
        % (see below) -- this is a deliberate, documented methodological difference.
        fprintf('Filling missing months with monthly means (climatology)...\n');
        for m = 1:12
            month_indices_complete = find(month(jpl_complete_months) == m);
            available_mask = month(jpl_time_processed) == m;
            available_cols = find(available_mask);
            if ~isempty(available_cols)
                monthly_means = mean(jpl_tws_processed(:, available_cols), 2, 'omitnan');
                for c = month_indices_complete
                    if any(isnan(jpl_tws_complete(:, c)))
                        jpl_tws_complete(:, c) = monthly_means;
                    end
                end
                fprintf('  Month %02d: filled gaps using %d available months\n', m, length(available_cols));
            else
                fprintf('  Month %02d: no available months in processed %s data - left NaNs for now\n', m, config.graceProduct);
            end
        end

        jpl_time_complete = jpl_complete_months;
        nan_columns = any(isnan(jpl_tws_complete), 1);

        if any(nan_columns)
            fprintf('Filling %d remaining gap columns using monthly climatology...\n', sum(nan_columns));
            for c = find(nan_columns)
                month_c = month(jpl_time_complete(c));
                same_month_cols = find(month(jpl_time_complete) == month_c);
                same_month_cols(same_month_cols == c) = [];
                if isempty(same_month_cols)
                    continue;
                end
                climatology_vec = mean(jpl_tws_complete(:, same_month_cols), 2, 'omitnan');
                jpl_tws_complete(:, c) = climatology_vec;
            end
        end
        jpl_tws_final = jpl_tws_complete;

        all_vals2 = jpl_tws_final(:);
        nonnan2 = ~isnan(all_vals2);
        if any(nonnan2)
            fprintf('%s data after processing: Min=%.4f, Max=%.4f, Mean=%.4f\n', config.graceProduct, ...
                min(all_vals2(nonnan2)), max(all_vals2(nonnan2)), mean(all_vals2(nonnan2)));
        else
            error('%s processed data is all NaN after filling.', config.graceProduct);
        end

        % ==================== PROCESS GLDAS DATA (1948-2024) ====================
        fprintf('\n=== PROCESSING GLDAS DATA (1948-2024) ===\n');

        gldas_start_year = 1948;
        gldas_end_year = 2024;
        gldas_mask = year(gldas_time) >= gldas_start_year & year(gldas_time) <= gldas_end_year;
        gldas_time_filtered = gldas_time(gldas_mask);

        if isempty(gldas_time_filtered)
            error('No GLDAS timepoints found between %d and %d.', gldas_start_year, gldas_end_year);
        end

        gldas_vars_processed = cell(length(gldas_vars_masked), 1);

        for var_idx = 1:length(gldas_vars_masked)
            fprintf('Processing variable %d/%d: %s\n', var_idx, length(gldas_vars_masked), gldas_var_names{var_idx});

            var_data = gldas_vars_masked{var_idx};
            var_filtered = var_data(:, gldas_mask);

            gldas_years = year(gldas_time_filtered);
            gldas_months = month(gldas_time_filtered);
            [unique_pairs_g, ~, ic_g] = unique([gldas_years gldas_months], 'rows', 'stable');
            n_unique_g = size(unique_pairs_g, 1);
            var_processed = zeros(size(var_filtered,1), n_unique_g);

            for k = 1:n_unique_g
                idxs = find(ic_g == k);
                var_processed(:, k) = mean(var_filtered(:, idxs), 2, 'omitnan');
            end

            gldas_time_processed = datetime(unique_pairs_g(:,1), unique_pairs_g(:,2), 1);

            fprintf('  Processed time range: %s to %s (%d months)\n', ...
                datestr(min(gldas_time_processed), 'mmm yyyy'), ...
                datestr(max(gldas_time_processed), 'mmm yyyy'), ...
                length(gldas_time_processed));

            gldas_complete_months = (datetime(gldas_start_year,1,1):calmonths(1):datetime(gldas_end_year,12,1))';
            fprintf('  Generated %d months (Jan %d - Dec %d)\n', length(gldas_complete_months), gldas_start_year, gldas_end_year);

            var_complete = NaN(size(var_processed,1), length(gldas_complete_months));
            [lia_g, locb_g] = ismember(gldas_time_processed, gldas_complete_months);
            locb_g = locb_g(lia_g);
            var_complete(:, locb_g) = var_processed(:, lia_g);

            % Linear interpolation per point for GLDAS gaps (distinct from
            % GRACE's climatological infilling above -- documented deliberately).
            for p = 1:size(var_complete,1)
                vals = var_complete(p,:);
                nan_mask = isnan(vals);
                if any(nan_mask) && sum(~nan_mask) >= 2
                    x = 1:length(vals);
                    vals(nan_mask) = interp1(x(~nan_mask), vals(~nan_mask), x(nan_mask), 'linear', 'extrap');
                    var_complete(p,:) = vals;
                elseif all(nan_mask)
                    fprintf('  Warning: variable %s point %d has all NaN.\n', gldas_var_names{var_idx}, p);
                end
            end

            gldas_vars_processed{var_idx} = var_complete;
        end

        gldas_time_complete = gldas_complete_months;
        gldas_vars_final = gldas_vars_processed;

        fprintf('GLDAS complete time series: %s to %s (%d months)\n', ...
            datestr(min(gldas_time_complete), 'mmm yyyy'), ...
            datestr(max(gldas_time_complete), 'mmm yyyy'), ...
            length(gldas_time_complete));

        % ==================== CREATE ALIGNED DATASETS ====================
        fprintf('\n=== CREATING ALIGNED DATASETS ===\n');

        overlap_start = datetime(2003, 1, 1);
        overlap_end = datetime(2024, 12, 1);

        jpl_overlap_idx = jpl_time_complete >= overlap_start & jpl_time_complete <= overlap_end;
        gldas_overlap_idx = gldas_time_complete >= overlap_start & gldas_time_complete <= overlap_end;

        if ~any(jpl_overlap_idx)
            error('No overlapping %s months found within %s - %s', config.graceProduct, datestr(overlap_start), datestr(overlap_end));
        end
        if ~any(gldas_overlap_idx)
            error('No overlapping GLDAS months found within %s - %s', datestr(overlap_start), datestr(overlap_end));
        end

        jpl_overlap = jpl_tws_final(:, jpl_overlap_idx);
        gldas_overlap = cellfun(@(x) x(:, gldas_overlap_idx), gldas_vars_final, 'UniformOutput', false);
        overlap_time = jpl_time_complete(jpl_overlap_idx);

        n_points = size(jpl_overlap, 1);
        point_names = arrayfun(@(i) sprintf('Lat_%.3f_Lon_%.3f', ...
            lat_masked(i), lon_masked(i)), 1:n_points, 'UniformOutput', false);

        if strcmp(reconstructionType, 'basin_average')
            fprintf('Calculating basin average across points...\n');
            jpl_overlap = mean(jpl_overlap, 1, 'omitnan');
            gldas_overlap = cellfun(@(x) mean(x, 1, 'omitnan'), gldas_overlap, 'UniformOutput', false);
            n_points = 1;
            point_names = {'Basin_Average'};
        end

        fprintf('Data prepared: %d time steps, %d points\n', size(jpl_overlap, 2), n_points);

        % ==================== TRAINING PREPARATION ====================
        fprintf('\n=== PREPARING FOR MODEL TRAINING ===\n');

        lag_months = config.LagMonths;
        fprintf('Using %d lag month(s) as configured by user.\n', lag_months);
        jpl_overlap_t = jpl_overlap';
        gldas_overlap_t = cellfun(@(x) x', gldas_overlap, 'UniformOutput', false);

        models = cell(1, n_points);
        train_time_global = [];
        test_time_global = [];

        % ==================== POINT-WISE MODEL TRAINING ====================
        fprintf('\n=== POINT-WISE MODEL TRAINING (%s) ===\n', upper(methodType));

        for point_idx = 1:n_points
            fprintf('\nTraining point %d/%d (%s)...\n', point_idx, n_points, point_names{point_idx});

            [features_point, target_point, valid_time_point] = app.createLaggedFeatures2D( ...
                gldas_overlap_t, jpl_overlap_t, overlap_time, lag_months, point_idx, USE_CURRENT_MONTH_IN_FEATURES);

            if isempty(features_point) || isempty(target_point)
                fprintf('  Warning: No valid features/target for point %d - skipping.\n', point_idx);
                models{point_idx} = [];
                continue;
            end

            valid_time_years = year(valid_time_point);
            train_mask = valid_time_years >= 2003 & valid_time_years <= 2020;
            test_mask = valid_time_years >= 2021 & valid_time_years <= 2024;

            X_train_point = features_point(train_mask, :);
            X_test_point = features_point(test_mask, :);
            y_train_point = target_point(train_mask, :);
            y_test_point = target_point(test_mask, :);

            fprintf('  Training samples: %d, Testing samples: %d\n', ...
                size(X_train_point, 1), size(X_test_point, 1));

            if point_idx == 1
                train_time_global = valid_time_point(train_mask);
                test_time_global = valid_time_point(test_mask);
            end

            if point_idx == 1
                y_pred_train_global = NaN(sum(train_mask), n_points);
                y_pred_test_global = NaN(sum(test_mask), n_points);
                y_train_global = NaN(sum(train_mask), n_points);
                y_test_global = NaN(sum(test_mask), n_points);
            end

            y_train_global(:, point_idx) = y_train_point;
            y_test_global(:, point_idx) = y_test_point;

            if size(X_train_point, 1) < 20
                fprintf('  Warning: Insufficient training samples (%d) - skipping.\n', size(X_train_point, 1));
                models{point_idx} = [];
                continue;
            end

            % ==================== METHOD-SPECIFIC TRAINING ====================
            if strcmpi(methodType, 'RF')
                fprintf('  Training Random Forest...\n');

                n_features = size(X_train_point, 2);
                num_vars_to_sample = max(1, floor(sqrt(n_features)));

                rf_model = TreeBagger(config.NTrees, X_train_point, y_train_point, ...
                    'Method', 'regression', ...
                    'MinLeafSize', config.MinLeafSize, ...
                    'MaxNumSplits', config.MaxNumSplits, ...
                    'NumPredictorsToSample', num_vars_to_sample, ...
                    'OOBPrediction', 'on', ...
                    'OOBPredictorImportance', 'on', ...
                    'Reproducible', true, ...
                    'InBagFraction', 0.8);

                models{point_idx} = rf_model;

                train_pred = predict(rf_model, X_train_point);
                y_pred_train_global(:, point_idx) = train_pred;

                if ~isempty(X_test_point)
                    test_pred = predict(rf_model, X_test_point);
                    y_pred_test_global(:, point_idx) = test_pred;
                end

                oob_err = oobError(rf_model);
                if ~isempty(oob_err)
                    fprintf('  Final OOB Error = %.4f\n', oob_err(end));
                end

            elseif strcmpi(methodType, 'ANN')
                fprintf('  Training Neural Network...\n');

                mu_X = mean(X_train_point, 1);
                sigma_X = std(X_train_point, 0, 1);
                sigma_X(sigma_X == 0) = 1;
                X_train_norm = (X_train_point - mu_X) ./ sigma_X;

                y_min = min(y_train_point);
                y_max = max(y_train_point);
                y_range = y_max - y_min;
                if y_range == 0
                    y_range = 1;
                end
                y_train_norm = (y_train_point - y_min) / y_range;

                hiddenLayerSize = config.HiddenLayers;
                net = fitnet(hiddenLayerSize);
                net.divideParam.trainRatio = 0.8;
                net.divideParam.valRatio = 0.2;
                net.divideParam.testRatio = 0;
                net.trainParam.epochs = config.MaxEpochs;
                net.trainParam.showWindow = false;
                net.trainParam.max_fail = config.MaxFail;
                net.trainParam.goal = 1e-5;
                % Note: trainlm (Levenberg-Marquardt, MATLAB default for fitnet)
                % does not use a learning rate; mu damping is used instead.

                [net, tr] = train(net, X_train_norm', y_train_norm');

                models{point_idx} = struct(...
                    'net', net, ...
                    'mu_X', mu_X, ...
                    'sigma_X', sigma_X, ...
                    'y_min', y_min, ...
                    'y_range', y_range, ...
                    'model_type', 'ann');

                train_pred_norm = net(X_train_norm');
                train_pred = (train_pred_norm' * y_range) + y_min;
                y_pred_train_global(:, point_idx) = train_pred;

                if ~isempty(X_test_point)
                    X_test_norm = (X_test_point - mu_X) ./ sigma_X;
                    test_pred_norm = net(X_test_norm');
                    test_pred = (test_pred_norm' * y_range) + y_min;
                    y_pred_test_global(:, point_idx) = test_pred;
                end

                if exist('tr','var') && isfield(tr, 'best_perf')
                    fprintf('  Best validation performance = %.4f\n', tr.best_perf);
                end
            else
                error('Unknown methodType: %s', methodType);
            end

            if mod(point_idx, 10) == 0
                fprintf('Completed %d/%d points\n', point_idx, n_points);
            end
        end

        % ==================== FEATURE IMPORTANCE ANALYSIS FOR ALL POINTS ====================
        fprintf('\n=== PERFORMING FEATURE IMPORTANCE ANALYSIS ===\n');

        if USE_CURRENT_MONTH_IN_FEATURES
            feature_names = {};
            for var_idx = 1:length(gldas_var_names)
                feature_names{end+1} = sprintf('%s_t', gldas_var_names{var_idx});
                for lag = 1:lag_months
                    feature_names{end+1} = sprintf('%s_lag%d', gldas_var_names{var_idx}, lag);
                end
            end
        else
            feature_names = {};
            for var_idx = 1:length(gldas_var_names)
                for lag = 1:lag_months
                    feature_names{end+1} = sprintf('%s_lag%d', gldas_var_names{var_idx}, lag);
                end
            end
        end

        fprintf('Created %d feature names\n', length(feature_names));

        shap_results = struct();
        shap_results.shap_values = cell(1, n_points);
        shap_results.feature_importance = zeros(n_points, length(feature_names));
        shap_results.expected_value = zeros(1, n_points);
        shap_results.feature_names = feature_names;
        shap_results.point_names = point_names;

        if ~isempty(models) && n_points > 0
            fprintf('\nPerforming feature importance analysis...\n');
            fprintf('Number of points to analyze: %d\n', n_points);

            for point_idx = 1:n_points
                fprintf('  Analyzing point %d/%d (%s)...\n', point_idx, n_points, point_names{point_idx});

                if isempty(models{point_idx})
                    fprintf('    No model for this point - skipping\n');
                    shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                    shap_results.expected_value(point_idx) = NaN;
                    continue;
                end

                [features_point, target_point, valid_time_point] = app.createLaggedFeatures2D( ...
                    gldas_overlap_t, jpl_overlap_t, overlap_time, lag_months, point_idx, USE_CURRENT_MONTH_IN_FEATURES);

                if isempty(features_point) || isempty(target_point)
                    fprintf('    No valid features/target for this point\n');
                    shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                    shap_results.expected_value(point_idx) = NaN;
                    continue;
                end

                valid_time_years = year(valid_time_point);
                train_mask = valid_time_years >= 2003 & valid_time_years <= 2020;

                if sum(train_mask) < 10
                    fprintf('    Insufficient training samples (%d)\n', sum(train_mask));
                    shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                    shap_results.expected_value(point_idx) = NaN;
                    continue;
                end

                X_train_point = features_point(train_mask, :);
                y_train_point = target_point(train_mask);

                try
                    if strcmpi(methodType, 'RF')
                        if isprop(models{point_idx}, 'OOBPermutedPredictorDeltaError')
                            feature_importance = models{point_idx}.OOBPermutedPredictorDeltaError;
                            if ~isempty(feature_importance)
                                shap_results.feature_importance(point_idx, :) = feature_importance;
                                shap_results.expected_value(point_idx) = mean(y_train_point);

                                n_samples = size(X_train_point, 1);
                                shap_results.shap_values{point_idx} = repmat(feature_importance, n_samples, 1);

                                [sorted_imp, sorted_idx] = sort(feature_importance, 'descend');
                                fprintf('    Top 3 features: ');
                                for i = 1:min(3, length(sorted_idx))
                                    fprintf('%s(%.3f) ', feature_names{sorted_idx(i)}, sorted_imp(i));
                                end
                                fprintf('\n');
                            end
                        end

                    elseif strcmpi(methodType, 'ANN')
                        if isstruct(models{point_idx})
                            mdl = models{point_idx};

                            X_norm = (X_train_point - mdl.mu_X) ./ mdl.sigma_X;

                            y_pred_norm = mdl.net(X_norm')';
                            y_pred = y_pred_norm * mdl.y_range + mdl.y_min;
                            baseline = mean(y_pred);

                            n_features = size(X_norm, 2);
                            n_samples_use = min(50, size(X_norm, 1));
                            sample_idx = randperm(size(X_norm, 1), n_samples_use);
                            X_sample = X_norm(sample_idx, :);
                            y_pred_sample = y_pred(sample_idx);

                            sensitivities = zeros(n_samples_use, n_features);

                            for f = 1:n_features
                                X_perturbed = X_sample;
                                X_perturbed(:, f) = X_perturbed(:, f) + 1;

                                y_perturbed_norm = mdl.net(X_perturbed')';
                                y_perturbed = y_perturbed_norm * mdl.y_range + mdl.y_min;
                                sensitivities(:, f) = y_perturbed - y_pred_sample;
                            end

                            mean_abs_sens = mean(abs(sensitivities), 1);
                            shap_results.feature_importance(point_idx, :) = mean_abs_sens;
                            shap_results.expected_value(point_idx) = baseline;

                            n_samples_full = size(X_norm, 1);
                            shap_results.shap_values{point_idx} = repmat(mean_abs_sens, n_samples_full, 1);

                            [sorted_sens, sorted_idx] = sort(mean_abs_sens, 'descend');
                            fprintf('    Top 3 features: ');
                            for i = 1:min(3, length(sorted_idx))
                                fprintf('%s(%.3f) ', feature_names{sorted_idx(i)}, sorted_sens(i));
                            end
                            fprintf('\n');
                        end
                    end

                catch err
                    fprintf('    Error analyzing point %d: %s\n', point_idx, err.message);
                    shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                    shap_results.expected_value(point_idx) = NaN;
                end
            end

            valid_points = sum(~isnan(shap_results.expected_value));
            fprintf('\nFeature importance analysis completed for %d/%d points\n', valid_points, n_points);

            if valid_points > 0
                overall_importance = mean(shap_results.feature_importance, 1, 'omitnan');
                [sorted_overall, sorted_idx] = sort(overall_importance, 'descend');

                fprintf('\n=== OVERALL TOP 5 FEATURES (across all valid points) ===\n');
                for i = 1:min(5, length(sorted_idx))
                    fprintf('  %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_overall(i));
                end
            end

        else
            fprintf('No models available for feature importance analysis\n');
        end

        % ==================== SAVE FEATURE IMPORTANCE RESULTS ====================
        fprintf('\n=== SAVING FEATURE IMPORTANCE RESULTS ===\n');

        has_shap_results = ~isempty(shap_results) && ...
            isfield(shap_results, 'feature_importance') && ...
            any(~isnan(shap_results.feature_importance(:)));

        if has_shap_results
            timestamp = datestr(now, 'yyyymmdd_HHMMSS');

            main_results = cell(n_points + 2, length(feature_names) + 2);

            main_results{1, 1} = 'Point';
            for f = 1:length(feature_names)
                main_results{1, f+1} = feature_names{f};
            end
            main_results{1, end} = 'Expected_Value';

            valid_point_count = 0;
            for p = 1:n_points
                main_results{p+1, 1} = shap_results.point_names{p};

                if any(~isnan(shap_results.feature_importance(p, :)))
                    valid_point_count = valid_point_count + 1;
                    for f = 1:length(feature_names)
                        main_results{p+1, f+1} = shap_results.feature_importance(p, f);
                    end
                    main_results{p+1, end} = shap_results.expected_value(p);
                else
                    for f = 1:length(feature_names)
                        main_results{p+1, f+1} = NaN;
                    end
                    main_results{p+1, end} = NaN;
                end
            end

            main_results{n_points+2, 1} = 'Overall_Average';
            if valid_point_count > 0
                for f = 1:length(feature_names)
                    col_vals = zeros(valid_point_count, 1);
                    idx = 1;
                    for p = 1:n_points
                        val = shap_results.feature_importance(p, f);
                        if ~isnan(val)
                            col_vals(idx) = val;
                            idx = idx + 1;
                        end
                    end
                    main_results{n_points+2, f+1} = mean(col_vals(1:idx-1));
                end

                exp_vals = shap_results.expected_value(~isnan(shap_results.expected_value));
                main_results{n_points+2, end} = mean(exp_vals);
            else
                for f = 1:length(feature_names)
                    main_results{n_points+2, f+1} = NaN;
                end
                main_results{n_points+2, end} = NaN;
            end

            % FIX: filenames now include graceProduct and a sanitized dataset
            % tag so different configurations no longer silently overwrite
            % each other's output.
            try
                selectedBasin = app.BasinSelectionButton.Value;
                datasetTag = strrep(config.datasetChoice, ' ', '');
                filename = sprintf('Feature_Importance_%s_%s_%s_%s_%s.xlsx', ...
                    reconstructionType, methodType, config.graceProduct, datasetTag, selectedBasin);

                writetable(cell2table(main_results(2:end, :), ...
                    'VariableNames', main_results(1, :)), filename, 'Sheet', 'Feature_Importance');

                ranking_table = cell(length(feature_names) + 1, 4);
                ranking_table{1, 1} = 'Rank';
                ranking_table{1, 2} = 'Feature';
                ranking_table{1, 3} = 'Overall_Importance';
                ranking_table{1, 4} = 'Std_Across_Points';

                if valid_point_count > 0
                    overall_imp = zeros(1, length(feature_names));
                    std_across = zeros(1, length(feature_names));

                    for f = 1:length(feature_names)
                        vals = [];
                        for p = 1:n_points
                            val = shap_results.feature_importance(p, f);
                            if ~isnan(val)
                                vals = [vals; val];
                            end
                        end

                        if ~isempty(vals)
                            overall_imp(f) = mean(vals);
                            std_across(f) = std(vals);
                        end
                    end

                    [sorted_imp, sorted_idx] = sort(overall_imp, 'descend');

                    for i = 1:length(sorted_idx)
                        f = sorted_idx(i);
                        ranking_table{i+1, 1} = i;
                        ranking_table{i+1, 2} = feature_names{f};
                        ranking_table{i+1, 3} = sorted_imp(i);
                        ranking_table{i+1, 4} = std_across(f);
                    end
                end

                writetable(cell2table(ranking_table(2:end, :), ...
                    'VariableNames', ranking_table(1, :)), filename, 'Sheet', 'Ranking');

                metadata = cell(14, 2);
                metadata{1,1} = 'Analysis Date'; metadata{1,2} = timestamp;
                metadata{2,1} = 'Reconstruction Type'; metadata{2,2} = reconstructionType;
                metadata{3,1} = 'Method'; metadata{3,2} = methodType;
                metadata{4,1} = 'GRACE Product'; metadata{4,2} = config.graceProduct;
                metadata{5,1} = 'Forcing Dataset'; metadata{5,2} = config.datasetChoice;
                metadata{6,1} = 'Total Points'; metadata{6,2} = n_points;
                metadata{7,1} = 'Valid Points Analyzed'; metadata{7,2} = valid_point_count;
                metadata{8,1} = 'Lag Months'; metadata{8,2} = lag_months;
                metadata{9,1} = 'Training Period'; metadata{9,2} = '2003-2020';
                metadata{10,1} = 'Testing Period'; metadata{10,2} = '2021-2024';
                metadata{11,1} = 'Feature Strategy';
                if USE_CURRENT_MONTH_IN_FEATURES
                    metadata{11,2} = 'Current month + lags';
                else
                    metadata{11,2} = 'Lags only (causal)';
                end
                metadata{12,1} = 'GLDAS Variables';
                metadata{12,2} = strjoin(gldas_var_names, ', ');
                metadata{13,1} = 'Features per variable';
                if USE_CURRENT_MONTH_IN_FEATURES
                    metadata{13,2} = sprintf('t + %d lags', lag_months);
                else
                    metadata{13,2} = sprintf('%d lags', lag_months);
                end
                metadata{14,1} = 'Total Features'; metadata{14,2} = length(feature_names);

                writetable(cell2table(metadata), filename, 'Sheet', 'Metadata');

                if n_points > 1
                    summary_table = cell(6, 2);
                    summary_table{1,1} = 'Statistic'; summary_table{1,2} = 'Value';

                    if valid_point_count > 0
                        valid_data = shap_results.feature_importance(~all(isnan(shap_results.feature_importance), 2), :);
                        if size(valid_data, 1) > 1
                            corr_matrix = corr(valid_data');
                            avg_corr = mean(corr_matrix(:));
                            summary_table{2,1} = 'Average Correlation Between Points';
                            summary_table{2,2} = avg_corr;
                        end

                        feature_std = std(shap_results.feature_importance, 0, 1, 'omitnan');
                        avg_std = mean(feature_std);
                        summary_table{3,1} = 'Average Feature Std Across Points';
                        summary_table{3,2} = avg_std;

                        [min_std, min_idx] = min(feature_std);
                        summary_table{4,1} = 'Most Consistent Feature';
                        summary_table{4,2} = feature_names{min_idx};
                        summary_table{5,1} = 'Consistency Score (1/Std)';
                        summary_table{5,2} = 1/min_std;

                        if size(valid_data, 1) > 0
                            top_features = zeros(1, size(valid_data, 1));
                            for i = 1:size(valid_data, 1)
                                [~, top_idx] = max(valid_data(i, :));
                                top_features(i) = top_idx;
                            end
                            most_common_top = mode(top_features);
                            summary_table{6,1} = 'Most Common Top Feature';
                            summary_table{6,2} = feature_names{most_common_top};
                        end

                        writetable(cell2table(summary_table), filename, 'Sheet', 'Summary');
                    end
                end

                fprintf('Feature importance results saved to: %s\n', filename);

            catch err
                fprintf('Warning: Could not save to Excel: %s\n', err.message);

                try
                    selectedBasin = app.BasinSelectionButton.Value;
                    datasetTag = strrep(config.datasetChoice, ' ', '');
                    csv_filename = sprintf('Feature_Importance_%s_%s_%s_%s_%s.csv', ...
                        reconstructionType, methodType, config.graceProduct, datasetTag, selectedBasin);

                    data_table = array2table(shap_results.feature_importance, ...
                        'VariableNames', feature_names, ...
                        'RowNames', shap_results.point_names);

                    data_table.ExpectedValue = shap_results.expected_value';

                    writetable(data_table, csv_filename, 'WriteRowNames', true);
                    fprintf('Results saved to CSV: %s\n', csv_filename);

                catch csv_err
                    fprintf('Could not save to CSV either: %s\n', csv_err.message);
                end
            end

        else
            fprintf('No valid feature importance results to save\n');
        end

        % ==================== FULL RECONSTRUCTION (1948-2024) ====================
        fprintf('\n=== FULL RECONSTRUCTION (1948-2024) ===\n');

        fprintf('GLDAS data for reconstruction: %s to %s (%d months)\n', ...
            datestr(min(gldas_time_complete), 'mmm yyyy'), ...
            datestr(max(gldas_time_complete), 'mmm yyyy'), ...
            length(gldas_time_complete));

        if strcmp(reconstructionType, 'basin_average')
            gldas_full_2d = cellfun(@(x) mean(x, 1, 'omitnan'), gldas_vars_final, 'UniformOutput', false);
            gldas_full_2d_t = cellfun(@(x) x', gldas_full_2d, 'UniformOutput', false);
        else
            gldas_full_2d_t = cellfun(@(x) x', gldas_vars_final, 'UniformOutput', false);
        end

        reconstructed_tws = NaN(n_points, length(gldas_time_complete));

        fprintf('Reconstructing for %d time steps across %d points\n', ...
            length(gldas_time_complete), n_points);
        fprintf('Reconstruction period: %s to %s\n', datestr(min(gldas_time_complete), 'mmm yyyy'), datestr(max(gldas_time_complete), 'mmm yyyy'));
        if USE_CURRENT_MONTH_IN_FEATURES
            fprintf('Using current month + %d lags for reconstruction\n', lag_months);
        else
            fprintf('Using %d lags only for reconstruction (causal)\n', lag_months);
        end

        for point_idx = 1:n_points
            if ~isempty(models{point_idx})
                fprintf('Reconstructing point %d/%d...\n', point_idx, n_points);

                [full_features_point, ~, full_valid_time_point] = app.createLaggedFeatures2D( ...
                    gldas_full_2d_t, [], gldas_time_complete, lag_months, point_idx, USE_CURRENT_MONTH_IN_FEATURES);

                if isempty(full_features_point)
                    fprintf('  Warning: No valid features for point %d - skipping.\n', point_idx);
                    continue;
                end

                valid_full = ~any(isnan(full_features_point), 2);

                if sum(valid_full) > 0
                    if strcmpi(methodType, 'RF')
                        preds = predict(models{point_idx}, full_features_point(valid_full, :));
                    elseif strcmpi(methodType, 'ANN')
                        mdl = models{point_idx};
                        X_full = full_features_point(valid_full, :);
                        X_full_norm = (X_full - mdl.mu_X) ./ mdl.sigma_X;
                        preds_norm = mdl.net(X_full_norm')';
                        preds = preds_norm * mdl.y_range + mdl.y_min;
                    else
                        preds = NaN(sum(valid_full), 1);
                    end

                    valid_times = full_valid_time_point(valid_full);
                    [lia, locb] = ismember(valid_times, gldas_time_complete);

                    if any(lia)
                        valid_indices = locb(lia);
                        reconstructed_tws(point_idx, valid_indices) = preds(lia)';
                    end
                else
                    fprintf('  No valid feature rows for point %d\n', point_idx);
                end
            else
                fprintf('  No model for point %d - leaving as NaN\n', point_idx);
            end

            if mod(point_idx, 10) == 0
                fprintf('Reconstructed %d/%d points\n', point_idx, n_points);
            end
        end

        rec_vals = reconstructed_tws(:);
        if any(~isnan(rec_vals))
            fprintf('Reconstructed TWSA statistics: Min=%.4f, Max=%.4f, Mean=%.4f\n', ...
                min(rec_vals(~isnan(rec_vals))), ...
                max(rec_vals(~isnan(rec_vals))), ...
                mean(rec_vals(~isnan(rec_vals))));
        else
            fprintf('Warning: Reconstructed TWSA is all NaN.\n');
        end

        % ==================== POST-PROCESSING CHECK ====================
        reconstructed_min = min(rec_vals(~isnan(rec_vals)));
        if ~isempty(reconstructed_min) && reconstructed_min > 0
            fprintf('Warning: All reconstructed values are positive. Applying bias correction using training residual mean...\n');

            for point_idx = 1:n_points
                if ~isempty(models{point_idx})
                    if ~all(isnan(y_train_global(:, point_idx))) && ~all(isnan(y_pred_train_global(:, point_idx)))
                        train_mean = mean(y_train_global(:, point_idx), 'omitnan');
                        train_pred_mean = mean(y_pred_train_global(:, point_idx), 'omitnan');
                        if ~isnan(train_mean) && ~isnan(train_pred_mean)
                            bias = train_mean - train_pred_mean;
                            reconstructed_tws(point_idx, :) = reconstructed_tws(point_idx, :) + bias;
                        end
                    end
                end
            end

            rec_vals2 = reconstructed_tws(:);
            fprintf('After bias correction: Min=%.4f, Max=%.4f\n', min(rec_vals2(~isnan(rec_vals2))), max(rec_vals2(~isnan(rec_vals2))));
        end

        % ==================== SAVE RESULTS ====================
        fprintf('\n=== SAVING RESULTS ===\n');

        if length(gldas_time_complete) ~= size(reconstructed_tws, 2)
            fprintf('Adjusting time vector to match data...\n');
            min_length = min(length(gldas_time_complete), size(reconstructed_tws, 2));
            full_valid_time = gldas_time_complete(1:min_length);
            reconstructed_tws = reconstructed_tws(:, 1:min_length);
        else
            full_valid_time = gldas_time_complete;
        end

        % FIX: filename tag now includes graceProduct and datasetChoice so
        % different configurations no longer overwrite each other's saved
        % reconstruction output.
        method_note = '';
        if USE_CURRENT_MONTH_IN_FEATURES
            method_note = '_withCurrentMonth';
        end
        datasetTag = strrep(config.datasetChoice, ' ', '');
        full_method_tag = sprintf('%s_%s_%s%s', methodType, config.graceProduct, datasetTag, method_note);

        try
            app.saveReconstructedTWSToExcel2D(reconstructed_tws, full_valid_time, point_names, ...
                reconstructionType, lat_masked, lon_masked, full_method_tag);
            fprintf('Reconstruction results saved successfully.\n');
        catch err
            warning('Saving with methodType failed: %s\nAttempting fallback save without methodType...', err.message);
            app.saveReconstructedTWSToExcel2D(reconstructed_tws, full_valid_time, point_names, ...
                reconstructionType, lat_masked, lon_masked);
            fprintf('Reconstruction results saved (without method type in filename).\n');
        end

        % ==================== DISPLAY RESULTS ====================
        train_rmse = [];
        test_rmse = [];
        train_corr = [];
        test_corr = [];

        if exist('y_train_global', 'var') && exist('y_pred_train_global', 'var')
            train_rmse = zeros(1, n_points);
            train_corr = zeros(1, n_points);
            for p = 1:n_points
                valid = ~isnan(y_train_global(:, p)) & ~isnan(y_pred_train_global(:, p));
                if sum(valid) > 5
                    train_rmse(p) = sqrt(mean((y_train_global(valid, p) - y_pred_train_global(valid, p)).^2));
                    train_corr(p) = corr(y_train_global(valid, p), y_pred_train_global(valid, p));
                else
                    train_rmse(p) = NaN;
                    train_corr(p) = NaN;
                end
            end
        end

        if exist('y_test_global', 'var') && exist('y_pred_test_global', 'var')
            test_rmse = zeros(1, n_points);
            test_corr = zeros(1, n_points);
            for p = 1:n_points
                valid = ~isnan(y_test_global(:, p)) & ~isnan(y_pred_test_global(:, p));
                if sum(valid) > 5
                    test_rmse(p) = sqrt(mean((y_test_global(valid, p) - y_pred_test_global(valid, p)).^2));
                    test_corr(p) = corr(y_test_global(valid, p), y_pred_test_global(valid, p));
                else
                    test_rmse(p) = NaN;
                    test_corr(p) = NaN;
                end
            end
        end

        fprintf('\n=== FINAL RESULTS ===\n');
        fprintf('Method: %s\n', upper(methodType));
        fprintf('GRACE Product: %s\n', config.graceProduct);
        fprintf('Forcing: %s\n', config.datasetChoice);
        fprintf('Reconstruction Type: %s\n', reconstructionType);
        if USE_CURRENT_MONTH_IN_FEATURES
            fprintf('Feature Strategy: Current month + %d lags\n', lag_months);
        else
            fprintf('Feature Strategy: %d lags only (causal)\n', lag_months);
        end
        if ~isempty(train_time_global)
            fprintf('Training Period: %s to %s\n', datestr(min(train_time_global), 'mmm yyyy'), datestr(max(train_time_global), 'mmm yyyy'));
        end
        if ~isempty(test_time_global)
            fprintf('Testing Period: %s to %s\n', datestr(min(test_time_global), 'mmm yyyy'), datestr(max(test_time_global), 'mmm yyyy'));
        end
        fprintf('Reconstruction Period: %s to %s\n', datestr(min(full_valid_time), 'mmm yyyy'), datestr(max(full_valid_time), 'mmm yyyy'));

        if ~isempty(train_rmse)
            fprintf('Training RMSE: %.4f +/- %.4f\n', nanmean(train_rmse), nanstd(train_rmse));
        end
        if ~isempty(test_rmse)
            fprintf('Testing RMSE: %.4f +/- %.4f\n', nanmean(test_rmse), nanstd(test_rmse));
        end
        if ~isempty(train_corr)
            fprintf('Training Correlation: %.4f +/- %.4f\n', nanmean(train_corr), nanstd(train_corr));
        end
        if ~isempty(test_corr)
            fprintf('Testing Correlation: %.4f +/- %.4f\n', nanmean(test_corr), nanstd(test_corr));
        end

        if exist('test_corr','var') && ~isempty(test_corr)
            valid_test_corr = test_corr(~isnan(test_corr));
            if ~isempty(valid_test_corr)
                success_rate = sum(valid_test_corr > 0.3) / length(valid_test_corr) * 100;
                fprintf('Success rate (testing correlation > 0.3): %.1f%%\n', success_rate);
            end
        end

        uialert(app.UIFigure, sprintf('%s Reconstruction Completed!\nGRACE: %s | Forcing: %s\nTesting correlation: %.3f\nReconstruction: %s to %s', ...
            upper(methodType), config.graceProduct, config.datasetChoice, nanmean(test_corr), ...
            datestr(min(full_valid_time), 'mmm yyyy'), ...
            datestr(max(full_valid_time), 'mmm yyyy')), 'Success', 'Icon', 'success');

    catch ME
        fprintf('Error in TWSA reconstruction: %s\n', ME.message);
        for i = 1:length(ME.stack)
            fprintf('  File: %s, Function: %s, Line: %d\n', ...
                ME.stack(i).file, ME.stack(i).name, ME.stack(i).line);
        end
        uialert(app.UIFigure, sprintf('Reconstruction Failed: %s', ME.message), 'Error', 'Icon', 'error');
    end
end

        % function shap_results = performSHAPanalysisRF2D(app, rf_models, X_train, y_train, feature_names, n_points)
        %     % Perform SHAP analysis for Random Forest models
        %     % Uses MATLAB's built-in TreeBagger feature importance
        %
        %     fprintf('Performing feature importance analysis for Random Forest models...\n');
        %
        %     shap_results = struct();
        %     shap_results.shap_values = [];
        %     shap_results.feature_names = feature_names;
        %     shap_results.expected_value = [];
        %
        %     % For Random Forest, we use OOBPermutedPredictorDeltaError as feature importance
        %     % This is analogous to SHAP values
        %
        %     if n_points == 1
        %         point_idx = 1;
        %         if ~isempty(rf_models{point_idx})
        %             % Get valid data for this point
        %             valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train), 2);
        %
        %             if sum(valid_train) > 10
        %                 X_train_point = X_train(valid_train, :);
        %                 y_train_point = y_train(valid_train, point_idx);
        %
        %                 % Get feature importance from TreeBagger
        %                 if isprop(rf_models{point_idx}, 'OOBPermutedPredictorDeltaError') && ...
        %                         ~isempty(rf_models{point_idx}.OOBPermutedPredictorDeltaError)
        %
        %                     % Get feature importance values
        %                     feature_importance = rf_models{point_idx}.OOBPermutedPredictorDeltaError;
        %
        %                     % Create SHAP-like structure
        %                     % For simplicity, we'll create a matrix where each sample has the same importance
        %                     % This is a simplification since TreeBagger gives aggregated importance
        %                     n_samples = size(X_train_point, 1);
        %                     shap_values_mat = repmat(feature_importance, n_samples, 1);
        %
        %                     shap_results.shap_values = shap_values_mat;
        %                     shap_results.expected_value = mean(y_train_point);
        %
        %                     fprintf('Feature importance calculated for %d features\n', length(feature_importance));
        %
        %                     % Display top 5 features
        %                     [sorted_imp, sorted_idx] = sort(feature_importance, 'descend');
        %                     fprintf('Top 5 features:\n');
        %                     for i = 1:min(5, length(sorted_idx))
        %                         fprintf('  %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_imp(i));
        %                     end
        %                 else
        %                     fprintf('No feature importance available for TreeBagger model\n');
        %                     % Create placeholder
        %                     n_samples = size(X_train_point, 1);
        %                     n_features = length(feature_names);
        %                     shap_results.shap_values = zeros(n_samples, n_features);
        %                     shap_results.expected_value = mean(y_train_point);
        %                 end
        %             else
        %                 fprintf('Insufficient training samples for feature importance\n');
        %                 shap_results.shap_values = [];
        %                 shap_results.expected_value = NaN;
        %             end
        %         else
        %             fprintf('No Random Forest model available\n');
        %             shap_results.shap_values = [];
        %             shap_results.expected_value = NaN;
        %         end
        %     else
        %         % For multiple points, we'll analyze the first point only for simplicity
        %         fprintf('Multiple points detected, analyzing first point only...\n');
        %
        %         point_idx = 1;
        %         if ~isempty(rf_models{point_idx})
        %             % Get valid data for this point
        %             valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train), 2);
        %
        %             if sum(valid_train) > 10
        %                 X_train_point = X_train(valid_train, :);
        %                 y_train_point = y_train(valid_train, point_idx);
        %
        %                 % Get feature importance from TreeBagger
        %                 if isprop(rf_models{point_idx}, 'OOBPermutedPredictorDeltaError') && ...
        %                         ~isempty(rf_models{point_idx}.OOBPermutedPredictorDeltaError)
        %
        %                     feature_importance = rf_models{point_idx}.OOBPermutedPredictorDeltaError;
        %                     n_samples = size(X_train_point, 1);
        %                     shap_values_mat = repmat(feature_importance, n_samples, 1);
        %
        %                     shap_results.shap_values = shap_values_mat;
        %                     shap_results.expected_value = mean(y_train_point);
        %
        %                     fprintf('Feature importance calculated for first point\n');
        %                 else
        %                     n_samples = size(X_train_point, 1);
        %                     n_features = length(feature_names);
        %                     shap_results.shap_values = zeros(n_samples, n_features);
        %                     shap_results.expected_value = mean(y_train_point);
        %                 end
        %             else
        %                 shap_results.shap_values = [];
        %                 shap_results.expected_value = NaN;
        %             end
        %         else
        %             shap_results.shap_values = [];
        %             shap_results.expected_value = NaN;
        %         end
        %     end
        % end
        %
        % function shap_results = performKernelSHAPanalysisANN2D(app, ann_models, X_train, y_train, feature_names, n_points)
        %     % Perform sensitivity analysis for Neural Network models
        %     % Uses perturbation-based sensitivity analysis instead of Python SHAP
        %
        %     fprintf('Performing sensitivity analysis for Neural Network models...\n');
        %
        %     shap_results = struct();
        %     shap_results.shap_values = [];
        %     shap_results.feature_names = feature_names;
        %     shap_results.expected_value = [];
        %
        %     if n_points == 1
        %         point_idx = 1;
        %         if ~isempty(ann_models{point_idx}) && isstruct(ann_models{point_idx})
        %             % Get model components
        %             mdl = ann_models{point_idx};
        %
        %             % Get valid data for this point
        %             valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train), 2);
        %
        %             if sum(valid_train) > 10
        %                 X_train_point = X_train(valid_train, :);
        %                 y_train_point = y_train(valid_train, point_idx);
        %
        %                 % Normalize features
        %                 X_norm = (X_train_point - mdl.mu_X) ./ mdl.sigma_X;
        %
        %                 % Get baseline predictions
        %                 y_pred_norm = mdl.net(X_norm')';
        %                 y_pred = y_pred_norm * mdl.y_range + mdl.y_min;
        %                 baseline = mean(y_pred);
        %
        %                 % Calculate sensitivity by perturbing each feature
        %                 n_features = size(X_norm, 2);
        %                 sensitivities = zeros(size(X_norm, 1), n_features);
        %
        %                 fprintf('Calculating feature sensitivities...\n');
        %
        %                 for f = 1:n_features
        %                     % Create perturbed input (increase by 1 standard deviation)
        %                     X_perturbed = X_norm;
        %                     X_perturbed(:, f) = X_perturbed(:, f) + 1; % Perturb by 1 std
        %
        %                     % Get predictions with perturbed input
        %                     y_perturbed_norm = mdl.net(X_perturbed')';
        %                     y_perturbed = y_perturbed_norm * mdl.y_range + mdl.y_min;
        %
        %                     % Calculate sensitivity (change in output)
        %                     sensitivities(:, f) = y_perturbed - y_pred;
        %
        %                     if mod(f, 5) == 0
        %                         fprintf('  Completed %d/%d features\n', f, n_features);
        %                     end
        %                 end
        %
        %                 % Create SHAP-like results structure
        %                 shap_results.shap_values = sensitivities;
        %                 shap_results.expected_value = baseline;
        %
        %                 % Calculate and display mean absolute sensitivities
        %                 mean_abs_sens = mean(abs(sensitivities), 1);
        %                 [sorted_sens, sorted_idx] = sort(mean_abs_sens, 'descend');
        %
        %                 fprintf('Top 5 most sensitive features:\n');
        %                 for i = 1:min(5, length(sorted_idx))
        %                     fprintf('  %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_sens(i));
        %                 end
        %             else
        %                 fprintf('Insufficient training samples for sensitivity analysis\n');
        %                 shap_results.shap_values = [];
        %                 shap_results.expected_value = NaN;
        %             end
        %         else
        %             fprintf('No Neural Network model available\n');
        %             shap_results.shap_values = [];
        %             shap_results.expected_value = NaN;
        %         end
        %     else
        %         % For multiple points, analyze first point only
        %         fprintf('Multiple points detected, analyzing first point only...\n');
        %
        %         point_idx = 1;
        %         if ~isempty(ann_models{point_idx}) && isstruct(ann_models{point_idx})
        %             mdl = ann_models{point_idx};
        %
        %             valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train), 2);
        %
        %             if sum(valid_train) > 10
        %                 X_train_point = X_train(valid_train, :);
        %                 y_train_point = y_train(valid_train, point_idx);
        %
        %                 % Normalize features
        %                 X_norm = (X_train_point - mdl.mu_X) ./ mdl.sigma_X;
        %
        %                 % Get baseline predictions
        %                 y_pred_norm = mdl.net(X_norm')';
        %                 y_pred = y_pred_norm * mdl.y_range + mdl.y_min;
        %                 baseline = mean(y_pred);
        %
        %                 % Simplified sensitivity calculation (faster)
        %                 n_features = size(X_norm, 2);
        %                 n_samples = min(100, size(X_norm, 1)); % Use subset for speed
        %                 sample_idx = randperm(size(X_norm, 1), n_samples);
        %                 X_sample = X_norm(sample_idx, :);
        %                 y_pred_sample = y_pred(sample_idx);
        %
        %                 sensitivities = zeros(n_samples, n_features);
        %
        %                 for f = 1:n_features
        %                     X_perturbed = X_sample;
        %                     X_perturbed(:, f) = X_perturbed(:, f) + 1;
        %
        %                     y_perturbed_norm = mdl.net(X_perturbed')';
        %                     y_perturbed = y_perturbed_norm * mdl.y_range + mdl.y_min;
        %
        %                     sensitivities(:, f) = y_perturbed - y_pred_sample;
        %                 end
        %
        %                 % Expand to full size (all samples get same pattern)
        %                 full_sensitivities = zeros(size(X_norm, 1), n_features);
        %                 for f = 1:n_features
        %                     full_sensitivities(:, f) = mean(sensitivities(:, f));
        %                 end
        %
        %                 shap_results.shap_values = full_sensitivities;
        %                 shap_results.expected_value = baseline;
        %
        %                 fprintf('Sensitivity analysis completed for first point\n');
        %             else
        %                 shap_results.shap_values = [];
        %                 shap_results.expected_value = NaN;
        %             end
        %         else
        %             shap_results.shap_values = [];
        %             shap_results.expected_value = NaN;
        %         end
        %     end
        % end

        function shap_results = performSHAPanalysisRF2D(app, rf_models, X_train, y_train, feature_names, n_points)
            % Perform SHAP analysis for Random Forest models for ALL points

            fprintf('Performing feature importance analysis for Random Forest models for %d points...\n', n_points);

            shap_results = struct();
            shap_results.shap_values = cell(1, n_points);
            shap_results.feature_names = feature_names;
            shap_results.expected_value = zeros(1, n_points);
            shap_results.feature_importance = zeros(n_points, length(feature_names));
            shap_results.point_names = cell(1, n_points);

            % Loop through ALL points
            for point_idx = 1:n_points
                fprintf('Analyzing point %d/%d...\n', point_idx, n_points);

                if ~isempty(rf_models{point_idx})
                    % Get valid data for this point
                    valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train), 2);

                    if sum(valid_train) > 10
                        X_train_point = X_train(valid_train, :);
                        y_train_point = y_train(valid_train, point_idx);

                        % Get feature importance from TreeBagger
                        if isprop(rf_models{point_idx}, 'OOBPermutedPredictorDeltaError') && ...
                                ~isempty(rf_models{point_idx}.OOBPermutedPredictorDeltaError)

                            % Get feature importance values
                            feature_importance = rf_models{point_idx}.OOBPermutedPredictorDeltaError;

                            % Create SHAP-like structure
                            n_samples = size(X_train_point, 1);
                            shap_values_mat = repmat(feature_importance, n_samples, 1);

                            shap_results.shap_values{point_idx} = shap_values_mat;
                            shap_results.expected_value(point_idx) = mean(y_train_point);
                            shap_results.feature_importance(point_idx, :) = feature_importance;

                            % Store point identifier
                            shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);

                            % Display top 5 features for this point
                            [sorted_imp, sorted_idx] = sort(feature_importance, 'descend');
                            fprintf('  Point %d - Top 5 features:\n', point_idx);
                            for i = 1:min(5, length(sorted_idx))
                                fprintf('    %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_imp(i));
                            end
                        else
                            fprintf('  No feature importance available for point %d\n', point_idx);
                            % Create placeholder
                            n_samples = size(X_train_point, 1);
                            n_features = length(feature_names);
                            shap_results.shap_values{point_idx} = zeros(n_samples, n_features);
                            shap_results.expected_value(point_idx) = mean(y_train_point);
                            shap_results.feature_importance(point_idx, :) = zeros(1, n_features);
                            shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);
                        end
                    else
                        fprintf('  Insufficient training samples for point %d\n', point_idx);
                        shap_results.shap_values{point_idx} = [];
                        shap_results.expected_value(point_idx) = NaN;
                        shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                        shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);
                    end
                else
                    fprintf('  No Random Forest model for point %d\n', point_idx);
                    shap_results.shap_values{point_idx} = [];
                    shap_results.expected_value(point_idx) = NaN;
                    shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                    shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);
                end
            end

            % Calculate overall feature importance across all points
            if any(~isnan(shap_results.feature_importance(:)))
                overall_importance = mean(shap_results.feature_importance, 1, 'omitnan');
                [sorted_overall, sorted_idx] = sort(overall_importance, 'descend');

                fprintf('\n=== OVERALL TOP 5 FEATURES (across all points) ===\n');
                for i = 1:min(5, length(sorted_idx))
                    fprintf('  %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_overall(i));
                end
            end
        end

        function shap_results = performKernelSHAPanalysisANN2D(app, ann_models, X_train, y_train, feature_names, n_points)
            % Perform sensitivity analysis for Neural Network models for ALL points

            fprintf('Performing sensitivity analysis for Neural Network models for %d points...\n', n_points);

            shap_results = struct();
            shap_results.shap_values = cell(1, n_points);
            shap_results.feature_names = feature_names;
            shap_results.expected_value = zeros(1, n_points);
            shap_results.feature_importance = zeros(n_points, length(feature_names));
            shap_results.point_names = cell(1, n_points);

            % Loop through ALL points
            for point_idx = 1:n_points
                fprintf('Analyzing point %d/%d...\n', point_idx, n_points);

                if ~isempty(ann_models{point_idx}) && isstruct(ann_models{point_idx})
                    % Get model components
                    mdl = ann_models{point_idx};

                    % Get valid data for this point
                    valid_train = ~isnan(y_train(:, point_idx)) & ~any(isnan(X_train), 2);

                    if sum(valid_train) > 10
                        X_train_point = X_train(valid_train, :);
                        y_train_point = y_train(valid_train, point_idx);

                        % Normalize features
                        X_norm = (X_train_point - mdl.mu_X) ./ mdl.sigma_X;

                        % Get baseline predictions
                        y_pred_norm = mdl.net(X_norm')';
                        y_pred = y_pred_norm * mdl.y_range + mdl.y_min;
                        baseline = mean(y_pred);

                        % Calculate sensitivity by perturbing each feature
                        n_features = size(X_norm, 2);

                        % Use a subset of samples for efficiency (max 100)
                        n_samples_use = min(100, size(X_norm, 1));
                        sample_idx = randperm(size(X_norm, 1), n_samples_use);
                        X_sample = X_norm(sample_idx, :);
                        y_pred_sample = y_pred(sample_idx);

                        sensitivities = zeros(n_samples_use, n_features);

                        for f = 1:n_features
                            % Create perturbed input (increase by 1 standard deviation)
                            X_perturbed = X_sample;
                            X_perturbed(:, f) = X_perturbed(:, f) + 1; % Perturb by 1 std

                            % Get predictions with perturbed input
                            y_perturbed_norm = mdl.net(X_perturbed')';
                            y_perturbed = y_perturbed_norm * mdl.y_range + mdl.y_min;

                            % Calculate sensitivity (change in output)
                            sensitivities(:, f) = y_perturbed - y_pred_sample;
                        end

                        % Calculate mean absolute sensitivity for each feature
                        mean_abs_sens = mean(abs(sensitivities), 1);

                        % Expand sensitivities to full sample size (replicate pattern)
                        full_sensitivities = zeros(size(X_norm, 1), n_features);
                        for f = 1:n_features
                            full_sensitivities(:, f) = mean(sensitivities(:, f));
                        end

                        % Store results
                        shap_results.shap_values{point_idx} = full_sensitivities;
                        shap_results.expected_value(point_idx) = baseline;
                        shap_results.feature_importance(point_idx, :) = mean_abs_sens;
                        shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);

                        % Display top 5 features for this point
                        [sorted_sens, sorted_idx] = sort(mean_abs_sens, 'descend');
                        fprintf('  Point %d - Top 5 most sensitive features:\n', point_idx);
                        for i = 1:min(5, length(sorted_idx))
                            fprintf('    %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_sens(i));
                        end
                    else
                        fprintf('  Insufficient training samples for point %d\n', point_idx);
                        shap_results.shap_values{point_idx} = [];
                        shap_results.expected_value(point_idx) = NaN;
                        shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                        shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);
                    end
                else
                    fprintf('  No Neural Network model for point %d\n', point_idx);
                    shap_results.shap_values{point_idx} = [];
                    shap_results.expected_value(point_idx) = NaN;
                    shap_results.feature_importance(point_idx, :) = NaN(1, length(feature_names));
                    shap_results.point_names{point_idx} = sprintf('Point_%d', point_idx);
                end
            end

            % Calculate overall feature importance across all points
            if any(~isnan(shap_results.feature_importance(:)))
                overall_importance = mean(shap_results.feature_importance, 1, 'omitnan');
                [sorted_overall, sorted_idx] = sort(overall_importance, 'descend');

                fprintf('\n=== OVERALL TOP 5 FEATURES (across all points) ===\n');
                for i = 1:min(5, length(sorted_idx))
                    fprintf('  %d. %s: %.4f\n', i, feature_names{sorted_idx(i)}, sorted_overall(i));
                end
            end
        end

        function saveSHAPToExcel2D(app, shap_results, feature_names, point_names, reconstructionType, methodType)
            % Save SHAP analysis results to Excel

            if isempty(shap_results) || all(cellfun(@isempty, shap_results))
                fprintf('No SHAP results to save\n');
                return;
            end

            % Determine number of features from first non-empty result
            n_features = 0;
            for i = 1:length(shap_results)
                if ~isempty(shap_results{i})
                    n_features = length(shap_results{i});
                    break;
                end
            end

            if n_features == 0
                fprintf('No valid SHAP results found\n');
                return;
            end

            % Ensure feature_names matches n_features
            if length(feature_names) > n_features
                feature_names = feature_names(1:n_features);
            elseif length(feature_names) < n_features
                % Add generic names for extra features
                for i = (length(feature_names)+1):n_features
                    feature_names{end+1} = sprintf('Feature_%d', i);
                end
            end

            % Create SHAP data table
            shap_data = table();
            shap_data.Point = point_names';

            for f = 1:n_features
                % Extract SHAP values for this feature across all points
                shap_values = zeros(length(point_names), 1);
                for p = 1:length(point_names)
                    if ~isempty(shap_results{p}) && length(shap_results{p}) >= f
                        shap_values(p) = shap_results{p}(f);
                    else
                        shap_values(p) = NaN;
                    end
                end
                shap_data.(feature_names{f}) = shap_values;
            end

            % Create filename with method type
            if strcmp(methodType, 'RF')
                method_str = 'RF';
            elseif strcmp(methodType, 'ANN')
                method_str = 'ANN';
            else
                method_str = methodType;
            end

            if strcmp(reconstructionType, 'basin_average')
                filename = sprintf('SHAP_Analysis_Results_%s_BasinAverage.xlsx', method_str);
            else
                filename = sprintf('SHAP_Analysis_Results_%s_AllPoints.xlsx', method_str);
            end

            writetable(shap_data, filename);
            fprintf('SHAP analysis results saved to %s\n', filename);
        end
        % % Callback when the user selects a different plot type
        % function PlotTypeDropDownValueChanged(app, event)
        %     value = app.PlotTypeDropDown.Value;
        %     try
        %         switch value
        %             case 'Temporal'
        %                 plotTemporal(app);
        %             case 'Spatial'
        %                 plotSpatial(app);
        %         end
        %     catch ME
        %         uialert(app.UIFigure, ME.message, 'Plotting Error');
        %     end
        % end

        % Callback when the user checks/unchecks nodes in the tree
        % function validation1NodeChecked(app, event)
        %     % Replot based on current plot type
        %     try
        %         switch app.PlotTypeDropDown.Value
        %             case 'Temporal'
        %                 plotTemporal(app);
        %             case 'Spatial'
        %                 plotSpatial(app);
        %         end
        %     catch ME
        %         uialert(app.UIFigure, ME.message, 'Plotting Error');
        %     end
        % end

        function validation1Clicked(app)
            % Delete dynamic axes if exists
            if ~isempty(app.DynamicAxes) && isvalid(app.DynamicAxes)
                delete(app.DynamicAxes);
                %app.DynamicAxes = []; % Clear the reference
            end

            % Restore main axes to original position if we have it stored
            if ~isempty(app.MainAxesOriginalPosition)
                currentUnits = app.UIAxes.Units;
                app.UIAxes.Units = app.MainAxesOriginalUnits;
                app.UIAxes.Position = app.MainAxesOriginalPosition;
                app.UIAxes.Units = currentUnits;
            end
        end

        function createDynamicAxes(app)
            % Store original position ONLY if not already stored
            if isempty(app.MainAxesOriginalPosition)
                app.MainAxesOriginalPosition = app.UIAxes.Position;
                app.MainAxesOriginalUnits = app.UIAxes.Units;
            end

            % Ensure we have valid figure dimensions
            drawnow;

            % Convert to pixels for consistent calculations
            originalAxesUnits = app.UIAxes.Units;
            originalFigureUnits = app.UIFigure.Units;

            app.UIAxes.Units = 'pixels';
            app.UIFigure.Units = 'pixels';

            % Get current layout information
            figPos = app.UIFigure.Position;
            figWidth = figPos(3);
            currentMainPos = app.UIAxes.Position;

            % Calculate new positions
            padding = 15;
            availableSpace = figWidth - currentMainPos(1) - padding;

            % Split available space: 60% for main, 40% for dynamic
            mainWidth = round(availableSpace * 0.60);
            dynamicWidth = round(availableSpace * 0.40);

            % Enforce minimum sizes
            minMainWidth = 400;
            minDynamicWidth = 250;

            if mainWidth < minMainWidth
                mainWidth = minMainWidth;
                dynamicWidth = availableSpace - mainWidth - padding;
            end

            if dynamicWidth < minDynamicWidth
                dynamicWidth = minDynamicWidth;
                mainWidth = availableSpace - dynamicWidth - padding;
                if mainWidth < minMainWidth
                    mainWidth = minMainWidth;
                    totalNeeded = minMainWidth + minDynamicWidth + padding;
                    if availableSpace < totalNeeded
                        scale = availableSpace / totalNeeded;
                        mainWidth = round(minMainWidth * scale);
                        dynamicWidth = round(minDynamicWidth * scale);
                    end
                end
            end

            % Position main axes
            newMainPos = [currentMainPos(1), currentMainPos(2), mainWidth, currentMainPos(4)];
            app.UIAxes.Position = newMainPos;

            % Create or update dynamic axes
            xPos = currentMainPos(1) + mainWidth + padding;
            yPos = currentMainPos(2);
            axHeight = currentMainPos(4);

            if isempty(app.DynamicAxes) || ~isvalid(app.DynamicAxes)
                app.DynamicAxes = uiaxes(app.UIFigure);
            end

            app.DynamicAxes.Units = 'pixels';
            app.DynamicAxes.Position = [xPos, yPos, dynamicWidth, axHeight];
            app.DynamicAxes.Visible = 'on';
            app.DynamicAxes.Parent = app.UIAxes.Parent;

            % Configure dynamic axes appearance
            title(app.DynamicAxes, 'Validation Results');
            xlabel(app.DynamicAxes, 'Time');
            ylabel(app.DynamicAxes, 'Value');
            app.DynamicAxes.Box = 'on';

            % Restore original units
            app.UIAxes.Units = originalAxesUnits;
            app.UIFigure.Units = originalFigureUnits;
        end
        % end
        %
        % methods (Access = private)

        function plotPrecipitationTemporal(app)
            try
                % Use your existing precipitation data
                % Filter to 2002-2018 period
                time_mask = app.IMDTime >= datetime(2002,1,1) & app.IMDTime <= datetime(2018,12,31);
                precip_time = app.IMDTime(time_mask);

                % Calculate basin average and anomalies (you might want to precompute this)
                precip_masked= app.IMDData;
                precip_basin_avg = mean(precip_masked, 1, 'omitnan');
                precip_basin_avg = precip_basin_avg(time_mask);

                % Calculate anomalies relative to 2004-2009 baseline
                baseline_idx = precip_time >= datetime(2004,1,1) & precip_time <= datetime(2009,12,31);
                clim_mean = mean(precip_basin_avg(baseline_idx), 'omitnan');
                precip_anom = precip_basin_avg - clim_mean;

                % Separate positive and negative values
                pos_idx = precip_anom >= 0;
                neg_idx = precip_anom < 0;

                % Plot positive bars
                if any(pos_idx)
                    bar(app.UIAxes, precip_time(pos_idx), precip_anom(pos_idx), ...
                        'FaceColor', [0.627, 0.800, 0.910], ... % #a0cce8
                        'EdgeColor', 'none', ...
                        'BarWidth', 1, ...
                        'DisplayName', 'Precip. Anomaly (+)');
                end

                % Plot negative bars
                if any(neg_idx)
                    bar(app.UIAxes, precip_time(neg_idx), precip_anom(neg_idx), ...
                        'FaceColor', [0.949, 0.733, 0.733], ... % #F2BBBB
                        'EdgeColor', 'none', ...
                        'BarWidth', 1, ...
                        'DisplayName', 'Precip. Anomaly (–)');
                end

            catch ME
                warning(ME.identifier,'Could not plot precipitation: %s', ME.message);
            end
        end

        function plotGRACE_Temporal(app)
            try
                % Filter GRACE data to 2002-2018 period
                time_mask = app.JPLTWSTime >= datetime(2002,1,1) & app.JPLTWSTime <= datetime(2018,12,31);
                grace_time = app.JPLTWSTime(time_mask);

                % Calculate basin average (you might want to precompute this)
                grace_masked= app.JPLTWSData;
                grace_basin_avg = mean(grace_masked, 1, 'omitnan');
                grace_basin_avg = grace_basin_avg(time_mask);

                plot(app.UIAxes, grace_time, grace_basin_avg, ...
                    's--', 'Color', 'black', ...
                    'LineWidth', 2, ...
                    'MarkerSize', 6, ...
                    'DisplayName', 'GRACE TWSA');

            catch ME
                warning(ME.identifier,'Could not plot GRACE data: %s', ME.message);
            end
        end

        function plotCGWB_Temporal(app)
            try
                % Use your loaded CGWB data
                plot(app.UIAxes, app.CGWBTime, app.CGWBAvg, ...
                    'o--', 'Color', [0.620, 0.482, 0.710], ... % #9e7bb5
                    'LineWidth', 2, ...
                    'MarkerSize', 6, ...
                    'DisplayName', 'CGWB Groundwater Level');

            catch ME
                warning(ME.identifier,'Could not plot CGWB data: %s', ME.message);
            end
        end
        function pr_seasonal_anom = computeIMDSeasonalAnomalies(app, IMD_masked, IMD_datetime)
            % computeIMDSeasonalAnomalies
            % -------------------------------------------------------------------------
            % Compute basin-averaged, seasonal precipitation anomalies (2002-2018)
            % Optimized version with vectorized operations
            %
            % INPUTS:
            %   IMD_masked   - Precipitation data masked to basin (lat×lon×time or pts×time)
            %   IMD_datetime - datetime vector for the time dimension
            %
            % OUTPUT:
            %   pr_seasonal_anom - Table with columns: Date, Year, Season, Anomaly
            % -------------------------------------------------------------------------

            try
                % --- Step 1: Compute basin mean (vectorized) ---
                IMD_basin_mean = mean(IMD_masked, 1, 'omitnan');
                IMD_basin_mean = squeeze(IMD_basin_mean); % Remove singleton dimensions

                % --- Step 2: Select period (2002-2024) ---
                startDate = datetime(2002,1,1);
                endDate   = datetime(2024,12,31);
                selIdx = IMD_datetime >= startDate & IMD_datetime <= endDate;
                IMD_sel = IMD_basin_mean(selIdx);
                time_sel = IMD_datetime(selIdx);

                % --- Step 3: Climatology (2004-2009) ---
                climIdx = time_sel >= datetime(2004,1,1) & time_sel <= datetime(2009,12,31);
                clim_mean = mean(IMD_sel(climIdx), 'omitnan');

                % --- Step 4: Anomalies ---
                IMD_anom = IMD_sel - clim_mean;

                % --- Step 5: Shift December to next year's January (vectorized) ---
                time_shifted = time_sel;
                dec_months = month(time_sel) == 12;
                time_shifted(dec_months) = dateshift(time_sel(dec_months), 'start', 'month', 1);

                % --- Step 6: Map to Seasons (vectorized) ---
                months = month(time_shifted);
                seasons = strings(size(months));

                % Vectorized season assignment
                seasons(ismember(months, [1, 12])) = "JAN";
                seasons(ismember(months, [2, 3, 4, 5])) = "MAY";
                seasons(ismember(months, [6, 7, 8])) = "AUG";
                seasons(ismember(months, [9, 10, 11])) = "NOV";

                years = year(time_shifted);

                % --- Step 7: Group by Year + Season ---
                T = table(years(:), seasons(:), IMD_anom(:), time_shifted(:), ...
                    'VariableNames', {'Year','Season','Anomaly','Time'});
                seasonalSummary = groupsummary(T, {'Year','Season'}, 'mean', 'Anomaly');

                % --- Step 8: Create chronological season date (vectorized) ---
                season_map = containers.Map({'JAN','MAY','AUG','NOV'}, [1, 5, 8, 11]);
                months_vector = zeros(height(seasonalSummary), 1);

                for i = 1:height(seasonalSummary)
                    months_vector(i) = season_map(seasonalSummary.Season{i});
                end

                seasonalSummary.Date = datetime(seasonalSummary.Year, months_vector, 1);

                % --- Step 9: Sort chronologically ---
                seasonalSummary = sortrows(seasonalSummary, 'Date');
                seasonalSummary.Date.Format = 'MM-yyyy';

                % --- Step 10: Final output table ---
                pr_seasonal_anom = table(seasonalSummary.Date, seasonalSummary.Year, ...
                    seasonalSummary.Season, seasonalSummary.mean_Anomaly, ...
                    'VariableNames', {'Date','Year','Season','Anomaly'});

            catch ME
                warning(ME.identifier,'Error in computeIMDSeasonalAnomalies: %s', ME.message);
                % Return empty table on error
                pr_seasonal_anom = table('Size', [0, 4], ...
                    'VariableTypes', {'datetime', 'double', 'string', 'double'}, ...
                    'VariableNames', {'Date', 'Year', 'Season', 'Anomaly'});
            end
        end


        function plotGRACE_Spatial(app)
            try
                grace_masked = app.JPLTWSData;
                grace_lat= app.JPLTWSLat;
                grace_lon = app.JPLTWSLon;
                % Take temporal mean for spatial pattern (2002-2018 period)
                time_mask = app.JPLTWSTime >= datetime(2002,1,1) & app.JPLTWSTime <= datetime(2018,12,31);
                grace_spatial = mean(grace_masked(:, time_mask), 2, 'omitnan');

                % Create spatial grid
                [unique_lat, ~, lat_idx] = unique(grace_lat);
                [unique_lon, ~, lon_idx] = unique(grace_lon);
                spatial_grid = NaN(length(unique_lat), length(unique_lon));

                for i = 1:length(grace_lat)
                    spatial_grid(lat_idx(i), lon_idx(i)) = grace_spatial(i);
                end

                % Plot spatial data
                imagesc(app.UIAxes, unique_lon, unique_lat, spatial_grid);
                colorbar(app.UIAxes);
                title(app.UIAxes, 'GRACE TWSA Spatial Distribution');

                % Add basin boundary
                hold(app.UIAxes, 'on');
                for i = 1:length(app.Basins)
                    if strcmpi(app.Basins(i).RIVER_BASI, 'GODAVARI')
                        plot(app.UIAxes, app.Basins(i).X, app.Basins(i).Y, ...
                            'k-', 'LineWidth', 2, 'DisplayName', 'Basin Boundary');
                    end
                end
                hold(app.UIAxes, 'off');

                axis(app.UIAxes, 'equal');
                xlabel(app.UIAxes, 'Longitude');
                ylabel(app.UIAxes, 'Latitude');

            catch ME
                uialert(app.UIFigure, sprintf('Error plotting GRACE spatial: %s', ME.message), 'Plot Error');
            end
        end

        function plotCGWB_Spatial(app)
            try
                % Check if CGWB data has coordinates
                if ~any(contains(app.CGWBData.Properties.VariableNames, {'Latitude', 'Longitude', 'LAT', 'LON', 'lat', 'lon'}))
                    % If no coordinates, show message
                    text(app.UIAxes, 0.5, 0.5, 'CGWB coordinates not available', ...
                        'HorizontalAlignment', 'center', 'Units', 'normalized', ...
                        'FontSize', 14);
                    title(app.UIAxes, 'CGWB Data - Coordinates Required for Spatial Plot');
                    return;
                end

                % Extract coordinates
                if ismember('Latitude', app.CGWBData.Properties.VariableNames)
                    well_lats = app.CGWBData.Latitude;
                    well_lons = app.CGWBData.Longitude;
                elseif ismember('LAT', app.CGWBData.Properties.VariableNames)
                    well_lats = app.CGWBData.LAT;
                    well_lons = app.CGWBData.LON;
                else
                    % Try to find coordinate columns by pattern matching
                    lat_col = find(contains(app.CGWBData.Properties.VariableNames, {'lat', 'Lat'}, 'IgnoreCase', true), 1);
                    lon_col = find(contains(app.CGWBData.Properties.VariableNames, {'lon', 'Lon'}, 'IgnoreCase', true), 1);
                    if ~isempty(lat_col) && ~isempty(lon_col)
                        well_lats = app.CGWBData{:, lat_col};
                        well_lons = app.CGWBData{:, lon_col};
                    else
                        msgbox('Could not find coordinate columns in CGWB data');
                    end
                end

                % Plot well locations
                scatter(app.UIAxes, well_lons, well_lats, 50, 'filled', ...
                    'MarkerFaceColor', [0.620, 0.482, 0.710], ... % #9e7bb5
                    'MarkerEdgeColor', 'k', ...
                    'DisplayName', 'CGWB Wells');

                % Add basin boundary
                hold(app.UIAxes, 'on');
                for i = 1:length(app.Basins)
                    if strcmpi(app.Basins(i).RIVER_BASI, 'GODAVARI')
                        plot(app.UIAxes, app.Basins(i).X, app.Basins(i).Y, ...
                            'k-', 'LineWidth', 2, 'DisplayName', 'Basin Boundary');
                    end
                end
                hold(app.UIAxes, 'off');

                title(app.UIAxes, 'CGWB Well Locations');
                axis(app.UIAxes, 'equal');
                xlabel(app.UIAxes, 'Longitude');
                ylabel(app.UIAxes, 'Latitude');
                legend(app.UIAxes, 'show', 'Location', 'best');

            catch ME
                uialert(app.UIFigure, sprintf('Error plotting CGWB spatial: %s', ME.message), 'Plot Error');
            end
        end

        function plotSpatialSideBySide(app)
            % Make both axes visible and adjust positions
            app.UIAxes2.Visible = 'on';
            app.UIAxes.Position = [0.08 0.25 0.4 0.65];
            app.UIAxes2.Position = [0.54 0.25 0.4 0.65];

            % Plot GRACE on left axes
            app.plotGRACE_Spatial();
            title(app.UIAxes, 'GRACE TWSA Spatial Distribution');

            % Plot CGWB on right axes
            cla(app.UIAxes2);
            app.plotCGWB_Spatial();
            title(app.UIAxes2, 'CGWB Well Locations');
        end

        function formatTemporalPlot(app)
            xlabel(app.UIAxes, 'Time', 'FontSize', 14, 'FontWeight', 'bold');
            ylabel(app.UIAxes, 'Anomaly (mm)', 'FontSize', 14, 'FontWeight', 'bold');

            % Set x-axis limits to match CGWB data period
            xlim(app.UIAxes, [datetime(2002,1,1), datetime(2018,12,31)]);

            % Yearly ticks
            years = 2002:2018;
            xticks(app.UIAxes, datetime(years, 1, 1));
            xticklabels(app.UIAxes, arrayfun(@num2str, years, 'UniformOutput', false));

            grid(app.UIAxes, 'on');
            legend(app.UIAxes, 'show', 'Location', 'best');
            title(app.UIAxes, 'Temporal Analysis: GRACE vs CGWB vs Precipitation');
        end
        % Helper function to safely test if a field exists and is valid
        function tf = isvalidField(app, propName)
            tf = isprop(app, propName) && ~isempty(app.(propName)) && isvalid(app.(propName));
        end
        function plotOnDynamicAxes(app, checkedTexts)
            if isempty(app.DynamicAxes) || ~isvalid(app.DynamicAxes)
                return;
            end

            % Clear previous plots
            cla(app.DynamicAxes);
            hold(app.DynamicAxes, 'on');

            colors = lines(3); % Get different colors for each dataset
            plotType = app.PlotTypeDropDown.Value;

            hasPlots = false;

            % Plot CGWB data
            if any(strcmp(checkedTexts, 'CGWB'))
                hasPlots = plotCGWBData(app, plotType) || hasPlots;
            end

            % Plot GRACE data
            if any(strcmp(checkedTexts, 'GRACE'))
                hasPlots = plotGRACEData(app, plotType) || hasPlots;
            end

            % Plot Precipitation data
            if any(strcmp(checkedTexts, 'Precipitation'))
                hasPlots = plotPrecipitationData(app, plotType) || hasPlots;
            end

            hold(app.DynamicAxes, 'off');

            % Add legend only if we have plots
            if hasPlots
                legend(app.DynamicAxes, 'show', 'Location', 'best');
            else
                legend(app.DynamicAxes, 'off');
            end
        end

        function success = plotCGWBData(app, plotType)
            success = false;
            selectedBasin = app.BasinSelectionButton.Value;
            try
                if strcmpi(selectedBasin,'Godavari')
                    switch plotType
                        case 'Temporal'
                            if ~isempty(app.CGWBTime) && ~isempty(app.CGWBAvg) && ...
                                    length(app.CGWBTime) == length(app.CGWBAvg)
                                plot(app.DynamicAxes, app.CGWBTime, app.CGWBAvg, ...
                                    'Color', [0.62, 0.482, 0.71], 'DisplayName', 'GWSA CGWB', 'LineWidth', 1.5);
                                success = true;
                            end
                        case 'Spatial'
                            if ~isempty(app.CGWBAnomalies) && ...
                                    ~isempty(app.CGWBLat) && ...
                                    ~isempty(app.CGWBLon) && ...
                                    ~isempty(app.CGWBTime)

                                % === Calculate trends ===
                                numPoints = size(app.CGWBAnomalies, 1);
                                trendValues = zeros(numPoints, 1);

                                if isdatetime(app.CGWBTime)
                                    timeNum = datenum(app.CGWBTime);
                                else
                                    timeNum = app.CGWBTime;
                                end
                                timeNum = timeNum - min(timeNum);

                                for i = 1:numPoints
                                    y = squeeze(app.CGWBAnomalies(i, :));
                                    validIdx = ~isnan(y);
                                    if sum(validIdx) > 1
                                        p = polyfit(timeNum(validIdx), y(validIdx), 1);
                                        trendValues(i) = p(1);
                                    else
                                        trendValues(i) = NaN;
                                    end
                                end

                                % === Plot trend points ===
                                scatter(app.DynamicAxes, app.CGWBLon, app.CGWBLat, 40, trendValues, ...
                                    'filled', 'MarkerEdgeColor', 'k', 'LineWidth', 0.5);

                                % Colormap & colorbar
                                redBlueMap = [linspace(1,0,64)' zeros(64,1) linspace(0,1,64)'];
                                colormap(app.DynamicAxes, redBlueMap);
                                c = colorbar(app.DynamicAxes, 'southoutside');
                                c.Label.String = 'GWSA CGWB (mm/year)';

                                maxAbs = max(abs(trendValues), [], 'omitnan');
                                if maxAbs > 0
                                    caxis(app.DynamicAxes, [-maxAbs, maxAbs]);
                                end

                                % === Retrieve and plot basin polygon ===
                                selectedBasin = app.BasinSelectionButton.Value;
                                basins = app.Basins;

                                basinPolygon = [];
                                for i = 1:length(basins)
                                    if strcmpi(basins(i).RIVER_BASI, selectedBasin)
                                        basinPolygon = [basins(i).X', basins(i).Y'];
                                        break;
                                    end
                                end

                                % Clean polygon
                                basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);

                                % === Plot polygon ===
                                hold(app.DynamicAxes, 'on');

                                if ~isempty(basinPolygon)
                                    plot(app.DynamicAxes, basinPolygon(:,1), basinPolygon(:,2), ...
                                        'k-', 'LineWidth', 2, 'DisplayName', selectedBasin);
                                else
                                    warning('No polygon found for basin: %s', selectedBasin);
                                end

                                % Optional: also check alternate boundary source
                                if isprop(app, 'BasinBoundaries') && ~isempty(app.BasinBoundaries)
                                    basinIdx = strcmp({app.BasinBoundaries.Name}, selectedBasin);
                                    if any(basinIdx)
                                        basinData = app.BasinBoundaries(basinIdx);
                                        plot(app.DynamicAxes, basinData.Lon, basinData.Lat, ...
                                            'k-', 'LineWidth', 2, 'DisplayName', [selectedBasin ' (Boundary)']);
                                    end
                                end

                                hold(app.DynamicAxes, 'off');

                                % === Final formatting ===
                                xlabel(app.DynamicAxes, 'Longitude');
                                ylabel(app.DynamicAxes, 'Latitude');
                                title(app.DynamicAxes, 'CGWB Spatial Trends');
                                grid(app.DynamicAxes, 'on');
                                axis(app.DynamicAxes, 'equal');

                                % Force axes to show both scatter & polygon
                                axis(app.DynamicAxes, 'tight');

                                % Add legend only if polygon exists
                                if exist('basinPolygon','var') && ~isempty(basinPolygon)
                                    legend(app.DynamicAxes, 'show', 'Location', 'best');
                                end

                                success = true;

                            else
                                warning('Spatial data for CGWB is incomplete (missing anomalies, coordinates, or time).');
                            end
                    end
                else
                    uialert(app.UIFigure, ...
                        sprintf('CGWB data is not available for:\n%s', selectedBasin), ...
                        'Basin', 'Icon', 'info');

                end

            catch ME
                warning(ME.identifier,'Failed to plot CGWB data: %s', ME.message);
            end
        end

        function success = plotGRACEData(app, plotType)
            success = false;
            try
                JPL_masked = app.JPLTWSData;
                latJPL=app.JPLTWSLat;
                lonJPL = app.JPLTWSLon;
                GLDASSM_masked= app.GLDAS_SMData;
                latGLDAS = app.GLDAS_SMLat;
                lonGLDAS = app.GLDAS_SMLon;

                % --- Compute GLDAS anomalies ---
                climYears = 2004:2009;
                climIdx = ismember(year(app.GLDAS_datetime), climYears);
                climMean = mean(GLDASSM_masked(:, climIdx), 2, 'omitnan');
                GLDASSM_anom = GLDASSM_masked - climMean;

                % --- Get datetime arrays ---
                JPL_time = dateshift(app.JPL_datetime, 'start', 'month');
                GLDAS_time = dateshift(app.GLDAS_datetime, 'start', 'month');

                % Remove duplicate months from JPL_time
                [JPL_time_unique, uniqueIdx] = unique(JPL_time, 'stable');
                JPL_masked = JPL_masked(:, uniqueIdx);
                JPL_time = JPL_time_unique;

                % --- Find overlap period ---
                startDate = max([min(JPL_time), min(GLDAS_time)]);
                endDate = min([max(JPL_time), max(GLDAS_time)]);

                % --- Create continuous common time vector ---
                common_time = (dateshift(startDate, 'start', 'month') : calmonths(1) : dateshift(endDate, 'start', 'month'))';

                % --- Initialize matrices with NaN ---
                JPL_full = nan(size(JPL_masked,1), length(common_time));
                GLDAS_full = nan(size(GLDASSM_anom,1), length(common_time));

                % --- Map existing data into common_time ---
                [~, ia_jpl, ib_jpl] = intersect(common_time, JPL_time);
                JPL_full(:, ia_jpl) = JPL_masked(:, ib_jpl);

                [~, ia_gldas, ib_gldas] = intersect(common_time, GLDAS_time);
                GLDAS_full(:, ia_gldas) = GLDASSM_anom(:, ib_gldas);

                % --- Compute spatial mean ---
                JPL_mean = mean(JPL_full, 1, 'omitnan');
                GLDAS_mean = mean(GLDAS_full, 1, 'omitnan');

                switch plotType
                    case 'Temporal'
                        GWSA = JPL_mean*10 - GLDAS_mean;

                        % --- Select representative months (Jan, May, Aug, Nov) ---
                        targetMonths = [1, 5, 8, 11];
                        selIdx = ismember(month(common_time), targetMonths);

                        % Filter time and GWSA data
                        common_time_filtered = common_time(selIdx);
                        GWSA_filtered = GWSA(selIdx);

                        % --- Plot filtered GWSA ---
                        plot(app.DynamicAxes, common_time_filtered, GWSA_filtered, ...
                            'Color', 'black', 'DisplayName', 'GWSA GRACE', 'LineWidth', 1.5, 'LineStyle', '--');
                        success = true;

                    case 'Spatial'
                        GWSA = JPL_full*10 - GLDAS_full;
                        nPoints = size(GWSA, 1);
                        trend = nan(nPoints, 1);

                        for p = 1:nPoints
                            y = GWSA(p, :);
                            t = (1:length(y))';
                            validIdx = ~isnan(y);
                            if sum(validIdx) >= 2
                                coeffs = polyfit(t(validIdx), y(validIdx), 1);
                                trend(p) = coeffs(1);
                            else
                                trend(p) = NaN;
                            end
                        end

                        % Retrieve the selected basin
                        selectedBasin = app.BasinSelectionButton.Value;
                        basins = app.Basins;

                        % Find the polygon corresponding to the selected basin
                        basinPolygon = [];
                        for i = 1:length(basins)
                            if strcmpi(basins(i).RIVER_BASI, selectedBasin)
                                basinPolygon = [basins(i).X', basins(i).Y'];
                                break;
                            end
                        end

                        % Clean polygon (remove NaNs)
                        basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);

                        % Call your spatial plotting function
                        plotSpatialBasinMapDynamic(app, lonJPL, latJPL, trend, basinPolygon, 'GWSA GRACE');
                        success = true;
                end
            catch ME
                warning(ME.identifier,'Failed to plot GRACE data: %s', ME.message);
            end
        end

        function success = plotPrecipitationData(app, plotType)
            success = false;
            try
                switch plotType
                    case 'Temporal'
                        % --- IMD file path ---
                        IMDFile = "./Data/Precipitation/IMD/IMD_Precipitation_1901_2023_Godavari.nc";


                        IMD_masked= app.IMDData;
                        latIMD= app.IMDLat;
                        lonIMD = app.IMDLon;

                        pr_seasonal_anom = computeIMDSeasonalAnomalies(app, IMD_masked, app.IMD_datetime);

                        % Make sure the target axes is active
                        axes(app.DynamicAxes);
                        hold(app.DynamicAxes, 'on');

                        xVals = pr_seasonal_anom.Date;
                        yVals = pr_seasonal_anom.Anomaly;

                        % Plot bars with different colors for positive and negative anomalies
                        for i = 1:length(xVals)
                            if yVals(i) >= 0
                                bar(app.DynamicAxes, xVals(i), yVals(i), ...
                                    'FaceColor', [0.627, 0.80, 0.91], 'EdgeColor', 'none', 'BarWidth', 75);
                            else
                                bar(app.DynamicAxes, xVals(i), yVals(i), ...
                                    'FaceColor', [0.949, 0.733, 0.733], 'EdgeColor', 'none', 'BarWidth', 75);
                            end
                        end

                        % Add zero reference line
                        yline(app.DynamicAxes, 0, 'k--', 'LineWidth', 1);

                        % Format axes for datetime
                        datetick(app.DynamicAxes, 'x', 'mmm-yyyy', 'keeplimits');
                        set(app.DynamicAxes, 'XTickLabelRotation', 45);
                        grid(app.DynamicAxes, 'on');

                        % Create legend manually
                        hPos = bar(app.DynamicAxes, NaT, 0, 'FaceColor', [0.627, 0.80, 0.91], 'EdgeColor', 'none', 'Visible', 'off');
                        hNeg = bar(app.DynamicAxes, NaT, 0, 'FaceColor', [0.949, 0.733, 0.733], 'EdgeColor', 'none', 'Visible', 'off');
                        legend(app.DynamicAxes, [hPos, hNeg], {'Positive', 'Negative'}, 'Location', 'best');

                        hold(app.DynamicAxes, 'off');
                        success = true;

                    case 'Spatial'
                        uialert(app.UIFigure, 'Please use other precipitation option', "Invalid Selection", "Icon", "error");
                end
            catch ME
                warning(ME.identifier,'Failed to plot Precipitation data: %s', ME.message);
            end
        end

        function readAndPlotSensitivityAnalysis(app, analysisType, methodType)
            % Read Excel file and create tornado plot for sensitivity analysis
            % based on the analysis type and method type

            try
                selectedBasin = app.BasinSelectionButton.Value;
                % Determine the file path based on analysis type and method type
                if strcmpi(methodType, 'RF')
                    method_str = 'RF';
                elseif strcmpi(methodType, 'ANN') || strcmpi(methodType, 'ann')
                    method_str = 'ANN';
                else
                    method_str = upper(methodType);
                end

                % Construct filename based on analysis type and method
                if strcmp(analysisType, 'basin_average')
                    filename = sprintf('./Feature_Importance_basin_average_%s_%s.xlsx', method_str, selectedBasin);
                    plotTitle = sprintf('Sensitivity Analysis - Basin Average (%s)', method_str);
                    plotType = 'basin_average';
                else
                    filename = sprintf('./Feature_Importance_all_points_%s_%s.xlsx', method_str, selectedBasin);
                    plotTitle = sprintf('Sensitivity Analysis - All Points (%s)', method_str);
                    plotType = 'all_points';
                end

                % Check if file exists
                if ~isfile(filename)
                    error('Sensitivity analysis file not found: %s', filename);
                end

                % Read the Feature_Importance sheet from Excel file
                % =================================================
                % First, get the sheet names to check if 'Feature_Importance' exists
                [~, sheetNames] = xlsfinfo(filename);

                if ~any(strcmp(sheetNames, 'Feature_Importance'))
                    error('Feature_Importance sheet not found in file: %s', filename);
                end

                % Read the Feature_Importance sheet
                % Use detectImportOptions to better handle the data structure
                opts = detectImportOptions(filename, 'Sheet', 'Feature_Importance');

                % Read the table from the Feature_Importance sheet
                data = readtable(filename, opts);

                % Display the column names for debugging
                fprintf('Available columns in Feature_Importance sheet:\n');
                disp(data.Properties.VariableNames);

                % Check if we have the expected columns
                if ~any(strcmp(data.Properties.VariableNames, 'Point')) || ...
                        ~any(strcmp(data.Properties.VariableNames, 'Expected_Value'))
                    warning('Expected columns not found. Using first column as Point names.');
                end
                % =================================================

                % Process the data based on analysis type
                if strcmp(plotType, 'basin_average')
                    % For basin_average: Filter out Overall_Average and get basin data
                    if any(strcmp(data.Properties.VariableNames, 'Point'))
                        % Find Basin_Average row
                        basinRowIdx = strcmp(data.Point, 'Basin_Average');
                        overallRowIdx = strcmp(data.Point, 'Overall_Average');

                        if any(basinRowIdx)
                            % Extract Basin_Average data
                            pointNames = {'Basin_Average'};
                            featureData = data{basinRowIdx, 2:end-1}; % Exclude Point and Expected_Value
                            expectedValue = data{basinRowIdx, 'Expected_Value'};

                            % Get feature names (exclude Point and Expected_Value)
                            allVars = data.Properties.VariableNames;
                            featureNames = allVars(2:end-1); % Exclude first (Point) and last (Expected_Value)
                        else
                            error('Basin_Average row not found in Feature_Importance sheet');
                        end
                    else
                        % Fallback: use first row as basin data
                        pointNames = {'Basin_Average'};
                        featureData = data{1, 1:end-1}; % First row, all columns except last
                        expectedValue = data{1, end}; % Last column is Expected_Value
                        featureNames = data.Properties.VariableNames(1:end-1);
                    end

                else
                    % For all_points: Filter out Overall_Average
                    if any(strcmp(data.Properties.VariableNames, 'Point'))
                        % Filter out Overall_Average
                        validRows = ~strcmp(data.Point, 'Overall_Average');
                        pointNames = data.Point(validRows);

                        % Extract feature data (all columns except Point and Expected_Value)
                        featureColumns = ~strcmp(data.Properties.VariableNames, 'Point') & ...
                            ~strcmp(data.Properties.VariableNames, 'Expected_Value');
                        featureData = data{validRows, featureColumns};

                        % Get expected values
                        expectedValues = data{validRows, 'Expected_Value'};

                        % Get feature names
                        allVars = data.Properties.VariableNames;
                        featureNames = allVars(featureColumns);
                    else
                        % Fallback: use all rows except last (Overall_Average)
                        % and last column is Expected_Value
                        validRows = 1:height(data)-1; % Exclude last row (Overall_Average)
                        pointNames = arrayfun(@(x) sprintf('Point_%d', x), 1:length(validRows), 'UniformOutput', false)';
                        featureData = data{validRows, 1:end-1}; % All columns except last
                        expectedValues = data{validRows, end}; % Last column
                        featureNames = data.Properties.VariableNames(1:end-1);
                    end
                end

                % Ensure featureData is numeric
                if ~isnumeric(featureData)
                    featureData = table2array(featureData);
                end

                % Display data summary for debugging
                fprintf('\nData Summary for %s analysis:\n', plotType);
                fprintf('Number of points: %d\n', length(pointNames));
                fprintf('Number of features: %d\n', length(featureNames));
                fprintf('Feature data size: %d x %d\n', size(featureData, 1), size(featureData, 2));
                fprintf('First few feature names:\n');
                disp(featureNames(1:min(5, length(featureNames))));

                % Store data in app for plotting
                app.SensitivityData = struct();
                app.SensitivityData.pointNames = pointNames;
                app.SensitivityData.featureData = featureData;
                app.SensitivityData.featureNames = featureNames;
                app.SensitivityData.plotTitle = plotTitle;
                app.SensitivityData.plotType = plotType;

                if strcmp(plotType, 'basin_average')
                    app.SensitivityData.expectedValue = expectedValue;
                else
                    app.SensitivityData.expectedValues = expectedValues;
                end
                app.SensitivityData.currentPointIndex = 1;
                app.SensitivityData.totalPoints = length(pointNames);

                % Create appropriate plot based on analysis type
                if strcmp(plotType, 'basin_average')
                    % Create tornado plot directly
                    createTornadoPlot(app);
                else
                    % Create interactive plot with navigation controls
                    createInteractiveTornadoPlot(app);
                end

                fprintf('Sensitivity analysis plot created for: %s (%s)\n', analysisType, method_str);

            catch ME
                errorMsg = sprintf('Error reading sensitivity analysis file: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'File Read Error');
                rethrow(ME);
            end
        end

        function createTornadoPlot(app)
            % Create tornado plot for single point (basin_average case) with enhanced visualization

            try
                data = app.SensitivityData;

                % Create a new figure
                fig = figure('Name', data.plotTitle, ...
                    'NumberTitle', 'off', ...
                    'Position', [100, 50, 1200, 700]);

                % For single point, use the feature data directly
                featureValues = data.featureData(1, :); % Single row for basin_average

                % Calculate absolute values for sorting
                absValues = abs(featureValues);

                % Sort features by absolute value (descending)
                [sortedAbsValues, sortIdx] = sort(absValues, 'descend');
                sortedValues = featureValues(sortIdx);
                sortedFeatureNames = data.featureNames(sortIdx);

                % Clean feature names (remove underscores and capitalize)
                cleanFeatureNames = cell(size(sortedFeatureNames));
                for i = 1:length(sortedFeatureNames)
                    name = sortedFeatureNames{i};
                    % Replace underscores with spaces
                    name = strrep(name, '_', ' ');
                    % Capitalize first letter of each word
                    nameParts = split(name);
                    for j = 1:length(nameParts)
                        if ~isempty(nameParts{j})
                            nameParts{j} = [upper(nameParts{j}(1)), lower(nameParts{j}(2:end))];
                        end
                    end
                    cleanFeatureNames{i} = strjoin(nameParts);
                end

                % Create main tornado plot (full figure)
                ax = axes('Parent', fig, 'Position', [0.15, 0.15, 0.75, 0.75]);

                % Determine how many features to show (top 20 or all if less)
                if length(sortedValues) > 20
                    topN = 20;
                    barhValues = sortedValues(1:topN);
                    barhFeatures = cleanFeatureNames(1:topN);
                    y = 1:topN;
                else
                    barhValues = sortedValues;
                    barhFeatures = cleanFeatureNames;
                    topN = length(sortedValues);
                    y = 1:topN;
                end

                % Create gradient colors from deep to light (blue gradient)
                colors = zeros(length(barhValues), 3);

                % Calculate normalized importance (0 to 1)
                if max(sortedAbsValues) > 0
                    normValues = sortedAbsValues(1:topN) / max(sortedAbsValues(1:topN));
                else
                    normValues = ones(1, topN);
                end

                % Create blue gradient (darker for higher importance)
                for i = 1:length(barhValues)
                    if barhValues(i) >= 0
                        % Blue gradient: darker blue for higher importance
                        intensity = 0.2 + 0.6 * normValues(i);  % 0.2 to 0.8
                        colors(i, :) = [0, 0.4 * intensity, intensity];
                    else
                        % For negative values
                        colors(i, :) = [0.8, 0.2, 0.2];
                    end
                end

                % Create horizontal bars (SHAP-style, positive only)
                positiveIdx = barhValues > 0;
                if any(positiveIdx)
                    posY = y(positiveIdx);
                    posValues = barhValues(positiveIdx);
                    posColors = colors(positiveIdx, :);
                    posFeatures = barhFeatures(positiveIdx);

                    h = barh(ax, posY, posValues);
                    h.FaceColor = 'flat';
                    h.CData = posColors;
                    h.EdgeColor = [0.2, 0.2, 0.4];
                    h.LineWidth = 0.8;

                    % Update y labels for positive features only
                    set(ax, 'YTick', posY, 'YTickLabel', posFeatures);
                else
                    h = barh(ax, y, barhValues);
                    h.FaceColor = 'flat';
                    h.CData = colors;
                    h.EdgeColor = [0.2, 0.2, 0.4];
                    h.LineWidth = 0.8;

                    set(ax, 'YTick', y, 'YTickLabel', barhFeatures);
                end

                ylabel(ax, 'Features', 'FontSize', 11, 'FontWeight', 'bold');
                xlabel(ax, 'Feature Importance Value', 'FontSize', 11, 'FontWeight', 'bold');
                % title(ax, sprintf('Tornado Plot - %s', data.pointNames{1}), ...
                %     'FontSize', 12, 'FontWeight', 'bold');

                % Set Y-axis properties
                set(ax, 'YDir', 'reverse'); % Highest values at top
                set(ax, 'FontSize', 10);
                grid(ax, 'on');
                grid(ax, 'minor');

                % Set x-axis to start at 0 (no negative part)
                xlim(ax, [0, max(barhValues) * 1.15]);

                % Add value labels
                for i = 1:length(posY)
                    value = posValues(i);
                    textPos = value + max(posValues) * 0.005;
                    text(ax, textPos, posY(i), sprintf('%.4f', value), ...
                        'FontSize', 9, ...
                        'VerticalAlignment', 'middle', ...
                        'HorizontalAlignment', 'left', ...
                        'BackgroundColor', [1, 1, 1, 0.7], ...
                        'EdgeColor', 'none', ...
                        'Margin', 1, ...
                        'FontWeight', 'bold');
                end

                % Beautify the axes
                ax.Box = 'on';
                ax.LineWidth = 1.5;
                ax.TickLength = [0.01, 0.01];
                ax.XGrid = 'on';
                ax.GridAlpha = 0.3;
                ax.GridLineStyle = '--';

                % % Add color bar
                % if any(positiveIdx)
                %     colormap(ax, winter(256));
                %     c = colorbar(ax, 'Location', 'eastoutside');
                %     c.Label.String = 'Relative Importance';
                %     c.Label.FontSize = 10;
                %     c.Label.FontWeight = 'bold';
                %     c.Ticks = [0, 0.25, 0.5, 0.75, 1];
                %     c.TickLabels = {'Low', '', 'Medium', '', 'High'};
                % end

                % % Add statistics panel on the right
                % statsPanel = uipanel('Parent', fig, ...
                %     'Title', 'Statistics', ...
                %     'Position', [0.75, 0.15, 0.22, 0.75], ...
                %     'FontSize', 11, ...
                %     'FontWeight', 'bold', ...
                %     'BackgroundColor', [0.95, 0.95, 0.95]);
                %
                % % Prepare statistics text
                % statsText = {
                %     sprintf('Point: %s', data.pointNames{1});
                %     sprintf('Expected Value: %.4f', data.expectedValue);
                %     sprintf('Total Features: %d', length(data.featureNames));
                %     '';
                %     'Most Important Feature:';
                %     sprintf('  %s: %.4f', cleanFeatureNames{1}, sortedValues(1));
                %     '';
                %     'Top 5 Features:';
                %     sprintf('  1. %s: %.4f', cleanFeatureNames{1}, sortedValues(1));
                %     sprintf('  2. %s: %.4f', cleanFeatureNames{2}, sortedValues(2));
                %     sprintf('  3. %s: %.4f', cleanFeatureNames{3}, sortedValues(3));
                %     sprintf('  4. %s: %.4f', cleanFeatureNames{4}, sortedValues(4));
                %     sprintf('  5. %s: %.4f', cleanFeatureNames{5}, sortedValues(5));
                %     '';
                %     'Summary Statistics:';
                %     sprintf('  Average: %.4f', mean(featureValues));
                %     sprintf('  Maximum: %.4f', max(featureValues));
                %     sprintf('  Minimum: %.4f', min(featureValues));
                %     sprintf('  Std Dev: %.4f', std(featureValues));
                % };
                %
                % uicontrol('Parent', statsPanel, ...
                %     'Style', 'text', ...
                %     'String', statsText, ...
                %     'Position', [10, 10, 200, 500], ...
                %     'FontSize', 9, ...
                %     'HorizontalAlignment', 'left', ...
                %     'BackgroundColor', [0.95, 0.95, 0.95]);
                %
                % Add main title
                annotation(fig, 'textbox', [0.05, 0.88, 0.9, 0.1], ...
                    'String', data.plotTitle, ...
                    'FontSize', 14, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'EdgeColor', 'none', ...
                    'BackgroundColor', 'none');

                % Display in command window
                fprintf('\n=== Basin Average Sensitivity Analysis ===\n');
                fprintf('Point: %s\n', data.pointNames{1});
                fprintf('Expected Value: %.4f\n', data.expectedValue);
                fprintf('Top 5 Features:\n');
                for i = 1:min(5, topN)
                    fprintf('  %d. %s: %.4f\n', i, barhFeatures{i}, barhValues(i));
                end

            catch ME
                errorMsg = sprintf('Error creating tornado plot: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'Plot Error');
                rethrow(ME);
            end
        end

        function createInteractiveTornadoPlot(app)
            % Create tornado plot with navigation buttons for all_points case

            try
                data = app.SensitivityData;

                % Create a new figure with navigation buttons
                fig = figure('Name', data.plotTitle, ...
                    'NumberTitle', 'off', ...
                    'Position', [100, 50, 1400, 800]); % Larger figure

                % Create axes first (this is the main plot area)
                ax = axes('Parent', fig, 'Position', [0.12, 0.25, 0.75, 0.65]);

                % Create navigation panel at the bottom
                navPanel = uipanel('Parent', fig, ...
                    'Title', 'Point Navigation', ...
                    'Position', [0.05, 0.02, 0.9, 0.15], ...
                    'FontSize', 11, ...
                    'FontWeight', 'bold', ...
                    'BackgroundColor', [0.95, 0.95, 0.95], ...
                    'BorderType', 'etchedin');

                % Create navigation controls with better layout
                prevBtn = uicontrol('Parent', navPanel, ...
                    'Style', 'pushbutton', ...
                    'String', '⏪ Previous', ...
                    'Position', [20, 45, 100, 30], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'BackgroundColor', [0.3, 0.6, 0.9], ...
                    'ForegroundColor', 'white');

                % Point number edit field with label
                uicontrol('Parent', navPanel, ...
                    'Style', 'text', ...
                    'String', 'Point Number:', ...
                    'Position', [120, 45, 100, 25], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'right', ...
                    'BackgroundColor', [0.95, 0.95, 0.95]);

                pointEdit = uicontrol('Parent', navPanel, ...
                    'Style', 'edit', ...
                    'String', '1', ...
                    'Position', [235, 45, 60, 25], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'BackgroundColor', 'white');

                uicontrol('Parent', navPanel, ...
                    'Style', 'text', ...
                    'String', sprintf('of %d', data.totalPoints), ...
                    'Position', [300, 45, 60, 25], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'left', ...
                    'BackgroundColor', [0.95, 0.95, 0.95]);

                % Dropdown for point selection
                uicontrol('Parent', navPanel, ...
                    'Style', 'text', ...
                    'String', 'Select Point:', ...
                    'Position', [350, 45, 100, 25], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'right', ...
                    'BackgroundColor', [0.95, 0.95, 0.95]);

                pointDropdown = uicontrol('Parent', navPanel, ...
                    'Style', 'popupmenu', ...
                    'String', data.pointNames, ...
                    'Position', [465, 45, 200, 25], ...
                    'FontSize', 9, ...
                    'BackgroundColor', 'white');

                nextBtn = uicontrol('Parent', navPanel, ...
                    'Style', 'pushbutton', ...
                    'String', 'Next ⏩', ...
                    'Position', [680, 45, 100, 30], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'BackgroundColor', [0.3, 0.6, 0.9], ...
                    'ForegroundColor', 'white');

                % Point info display (current point and expected value)
                pointInfo = uicontrol('Parent', navPanel, ...
                    'Style', 'text', ...
                    'String', sprintf('Point 1/%d', data.totalPoints), ...
                    'Position', [800, 45, 350, 25], ...
                    'FontSize', 10, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'left', ...
                    'BackgroundColor', [0.95, 0.95, 0.95]);

                % Instructions label
                uicontrol('Parent', navPanel, ...
                    'Style', 'text', ...
                    'String', 'Use buttons, dropdown, or type point number to navigate', ...
                    'Position', [20, 75, 600, 20], ...
                    'FontSize', 9, ...%'FontStyle', 'italic', ...
                    'ForegroundColor', [0.4, 0.4, 0.4], ...
                    'HorizontalAlignment', 'left', ...
                    'BackgroundColor', [0.95, 0.95, 0.95]);

                % Store all handles in app structure
                app.SensitivityNavHandles = struct();
                app.SensitivityNavHandles.figure = fig;
                app.SensitivityNavHandles.axes = ax;
                app.SensitivityNavHandles.prevBtn = prevBtn;
                app.SensitivityNavHandles.nextBtn = nextBtn;
                app.SensitivityNavHandles.pointEdit = pointEdit;
                app.SensitivityNavHandles.pointDropdown = pointDropdown;
                app.SensitivityNavHandles.pointInfo = pointInfo;
                app.SensitivityNavHandles.expectedText = pointInfo; % For backward compatibility

                % Set callbacks with direct function definitions
                set(prevBtn, 'Callback', @(src, event) navigatePointCallback(app, -1));
                set(nextBtn, 'Callback', @(src, event) navigatePointCallback(app, 1));
                set(pointEdit, 'Callback', @(src, event) selectPointFromNumberCallback(app, src));
                set(pointDropdown, 'Callback', @(src, event) selectPointFromDropdownCallback(app, src));

                % Plot initial point (first point)
                updatePointPlot(app, 1);

                % Add a title to the figure
                annotation(fig, 'textbox', [0.05, 0.88, 0.9, 0.1], ...
                    'String', data.plotTitle, ...
                    'FontSize', 14, ...
                    'FontWeight', 'bold', ...
                    'HorizontalAlignment', 'center', ...
                    'VerticalAlignment', 'middle', ...
                    'EdgeColor', 'none', ...
                    'BackgroundColor', 'none');

            catch ME
                errorMsg = sprintf('Error creating interactive plot: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'Plot Error');
                rethrow(ME);
            end
        end

        function navigatePointCallback(app, direction)
            % Navigate to next/previous point
            try
                % Get current point index from the edit field
                currentIndex = str2double(app.SensitivityNavHandles.pointEdit.String);
                if isnan(currentIndex)
                    currentIndex = 1;
                end

                % Calculate new index
                newIndex = currentIndex + direction;

                % Get total points
                data = app.SensitivityData;

                % Check bounds and wrap around
                if newIndex < 1
                    newIndex = data.totalPoints;  % Wrap to last point
                elseif newIndex > data.totalPoints
                    newIndex = 1;  % Wrap to first point
                end

                % Update current point index in app
                app.SensitivityData.currentPointIndex = newIndex;

                % Update navigation controls
                app.SensitivityNavHandles.pointDropdown.Value = newIndex;
                app.SensitivityNavHandles.pointEdit.String = num2str(newIndex);

                % Update plot
                updatePointPlot(app, newIndex);

            catch ME
                errorMsg = sprintf('Error navigating to point: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'Navigation Error');
            end
        end

        function selectPointFromDropdownCallback(app, src)
            % Select point from dropdown
            try
                newIndex = src.Value;

                % Get total points from app data
                data = app.SensitivityData;

                if newIndex >= 1 && newIndex <= data.totalPoints
                    % Update current point index in app
                    app.SensitivityData.currentPointIndex = newIndex;

                    % Update navigation controls
                    app.SensitivityNavHandles.pointEdit.String = num2str(newIndex);

                    % Update plot
                    updatePointPlot(app, newIndex);
                end

            catch ME
                errorMsg = sprintf('Error selecting point from dropdown: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'Selection Error');
            end
        end

        function selectPointFromNumberCallback(app, src)
            % Select point from number input
            try
                pointNum = str2double(src.String);

                % Get data from app
                data = app.SensitivityData;

                % Check if valid number
                if isnan(pointNum)
                    src.String = num2str(data.currentPointIndex);
                    return;
                end

                % Check bounds
                pointNum = round(pointNum);
                if pointNum < 1
                    pointNum = 1;
                elseif pointNum > data.totalPoints
                    pointNum = data.totalPoints;
                end

                src.String = num2str(pointNum);

                % Update current point index in app
                app.SensitivityData.currentPointIndex = pointNum;

                app.SensitivityNavHandles.pointDropdown.Value = pointNum;

                updatePointPlot(app, pointNum);

            catch ME
                errorMsg = sprintf('Error selecting point from number: %s', ME.message);
                uialert(app.UIFigure, errorMsg, 'Selection Error');
            end
        end

        function updatePointPlot(app, pointIndex)
            % Update plot for current point with enhanced visualization
            try
                data = app.SensitivityData;

                % Get current point data
                pointName = data.pointNames{pointIndex};
                featureValues = data.featureData(pointIndex, :);

                % Get expected value if available
                if isfield(data, 'expectedValues')
                    expectedValue = data.expectedValues(pointIndex);
                elseif isfield(data, 'expectedValue')
                    expectedValue = data.expectedValue;
                else
                    expectedValue = NaN;
                end

                % Calculate absolute values for sorting
                absValues = abs(featureValues);

                % Sort features by absolute value (descending)
                [sortedAbsValues, sortIdx] = sort(absValues, 'descend');
                sortedValues = featureValues(sortIdx);
                sortedFeatureNames = data.featureNames(sortIdx);

                % Clean feature names (remove underscores and capitalize)
                cleanFeatureNames = cell(size(sortedFeatureNames));
                for i = 1:length(sortedFeatureNames)
                    name = sortedFeatureNames{i};
                    % Replace underscores with spaces
                    name = strrep(name, '_', ' ');
                    % Capitalize first letter of each word
                    nameParts = split(name);
                    for j = 1:length(nameParts)
                        if ~isempty(nameParts{j})
                            nameParts{j} = [upper(nameParts{j}(1)), lower(nameParts{j}(2:end))];
                        end
                    end
                    cleanFeatureNames{i} = strjoin(nameParts);
                end

                % Clear and update plot
                ax = app.SensitivityNavHandles.axes;
                cla(ax);
                hold(ax, 'on');

                % Show top 20 features
                topN = min(20, length(sortedValues));
                barhValues = sortedValues(1:topN);
                barhFeatures = cleanFeatureNames(1:topN);
                y = 1:topN;

                % Create gradient colors from deep to light (blue gradient for positive)
                % We'll use a gradient from dark blue to light blue based on rank
                colors = zeros(length(barhValues), 3);

                % Calculate normalized importance (0 to 1)
                if max(sortedAbsValues) > 0
                    normValues = sortedAbsValues(1:topN) / max(sortedAbsValues(1:topN));
                else
                    normValues = ones(1, topN);
                end

                % Create blue gradient (darker for higher importance)
                for i = 1:length(barhValues)
                    if barhValues(i) >= 0
                        % Blue gradient: darker blue for higher importance
                        intensity = 0.2 + 0.6 * normValues(i);  % 0.2 to 0.8
                        colors(i, :) = [0, 0.4 * intensity, intensity];
                    else
                        % For negative values (shouldn't exist in SHAP style, but keep as backup)
                        colors(i, :) = [0.8, 0.2, 0.2]; % Red as fallback
                    end
                end

                % Create horizontal bars (only positive values for SHAP style)
                % Filter only positive values for SHAP-style tornado plot
                positiveIdx = barhValues > 0;
                if any(positiveIdx)
                    posY = y(positiveIdx);
                    posValues = barhValues(positiveIdx);
                    posColors = colors(positiveIdx, :);
                    posFeatures = barhFeatures(positiveIdx);

                    h = barh(ax, posY, posValues);
                    h.FaceColor = 'flat';
                    h.CData = posColors;
                    h.EdgeColor = [0.2, 0.2, 0.4]; % Dark blue edges
                    h.LineWidth = 0.8;

                    % Update y labels for positive features only
                    set(ax, 'YTick', posY, 'YTickLabel', posFeatures);
                else
                    % If all values are negative (unlikely in SHAP), plot all
                    h = barh(ax, y, barhValues);
                    h.FaceColor = 'flat';
                    h.CData = colors;
                    h.EdgeColor = [0.2, 0.2, 0.4];
                    h.LineWidth = 0.8;

                    set(ax, 'YTick', y, 'YTickLabel', barhFeatures);
                end

                ylabel(ax, 'Features', 'FontSize', 11, 'FontWeight', 'bold');
                xlabel(ax, 'Feature Importance Value', 'FontSize', 11, 'FontWeight', 'bold');
                % title(ax, sprintf('Tornado Plot - %s', pointName), ...
                %     'FontSize', 12, 'FontWeight', 'bold');

                % Set Y-axis properties
                set(ax, 'YDir', 'reverse'); % Highest values at top
                set(ax, 'FontSize', 10);
                grid(ax, 'on');
                grid(ax, 'minor');

                % Set x-axis to start at 0 (no negative part)
                xlim(ax, [0, max(barhValues) * 1.15]);

                % Remove x-axis negative ticks
                ax.XTick(ax.XTick < 0) = [];

                % Add value labels inside or at the end of bars
                for i = 1:length(posY)
                    value = posValues(i);
                    % Position label at the end of the bar with slight offset
                    textPos = value + max(posValues) * 0.005;
                    text(ax, textPos, posY(i), sprintf('%.4f', value), ...
                        'FontSize', 9, ...
                        'VerticalAlignment', 'middle', ...
                        'HorizontalAlignment', 'left', ...
                        'BackgroundColor', [1, 1, 1, 0.7], ... % Semi-transparent white
                        'EdgeColor', 'none', ...
                        'Margin', 1, ...
                        'FontWeight', 'bold');
                end

                % Add a subtle vertical grid for better readability
                ax.XGrid = 'on';
                ax.GridAlpha = 0.3;
                ax.GridLineStyle = '--';

                % Beautify the axes
                ax.Box = 'on';
                ax.LineWidth = 1.5;
                ax.TickLength = [0.01, 0.01];

                % % Add color bar to show importance gradient
                % if any(positiveIdx)
                %     colormap(ax, winter(256)); % Blue gradient colormap
                %     c = colorbar(ax, 'Location', 'eastoutside');
                %     c.Label.String = 'Relative Importance';
                %     c.Label.FontSize = 10;
                %     c.Label.FontWeight = 'bold';
                %     c.Ticks = [0, 0.25, 0.5, 0.75, 1];
                %     c.TickLabels = {'Low', '', 'Medium', '', 'High'};
                % end

                hold(ax, 'off');

                % Update expected value display if available
                if isfield(app.SensitivityNavHandles, 'expectedText')
                    app.SensitivityNavHandles.expectedText.String = sprintf('Expected Value: %.4f', expectedValue);
                end

                % Update point info in navigation panel
                if isfield(app.SensitivityNavHandles, 'pointInfo')
                    app.SensitivityNavHandles.pointInfo.String = ...
                        sprintf('Point %d/%d | Expected Value: %.4f', pointIndex, data.totalPoints, expectedValue);
                end

                % Display in command window
                fprintf('\n=== Point %d/%d: %s ===\n', pointIndex, data.totalPoints, pointName);
                fprintf('Expected Value: %.4f\n', expectedValue);
                fprintf('Top 5 Features:\n');
                for i = 1:min(5, topN)
                    fprintf('  %d. %s: %.4f\n', i, cleanFeatureNames{i}, sortedValues(i));
                end

                % Force redraw
                drawnow;

            catch ME
                errorMsg = sprintf('Error updating plot for point %d: %s', pointIndex, ME.message);
                uialert(app.UIFigure, errorMsg, 'Plot Update Error');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function updateAllPlots(app)
            % % Get dropdown selection
            screenSize = get(0,'ScreenSize');

            % Design size (from your UIFigure in Design View)
            designWidth = 1212;
            designHeight = 764;

            % Compute scale factor relative to screen size
            scale = min(screenSize(3)/designWidth, screenSize(4)/designHeight);
            %
            % % Apply new scaled size (use 85% of available screen for safety)
            % newWidth = round(designWidth * scale* 0.85);% * 0.85
            % newHeight = round(designHeight * scale* 0.85);
            %
            % % Update UIFigure size
            % app.UIFigure.Position = [50 50 newWidth newHeight];
            cla(app.UIAxes, 'reset');
            legendItems = {};
            plotType = app.PlotTypeDropDown.Value;  % 'Temporal' or 'Spatial'


            switch plotType

                case 'Temporal'
                    % ===== Temporal Plot =====

                    timescales = app.TimescaleDropDown.Value;
                    switch timescales
                        %case 'Daily'
                        case 'Monthly'
                            cla(app.UIAxes, 'reset');
                            hold(app.UIAxes,'on'); % clear axes before plotting anything
                            % Define the starting and ending dates for the slider scale
                            startDate = datetime(1901, 1, 1); % Start date (01-1901)
                            endDate = datetime(2025, 12, 1);  % End date (12-2025)
                            % Generate all months between the start and end dates
                            allTimeDates = startDate:calmonths(1):endDate;
                            % Get selected time range from slider
                            sIdx = round(app.TimeSpan19012025Slider.Value(1));
                            eIdx = round(app.TimeSpan19012025Slider.Value(2));
                            sIdx = max(sIdx,1);
                            eIdx = min(eIdx, numel(allTimeDates));
                            selectedTimeRange = allTimeDates(sIdx:eIdx);
                            x = sIdx:eIdx;
                            % Get start & end years of current visible time range
                            tStart = year(selectedTimeRange(1));
                            tEnd   = year(selectedTimeRange(end));
                            xlim(app.UIAxes, [sIdx eIdx]);
                            % Calculate total span in years
                            spanYears = tEnd - tStart;

                            % Choose a step size dynamically (prevent overlap)
                            if spanYears > 40
                                step = 10;   % e.g., large range → coarser ticks
                            elseif spanYears > 20
                                step = 5;
                            elseif spanYears > 10
                                step = 2;
                            else
                                step = 1;    % short range → finer ticks
                            end

                            % Define ticks
                            tickYears = tStart:step:tEnd;

                            % % Axis ticks every 2 years
                            % tickYears = year(selectedTimeRange(1)) : 2 : year(selectedTimeRange(end));
                            xt = datetime(tickYears, 1, 1);
                            % Convert datetime to indices relative to allTimeDates
                            [~, idxTicks] = ismember(xt, allTimeDates);
                            % Apply ticks and labels
                            app.UIAxes.XTick = idxTicks;
                            app.UIAxes.XTickLabel = cellstr(datestr(xt, 'yyyy'));
                            % Set axis limits (numeric)
                            xlim(app.UIAxes, [sIdx eIdx]);
                            timescales = app.TimescaleDropDown.Value;
                            legendItems = {};

                            %% ==================== RECONSTRUCTED TREE ====================
                            selectedNodes = app.RTWSA.CheckedNodes;
                            % Colors for different reconstructions (normalized keys)
                            colors = struct('hg19', [0.0 0.60 0.50], ...   % teal
                                'p25',[0.95 0.60 0.20], ...  % golden orange
                                'l21',       [0.35 0.70 0.90], ...  % light blue
                                'd23',     [0.90 0.10 0.15], ...  % red
                                'm25',   [0.60 0.40 0.20]);     % brown

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % clean up
                                nodeKey = lower(rawName); % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% HUMPHREY (6 datasets)
                                    case 'hg19'
                                        Hp_REC = NaN(6,1500);
                                        Hp_REC(1,[app.Hp_GERECMN]) = mean(app.Hp_GERECData,1);
                                        Hp_REC(2,[app.Hp_GGRECMN]) = mean(app.Hp_GGRECData,1);
                                        Hp_REC(3,[app.Hp_GMRECMN]) = mean(app.Hp_GMRECData,1);
                                        Hp_REC(4,[app.Hp_JERECMN]) = mean(app.Hp_JERECData,1);
                                        Hp_REC(5,[app.Hp_JGRECMN]) = mean(app.Hp_JGRECData,1);
                                        Hp_REC(6,[app.Hp_JMRECMN]) = mean(app.Hp_JMRECData,1);

                                        REC = [
                                            min(Hp_REC, [], 1,'omitnan');
                                            max(Hp_REC, [], 1,'omitnan');
                                            mean(Hp_REC, 1,'omitnan')
                                            ];

                                        %% PALAZZOLI (4 datasets)
                                    case 'p25'
                                        Pl_REC = NaN(4,1500);
                                        Pl_REC(1,[app.PalazzoliLSTMMN])        = 10*mean(app.PalazzoliLSTMData,1);
                                        Pl_REC(2,[app.PalazzoliLSTMnoLCSIFMN]) = 10*mean(app.PalazzoliLSTMnoLCSIFData,1);
                                        Pl_REC(3,[app.PalazzoliBiLSTMMN])      = 10*mean(app.PalazzoliBiLSTMData,1);
                                        Pl_REC(4,[app.PalazzoliBiLSTMnoLCSIFMN]) = 10*mean(app.PalazzoliBiLSTMnoLCSIFData,1);

                                        REC = [
                                            min(Pl_REC, [], 1,'omitnan');
                                            max(Pl_REC, [], 1,'omitnan');
                                            mean(Pl_REC, 1,'omitnan')
                                            ];

                                        %% LI (single)
                                    case 'l21'
                                        Li_BA = 10*mean(app.LiRECData,1);
                                        Li_REC = NaN(1,1500);
                                        Li_REC(1,[app.LiRECMN]) = Li_BA;
                                        REC = repmat(Li_REC,3,1);

                                        %% DENG (single)
                                    case 'd23'
                                        Deng_BA = mean(app.DengRECData,1);
                                        Deng_REC = NaN(1,1500);
                                        Deng_REC(1,[app.DengRECMN]) = Deng_BA;
                                        REC = repmat(Deng_REC,3,1);

                                        %% MANDAL (single)
                                    case 'm25'
                                        Mandal_BA = mean(app.MandalRECData,1);
                                        Mandal_REC = NaN(1,1500);
                                        Mandal_REC(1,[app.MandalRECMN]) = Mandal_BA;
                                        REC = repmat(Mandal_REC,3,1);
                                end

                                % Pick color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                % Fill band (flat since single dataset)
                                hold(app.UIAxes, 'on');  % Hold once at the beginning

                                if strcmp(nodeKey, 'hg19') || strcmp(nodeKey, 'p25')
                                    % Plot filled area
                                    fill(app.UIAxes, [x fliplr(x)], [REC(2, x) fliplr(REC(1, x))],c, 'FaceAlpha', 0.2, 'EdgeColor', 'none','DisplayName', strcat(rawName," Range"));
                                end

                                % Plot main line
                                plot(app.UIAxes, x, REC(3, x), 'Color', c, 'LineWidth',1.5,'LineStyle','--','DisplayName', strcat(rawName));

                            end


                            % Get selected GRACE datasets
                            selectedNodes = app.TWSA.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for GRACE datasets (normalized keys)
                            colors = struct( ...
                                'jpl',  [0.2 0.2 0.2], ...  % dark gray
                                'csr',  [0.5 0.5 0.5], ...  % medium gray
                                'gsfc', [0.8 0.8 0.8]);     % light gray
                            markers= struct( ...
                                'jpl',  "o", ...  % dark gray
                                'csr',  "+", ...  % medium gray
                                'gsfc', '*' );     % light gray

                            for i = 1:numel(selectedNodes)
                                % Skip non-leaf nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                                nodeKey = lower(rawName);

                                REC = [];
                                switch nodeKey
                                    case 'jpl'
                                        JPL_BA = 10*mean(app.JPLTWSData,1);
                                        REC = repmat(NaN,3,1500);
                                        REC(:,app.JPLTWSMN) = [JPL_BA; JPL_BA; JPL_BA];

                                    case 'csr'
                                        CSR_BA = 10*mean(app.CSRTWSData,1);
                                        REC = repmat(NaN,3,1500);
                                        REC(:,app.CSRTWSMN) = [CSR_BA; CSR_BA; CSR_BA];

                                    case 'gsfc'
                                        GSFC_BA = 10*mean(app.GFSCTWSData,1);
                                        REC = repmat(NaN,3,1500);
                                        REC(:,app.GFSCTWSMN) = [GSFC_BA; GSFC_BA; GSFC_BA];

                                    otherwise
                                        disp(['[TWSA] Skipping unhandled node: "', rawName, '"']);
                                        continue;
                                end

                                if isempty(REC), continue; end
                                eIdx = min(eIdx, size(REC,2));
                                x = sIdx:eIdx;

                                % Choose dataset color (fallback if missing)
                                if isfield(colors, nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                if isfield(markers, nodeKey)
                                    d = markers.(nodeKey);
                                else
                                    d = 'square';
                                end

                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth', 2,'Marker',d, 'DisplayName', strcat(upper(nodeKey)));%," TWSA"
                            end

                            % Use one of these values: '+' | 'o' | '*' | '.' | 'x' | 'square' | 'diamond' | 'v' | '^' | '>' | '<' | 'pentagram' |
                            % 'hexagram' | '|' | '_' | 'none'.
                            %% ==================== THIS STUDY TREE ====================
                            selectedNodes = app.This_Study.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for each of your models
                            colors = struct( ...
                                'rf_imd',     [0.0 0.45 0.70], ...   % strong blue
                                'ann_g',      [0.85 0.33 0.10], ...  % bright orange
                                'ann_imd',     [0.93 0.69 0.13], ...  % yellow
                                'rf_g',       [0.49 0.18 0.56]);     % purple

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                                nodeKey = lower(rawName);                 % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% BEST
                                    case 'rf_imd'
                                        Best_BA = mean(app.ThisStudyBestData,1);
                                        Best_REC = NaN(1,1500);
                                        Best_REC(1,[app.ThisStudyBestMN]) = Best_BA;
                                        REC = repmat(Best_REC,3,1);

                                        %% ANN
                                    case 'ann_g'
                                        ANN_BA = mean(app.ThisStudyANNData,1);
                                        ANN_REC = NaN(1,1500);
                                        ANN_REC(1,[app.ThisStudyANNMN]) = ANN_BA;
                                        REC = repmat(ANN_REC,3,1);

                                        %% LSTM
                                    case 'ann_imd'
                                        LSTM_BA = mean(app.ThisStudyLSTMData,1);
                                        LSTM_REC = NaN(1,1500);
                                        LSTM_REC(1,[app.ThisStudyLSTMMN]) = LSTM_BA;
                                        REC = repmat(LSTM_REC,3,1);

                                        %% RF
                                    case 'rf_g'
                                        RF_BA = mean(app.ThisStudyRFData,1);
                                        RF_REC = NaN(1,1500);
                                        RF_REC(1,[app.ThisStudyRFMN]) = RF_BA;
                                        REC = repmat(RF_REC,3,1);
                                end

                                if isempty(REC), continue; end
                                % Pick dataset color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5]; % fallback gray
                                end

                                % Fill band (flat since single dataset)
                                % fill(app.UIAxes, [x fliplr(x)], [REC(2,x) fliplr(REC(1,x))], ...
                                %     c, 'FaceAlpha',0.2, 'EdgeColor','none');
                                hold(app.UIAxes,'on');

                                % Mean line
                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth',1.5,'DisplayName', strcat(rawName));%," RTWSA"

                            end
                            % Axes settings
                            grid(app.UIAxes,'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 14;
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18;
                            app.UIAxes.YLabel.String = '(R)TWSA (mm)';   % left axis label
                            app.UIAxes.YLabel.FontName = 'Times New Roman';
                            app.UIAxes.YLabel.FontWeight = 'bold';
                            app.UIAxes.YLabel.FontSize = 18;
                            % Lyticks = 0:round(max(REC,2)/5):max(REC,2);   % adjust step as needed
                            % app.UIAxes.YTick = Lyticks;
                            hold(app.UIAxes,'off');
                            %% ==================== PRECIPITATION (inverted, on right axis, with gap to TWS) ====================
                            selectedNodes = app.Precipitation.CheckedNodes;
                            yyaxis(app.UIAxes,'right');
                            yl = ylabel(app.UIAxes,'Precipitation (mm)');  % get handle
                            yl.FontSize = 18;
                            yl.FontName = 'Times New Roman';
                            yl.FontWeight = 'bold';
                            set(app.UIAxes,'YDir','reverse');  % invert the axis so bars grow downward

                            precipData = []; precipLabels = {};
                            for i = 1:numel(selectedNodes)
                                if ~isempty(selectedNodes(i).Children), continue; end
                                rawName = strtrim(selectedNodes(i).Text);
                                nodeKey = lower(rawName);
                                clear data

                                switch nodeKey
                                    case 'imd'
                                        % IMD (already in mm monthly)
                                        app.IMD_BA = mean(app.IMDData,1);
                                        data = NaN(1,1500); data(1,app.IMDMN) = app.IMD_BA;

                                    case 'gpcp'
                                        % GPCP (mm/month)
                                        app.GPCP_BA = mean(app.GPCPData,1);
                                        data = NaN(1,1500); data(1,app.GPCPMN) = app.GPCP_BA;

                                    case 'era5'
                                        % ERA5 'tp' is meters; convert to mm
                                        app.ERA5_BA = mean(app.ERA5Data,1);
                                        data = NaN(1,1500); data(1,app.ERA5MN) = app.ERA5_BA;
                                end

                                if exist('data','var')
                                    precipData   = [precipData; data];    %#ok<AGROW>
                                    precipLabels = [precipLabels; nodeKey]; %#ok<AGROW>
                                end
                            end

                            if ~isempty(precipData)
                                % What’s selected?
                                nSel      = size(precipData,1);
                                hasIMD    = any(strcmpi(precipLabels,'imd'));
                                % Stats in window
                                p_min  = min(precipData(:,x),[],1,'omitnan');
                                p_max  = max(precipData(:,x),[],1,'omitnan');
                                p_mean = mean(precipData(:,x),1,'omitnan');
                                Pmax   = max(p_max,[],'omitnan');

                                % ---- Plot according to selection rules ----
                                if nSel == 1
                                    % Only one dataset selected
                                    ydata = precipData(1,x);
                                    if strcmpi(precipLabels{1},'imd')
                                        hold(app.UIAxes,'on');
                                        % IMD as open circles (inverted)
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','IMD Precip');
                                        plot(app.UIAxes, x, ydata, 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    elseif strcmpi(precipLabels{1},'gpcp')
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','GPCP Precip');
                                    else % ERA5
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','ERA5 Precip');
                                    end
                                elseif nSel == 2
                                    % Multiple selected
                                    % (Only IMD points + whiskers + hollow mean bar in blue tones)
                                    % Hollow mean bar (blue edge)
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    % Whiskers (min–max) behind mean
                                    legendAdded = false;  % flag to ensure legend appears only once

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            % First whisker line with legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;  % next lines won’t have legend
                                        else
                                            % Other lines: no legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end

                                    if hasIMD
                                        % Plot IMD as “o” markers
                                        j = find(strcmpi(precipLabels,'imd'),1);
                                        plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    end
                                else
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    j = find(strcmpi(precipLabels,'imd'),1);
                                    plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    legendAdded = false;  % flag to ensure legend appears only once

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            % First whisker line with legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;  % next lines won’t have legend
                                        else
                                            % Other lines: no legend
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end


                                end

                                % ---------- Dynamic Axis coordination (TWSA + Precip Gap) ----------
                                yyaxis(app.UIAxes,'left');

                                % Collect ALL TWSA data from plotted series in the selected window
                                allTWSA = [];
                                h = findall(app.UIAxes,'Type','Line'); % get plotted lines
                                for k = 1:numel(h)
                                    ydata = h(k).YData;
                                    xdata = h(k).XData;
                                    % only keep values within the sIdx:eIdx window
                                    mask = (xdata >= sIdx & xdata <= eIdx);
                                    if any(mask)
                                        allTWSA = [allTWSA, ydata(mask)];
                                    end
                                end

                                if ~isempty(allTWSA)
                                    % Dynamic limits based on selected time window
                                    twsMin = min(allTWSA, [], 'omitnan');
                                    twsMax = max(allTWSA, [], 'omitnan');

                                    % Add padding for readability
                                    pad = 0.15 * (twsMax - twsMin);
                                    twsLimits = [twsMin , twsMax + pad];

                                    % Apply limits to left axis
                                    ylim(app.UIAxes, twsLimits);

                                    % Dynamic ticks
                                    step = round((twsMax - twsMin) / 5);
                                    if step == 0, step = 1; end
                                    app.UIAxes.YTick = round(twsMin):step:round(twsMax);

                                    % -------- Right axis (precipitation, inverted, with gap) --------
                                    yyaxis(app.UIAxes,'right');
                                    Pmax = max(p_max,[],'omitnan');   % already computed from precip data
                                    gap  = 1.2 * (twsLimits(2) - twsLimits(1)); % proportional gap
                                    ylim(app.UIAxes, [0 Pmax + gap]);
                                    yticks(app.UIAxes, 0:round(Pmax/5):Pmax);
                                end

                            end

                            % Ensure grid and axis font
                            grid(app.UIAxes, 'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 18* scale;

                            % Axis labels
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18* scale;
                            % Create legend AFTER plotting
                            lg = legend(app.UIAxes, 'show');
                            % Legend configuration
                            % if exist('newWidth','var') && ~isempty(newWidth)
                            lg.NumColumns =9;% min(9, floor(newWidth/150));
                            % else
                            %     lg.NumColumns = 3; % fallback
                            % end
                            lg.Location    = 'southoutside';
                            lg.Orientation = 'horizontal';
                            lg.Box         = 'on';
                            lg.Interpreter = 'none';
                            lg.AutoUpdate  = 'off';
                            lg.FontName    = 'Times New Roman';
                            lg.FontWeight  = 'bold';

                            if exist('scale','var') && ~isempty(scale)
                                lg.FontSize = 10* scale;
                            else
                                lg.FontSize = 12; % fallback
                            end

                            hold(app.UIAxes, 'off');

                        case 'Annual'
                            cla(app.UIAxes, 'reset');
                            hold(app.UIAxes,'on'); % clear axes before plotting anything
                            % Define the starting and ending years for the slider scale
                            startYr = 1901;
                            endYr = 2025;
                            allYrs = startYr:endYr;
                            % Get selected time range from slider
                            sIdx = round(app.TimeSpan19012025Slider.Value(1));
                            eIdx = round(app.TimeSpan19012025Slider.Value(2));
                            sIdx = max(sIdx,1);
                            eIdx = min(eIdx, numel(allYrs));
                            selectedTimeRange = allYrs(sIdx:eIdx);
                            x = sIdx:eIdx;
                            % Get start & end years of current visible time range
                            tStart = selectedTimeRange(1);
                            tEnd   = selectedTimeRange(end);

                            % Calculate total span in years
                            spanYrs = tEnd - tStart;

                            % Choose a step size dynamically (prevent overlap)
                            if spanYrs > 40
                                step = 10;   % e.g., large range → coarser ticks
                            elseif spanYrs > 20
                                step = 5;
                            elseif spanYrs > 10
                                step = 2;
                            else
                                step = 1;    % short range → finer ticks
                            end

                            % Define ticks
                            tickYrs = tStart:step:tEnd;

                            % Apply ticks and labels
                            app.UIAxes.XTick = find(ismember(allYrs, tickYrs));
                            app.UIAxes.XTickLabel = cellstr(num2str(tickYrs'));

                            % Set axis limits (numeric)
                            xlim(app.UIAxes, [sIdx eIdx]);
                            timescales = app.TimescaleDropDown.Value;
                            legendItems = {};
                            %% ==================== RECONSTRUCTED TREE ====================
                            selectedNodes = app.RTWSA.CheckedNodes;
                            % Colors for different reconstructions (normalized keys)
                            colors = struct('hg19', [0.0 0.60 0.50], ...   % teal
                                'p25',[0.95 0.60 0.20], ...  % golden orange
                                'l21',       [0.35 0.70 0.90], ...  % light blue
                                'd23',     [0.90 0.10 0.15], ...  % red
                                'm25',   [0.60 0.40 0.20]);     % brown

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % clean up
                                nodeKey = lower(rawName); % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% HUMPHREY (6 datasets)
                                    case 'hg19'
                                        Hp_REC = NaN(6,1500);
                                        Hp_REC(1,[app.Hp_GERECMN]) = mean(app.Hp_GERECData,1);
                                        Hp_REC(2,[app.Hp_GGRECMN]) = mean(app.Hp_GGRECData,1);
                                        Hp_REC(3,[app.Hp_GMRECMN]) = mean(app.Hp_GMRECData,1);
                                        Hp_REC(4,[app.Hp_JERECMN]) = mean(app.Hp_JERECData,1);
                                        Hp_REC(5,[app.Hp_JGRECMN]) = mean(app.Hp_JGRECData,1);
                                        Hp_REC(6,[app.Hp_JMRECMN]) = mean(app.Hp_JMRECData,1);

                                        % Convert monthly to annual mean - handle partial years
                                        Hp_REC_annual = NaN(6,125);
                                        for datasetIdx = 1:6
                                            monthly_data = Hp_REC(datasetIdx,:);
                                            for yrIdx = 1:125
                                                start_month = (yrIdx-1)*12 + 1;
                                                end_month = yrIdx*12;
                                                % Only calculate if we have data for this year
                                                year_data = monthly_data(start_month:end_month);
                                                if any(~isnan(year_data))
                                                    Hp_REC_annual(datasetIdx,yrIdx) = mean(year_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Hp_REC_annual, [], 1,'omitnan');
                                            max(Hp_REC_annual, [], 1,'omitnan');
                                            mean(Hp_REC_annual, 1,'omitnan')
                                            ];

                                        %% PALAZZOLI (4 datasets)
                                    case 'p25'
                                        Pl_REC = NaN(4,1500);
                                        Pl_REC(1,[app.PalazzoliLSTMMN])        = 10*mean(app.PalazzoliLSTMData,1);
                                        Pl_REC(2,[app.PalazzoliLSTMnoLCSIFMN]) = 10*mean(app.PalazzoliLSTMnoLCSIFData,1);
                                        Pl_REC(3,[app.PalazzoliBiLSTMMN])      = 10*mean(app.PalazzoliBiLSTMData,1);
                                        Pl_REC(4,[app.PalazzoliBiLSTMnoLCSIFMN]) = 10*mean(app.PalazzoliBiLSTMnoLCSIFData,1);

                                        % Convert monthly to annual mean - handle partial years
                                        Pl_REC_annual = NaN(4,125);
                                        for datasetIdx = 1:4
                                            monthly_data = Pl_REC(datasetIdx,:);
                                            for yrIdx = 1:125
                                                start_month = (yrIdx-1)*12 + 1;
                                                end_month = yrIdx*12;
                                                % Only calculate if we have data for this year
                                                year_data = monthly_data(start_month:end_month);
                                                if any(~isnan(year_data))
                                                    Pl_REC_annual(datasetIdx,yrIdx) = mean(year_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Pl_REC_annual, [], 1,'omitnan');
                                            max(Pl_REC_annual, [], 1,'omitnan');
                                            mean(Pl_REC_annual, 1,'omitnan')
                                            ];

                                        %% LI (single)
                                    case 'l21'
                                        Li_BA = 10*mean(app.LiRECData,1);
                                        Li_REC = NaN(1,1500);
                                        Li_REC(1,[app.LiRECMN]) = Li_BA;

                                        % Convert monthly to annual mean - handle partial years
                                        Li_REC_annual = NaN(1,125);
                                        monthly_data = Li_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Li_REC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Li_REC_annual,3,1);

                                        %% DENG (single)
                                    case 'd23'
                                        Deng_BA = mean(app.DengRECData,1);
                                        Deng_REC = NaN(1,1500);
                                        Deng_REC(1,[app.DengRECMN]) = Deng_BA;

                                        % Convert monthly to annual mean - handle partial years
                                        Deng_REC_annual = NaN(1,125);
                                        monthly_data = Deng_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Deng_REC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Deng_REC_annual,3,1);

                                        %% MANDAL (single)
                                    case 'm25'
                                        Mandal_BA = mean(app.MandalRECData,1);
                                        Mandal_REC = NaN(1,1500);
                                        Mandal_REC(1,[app.MandalRECMN]) = Mandal_BA;

                                        % Convert monthly to annual mean - handle partial years
                                        Mandal_REC_annual = NaN(1,125);
                                        monthly_data = Mandal_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Mandal_REC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Mandal_REC_annual,3,1);
                                end

                                % Pick color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                % Fill band (flat since single dataset)
                                hold(app.UIAxes, 'on');

                                if strcmp(nodeKey, 'hg19') || strcmp(nodeKey, 'p25')
                                    % Plot filled area
                                    fill(app.UIAxes, [x fliplr(x)], [REC(2, x) fliplr(REC(1, x))],c, 'FaceAlpha', 0.2, 'EdgeColor', 'none','DisplayName', strcat(rawName," Range"));
                                end

                                % Plot main line
                                plot(app.UIAxes, x, REC(3, x), 'Color', c, 'LineWidth',1.5,'LineStyle','--','DisplayName', strcat(rawName));

                            end

                            % Get selected GRACE datasets
                            selectedNodes = app.TWSA.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for GRACE datasets (normalized keys)
                            colors = struct( ...
                                'jpl',  [0.2 0.2 0.2], ...  % dark gray
                                'csr',  [0.5 0.5 0.5], ...  % medium gray
                                'gsfc', [0.8 0.8 0.8]);     % light gray
                            markers= struct( ...
                                'jpl',  "o", ...  % dark gray
                                'csr',  "+", ...  % medium gray
                                'gsfc', '*' );     % light gray

                            for i = 1:numel(selectedNodes)
                                % Skip non-leaf nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                                nodeKey = lower(rawName);
                                REC = [];
                                switch nodeKey
                                    case 'jpl'
                                        JPL_BA = 10*mean(app.JPLTWSData,1);
                                        JPL_TWSA = NaN(1,1500);
                                        JPL_TWSA(1,[app.JPLTWSMN]) = JPL_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        JPL_annual = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = JPL_TWSA(start_month:end_month);
                                            if any(~isnan(year_data))
                                                JPL_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(JPL_annual,3,1);

                                    case 'csr'
                                        CSR_BA = 10*mean(app.CSRTWSData,1);
                                        CSR_TWSA = NaN(1,1500);
                                        CSR_TWSA(1,[app.CSRTWSMN]) = CSR_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        CSR_annual = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = CSR_TWSA(start_month:end_month);
                                            if any(~isnan(year_data))
                                                CSR_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(CSR_annual,3,1);

                                    case 'gsfc'
                                        GSFC_BA = 10*mean(app.GFSCTWSData,1);
                                        GSFC_TWSA = NaN(1,1500);
                                        GSFC_TWSA(1,[app.GFSCTWSMN]) = GSFC_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        GSFC_annual = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = GSFC_TWSA(start_month:end_month);
                                            if any(~isnan(year_data))
                                                GSFC_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(GSFC_annual,3,1);

                                    otherwise
                                        disp(['[TWSA] Skipping unhandled node: "', rawName, '"']);
                                        continue;
                                end

                                if isempty(REC), continue; end
                                eIdx_plot = min(eIdx, size(REC,2));
                                x_plot = sIdx:eIdx_plot;

                                % Choose dataset color (fallback if missing)
                                if isfield(colors, nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                if isfield(markers, nodeKey)
                                    d = markers.(nodeKey);
                                else
                                    d = 'square';
                                end

                                plot(app.UIAxes, x_plot, REC(3,x_plot), 'Color', c, 'LineWidth', 2,'Marker',d, 'DisplayName', strcat(upper(nodeKey)));
                            end

                            %% ==================== THIS STUDY TREE ====================
                            selectedNodes = app.This_Study.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for each of your models
                            colors = struct( ...
                                'rf_imd',     [0.0 0.45 0.70], ...   % strong blue
                                'ann_g',      [0.85 0.33 0.10], ...  % bright orange
                                'ann_imd',     [0.93 0.69 0.13], ...  % yellow
                                'rf_g',       [0.49 0.18 0.56]);     % purple

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                                nodeKey = lower(rawName);                 % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% BEST
                                    case 'rf_imd'
                                        Best_BA = mean(app.ThisStudyBestData,1);
                                        Best_REC = NaN(1,1500);
                                        Best_REC(1,[app.ThisStudyBestMN]) = Best_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        Best_annual = NaN(1,125);
                                        monthly_data = Best_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                Best_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Best_annual,3,1);

                                        %% ANN
                                    case 'ann_g'
                                        ANN_BA = mean(app.ThisStudyANNData,1);
                                        ANN_REC = NaN(1,1500);
                                        ANN_REC(1,[app.ThisStudyANNMN]) = ANN_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        ANN_annual = NaN(1,125);
                                        monthly_data = ANN_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                ANN_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(ANN_annual,3,1);

                                        %% LSTM
                                    case 'ann_imd'
                                        LSTM_BA = mean(app.ThisStudyLSTMData,1);
                                        LSTM_REC = NaN(1,1500);
                                        LSTM_REC(1,[app.ThisStudyLSTMMN]) = LSTM_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        LSTM_annual = NaN(1,125);
                                        monthly_data = LSTM_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                LSTM_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(LSTM_annual,3,1);

                                        %% RF
                                    case 'rf_g'
                                        RF_BA = mean(app.ThisStudyRFData,1);
                                        RF_REC = NaN(1,1500);
                                        RF_REC(1,[app.ThisStudyRFMN]) = RF_BA;
                                        % Convert monthly to annual mean - handle partial years
                                        RF_annual = NaN(1,125);
                                        monthly_data = RF_REC(1,:);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = monthly_data(start_month:end_month);
                                            if any(~isnan(year_data))
                                                RF_annual(1,yrIdx) = mean(year_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(RF_annual,3,1);
                                end

                                if isempty(REC), continue; end
                                % Pick dataset color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5]; % fallback gray
                                end

                                hold(app.UIAxes,'on');

                                % Mean line
                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth',1.5,'DisplayName', strcat(rawName));

                            end

                            % Axes settings
                            grid(app.UIAxes,'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 14;
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18;
                            app.UIAxes.YLabel.String = '(R)TWSA (mm)';   % left axis label
                            app.UIAxes.YLabel.FontName = 'Times New Roman';
                            app.UIAxes.YLabel.FontWeight = 'bold';
                            app.UIAxes.YLabel.FontSize = 18;

                            hold(app.UIAxes,'off');

                            %% ==================== PRECIPITATION (inverted, on right axis, with gap to TWS) ====================
                            selectedNodes = app.Precipitation.CheckedNodes;
                            yyaxis(app.UIAxes,'right');
                            yl = ylabel(app.UIAxes,'Precipitation (mm)');  % get handle
                            yl.FontSize = 18;
                            yl.FontName = 'Times New Roman';
                            yl.FontWeight = 'bold';
                            set(app.UIAxes,'YDir','reverse');  % invert the axis so bars grow downward

                            precipData = []; precipLabels = {};
                            for i = 1:numel(selectedNodes)
                                if ~isempty(selectedNodes(i).Children), continue; end
                                rawName = strtrim(selectedNodes(i).Text);
                                nodeKey = lower(rawName);
                                clear data

                                switch nodeKey
                                    case 'imd'
                                        % IMD (already in mm monthly) - convert to annual sum
                                        app.IMD_BA = mean(app.IMDData,1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.IMDMN) = app.IMD_BA;
                                        % Convert to annual sum - handle partial years
                                        data = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = data_monthly(1,start_month:end_month);
                                            if any(~isnan(year_data))
                                                data(1,yrIdx) = sum(year_data, 'omitnan');
                                            end
                                        end

                                    case 'gpcp'
                                        % GPCP (mm/month) - convert to annual sum
                                        app.GPCP_BA = mean(app.GPCPData,1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.GPCPMN) = app.GPCP_BA;
                                        % Convert to annual sum - handle partial years
                                        data = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = data_monthly(1,start_month:end_month);
                                            if any(~isnan(year_data))
                                                data(1,yrIdx) = sum(year_data, 'omitnan');
                                            end
                                        end

                                    case 'era5'
                                        % ERA5 'tp' is meters; convert to mm and then annual sum
                                        app.ERA5_BA = mean(app.ERA5Data,1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.ERA5MN) = app.ERA5_BA;
                                        % Convert to annual sum - handle partial years
                                        data = NaN(1,125);
                                        for yrIdx = 1:125
                                            start_month = (yrIdx-1)*12 + 1;
                                            end_month = yrIdx*12;
                                            % Only calculate if we have data for this year
                                            year_data = data_monthly(1,start_month:end_month);
                                            if any(~isnan(year_data))
                                                data(1,yrIdx) = sum(year_data, 'omitnan');
                                            end
                                        end
                                end

                                if exist('data','var')
                                    precipData   = [precipData; data];    %#ok<AGROW>
                                    precipLabels = [precipLabels; nodeKey]; %#ok<AGROW>
                                end
                            end

                            if ~isempty(precipData)
                                % What's selected?
                                nSel      = size(precipData,1);
                                hasIMD    = any(strcmpi(precipLabels,'imd'));
                                % Stats in window
                                p_min  = min(precipData(:,x),[],1,'omitnan');
                                p_max  = max(precipData(:,x),[],1,'omitnan');
                                p_mean = mean(precipData(:,x),1,'omitnan');
                                Pmax   = max(p_max,[],'omitnan');

                                % ---- Plot according to selection rules ----
                                if nSel == 1
                                    % Only one dataset selected
                                    ydata = precipData(1,x);
                                    if strcmpi(precipLabels{1},'imd')
                                        hold(app.UIAxes,'on');
                                        % IMD as open circles (inverted)
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','IMD Precip');
                                        plot(app.UIAxes, x, ydata, 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    elseif strcmpi(precipLabels{1},'gpcp')
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','GPCP Precip');
                                    else % ERA5
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','ERA5 Precip');
                                    end
                                elseif nSel == 2
                                    % Multiple selected
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    % Whiskers (min-max) behind mean
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end

                                    if hasIMD
                                        j = find(strcmpi(precipLabels,'imd'),1);
                                        plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    end
                                else
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    j = find(strcmpi(precipLabels,'imd'),1);
                                    plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end
                                end

                                % ---------- Dynamic Axis coordination (TWSA + Precip Gap) ----------
                                yyaxis(app.UIAxes,'left');

                                % Collect ALL TWSA data from plotted series in the selected window
                                allTWSA = [];
                                h = findall(app.UIAxes,'Type','Line'); % get plotted lines
                                for k = 1:numel(h)
                                    ydata = h(k).YData;
                                    xdata = h(k).XData;
                                    % only keep values within the sIdx:eIdx window
                                    mask = (xdata >= sIdx & xdata <= eIdx);
                                    if any(mask)
                                        allTWSA = [allTWSA, ydata(mask)];
                                    end
                                end

                                if ~isempty(allTWSA)
                                    % Dynamic limits based on selected time window
                                    twsMin = min(allTWSA, [], 'omitnan');
                                    twsMax = max(allTWSA, [], 'omitnan');
                                    disp(twsMax)
                                    % Add padding for readability
                                    %pad = 0.15 * (twsMax - twsMin);
                                    twsLimits = [twsMin , twsMax];% + pad];


                                    % Apply limits to left axis
                                    ylim(app.UIAxes, twsLimits);

                                    % Dynamic ticks
                                    step = round((twsMax - twsMin) / 5);
                                    if step == 0, step = 1; end
                                    app.UIAxes.YTick = round(twsMin):step:round(twsMax);

                                    % -------- Right axis (precipitation, inverted, with gap) --------
                                    yyaxis(app.UIAxes,'right');
                                    Pmax = max(p_max,[],'omitnan');
                                    gap  = 1.2 * (twsLimits(2) - twsLimits(1)); % proportional gap
                                    ylim(app.UIAxes, [0 Pmax + gap]);
                                    yticks(app.UIAxes, 0:round(Pmax/5):Pmax);
                                end
                            end
                            % Ensure grid and axis font
                            grid(app.UIAxes, 'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 18* scale;;

                            % Axis labels
                            app.UIAxes.XLabel.String = 'Time (Years)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18* scale;;
                            % Create legend AFTER plotting
                            lg = legend(app.UIAxes, 'show');
                            % Legend configuration
                            % if exist('newWidth','var') && ~isempty(newWidth)
                            %     lg.NumColumns = min(9, floor(newWidth/150));
                            % else
                            lg.NumColumns = 9; % fallback
                            % end
                            lg.Location    = 'southoutside';
                            lg.Orientation = 'horizontal';
                            lg.Box         = 'on';
                            lg.Interpreter = 'none';
                            lg.AutoUpdate  = 'off';
                            lg.FontName    = 'Times New Roman';
                            lg.FontWeight  = 'bold';

                            if exist('scale','var') && ~isempty(scale)
                                lg.FontSize = 10* scale;
                            else
                                lg.FontSize = 12; % fallback
                            end

                            hold(app.UIAxes, 'off');
                        case 'Decadal'
                            cla(app.UIAxes, 'reset');
                            hold(app.UIAxes,'on'); % clear axes before plotting anything
                            % Define the starting and ending years for the slider scale
                            startYr = 1901;
                            endYr = 2025;
                            allYrs = startYr:endYr;
                            % Get selected time range from slider
                            sIdx = round(app.TimeSpan19012025Slider.Value(1));
                            eIdx = round(app.TimeSpan19012025Slider.Value(2));
                            sIdx = max(sIdx,1);
                            eIdx = min(eIdx, numel(allYrs));
                            selectedTimeRange = allYrs(sIdx:eIdx);

                            % Calculate decadal bins for the selected time range
                            tStart = selectedTimeRange(1);
                            tEnd = selectedTimeRange(end);

                            % Create decade bins (1901-1910, 1911-1920, etc.)
                            decadeStarts = floor(tStart/10)*10+1:10:floor(tEnd/10)*10+1;
                            if isempty(decadeStarts)
                                decadeStarts = tStart;
                            end

                            % Calculate decade centers for plotting
                            decadeCenters = decadeStarts + 4.5; % Middle of the decade
                            x = 1:numel(decadeStarts);

                            % Apply ticks and labels
                            app.UIAxes.XTick = x;
                            decadeLabels = cell(1, numel(decadeStarts));
                            for i = 1:numel(decadeStarts)
                                decadeEnd = min(decadeStarts(i) + 9, tEnd);
                                decadeLabels{i} = sprintf('%d-%d', decadeStarts(i), decadeEnd);
                            end
                            app.UIAxes.XTickLabel = decadeLabels;

                            % Set axis limits
                            xlim(app.UIAxes, [0.5 numel(decadeStarts)+0.5]);

                            timescales = app.TimescaleDropDown.Value;
                            legendItems = {};

                            %% ==================== RECONSTRUCTED TREE ====================
                            selectedNodes = app.RTWSA.CheckedNodes;
                            % Colors for different reconstructions (normalized keys)
                            colors = struct('hg19', [0.0 0.60 0.50], ...   % teal
                                'p25',[0.95 0.60 0.20], ...  % golden orange
                                'l21',       [0.35 0.70 0.90], ...  % light blue
                                'd23',     [0.90 0.10 0.15], ...  % red
                                'm25',   [0.60 0.40 0.20]);     % brown

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % clean up
                                nodeKey = lower(rawName); % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% HUMPHREY (6 datasets)
                                    case 'hg19'
                                        Hp_REC = NaN(6,1500);
                                        Hp_REC(1,[app.Hp_GERECMN]) = mean(app.Hp_GERECData,1);
                                        Hp_REC(2,[app.Hp_GGRECMN]) = mean(app.Hp_GGRECData,1);
                                        Hp_REC(3,[app.Hp_GMRECMN]) = mean(app.Hp_GMRECData,1);
                                        Hp_REC(4,[app.Hp_JERECMN]) = mean(app.Hp_JERECData,1);
                                        Hp_REC(5,[app.Hp_JGRECMN]) = mean(app.Hp_JGRECData,1);
                                        Hp_REC(6,[app.Hp_JMRECMN]) = mean(app.Hp_JMRECData,1);

                                        % Convert monthly to decadal mean
                                        Hp_REC_decadal = NaN(6, numel(decadeStarts));
                                        for datasetIdx = 1:6
                                            monthly_data = Hp_REC(datasetIdx,:);
                                            for decadeIdx = 1:numel(decadeStarts)
                                                startYear = decadeStarts(decadeIdx);
                                                endYear = min(startYear + 9, tEnd);
                                                % Convert years to month indices
                                                startMonth = (startYear - startYr) * 12 + 1;
                                                endMonth = (endYear - startYr + 1) * 12;
                                                % Ensure we don't exceed data bounds
                                                startMonth = max(startMonth, 1);
                                                endMonth = min(endMonth, length(monthly_data));

                                                decade_data = monthly_data(startMonth:endMonth);
                                                if any(~isnan(decade_data))
                                                    Hp_REC_decadal(datasetIdx,decadeIdx) = mean(decade_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Hp_REC_decadal, [], 1,'omitnan');
                                            max(Hp_REC_decadal, [], 1,'omitnan');
                                            mean(Hp_REC_decadal, 1,'omitnan')
                                            ];

                                        %% PALAZZOLI (4 datasets)
                                    case 'p25'
                                        Pl_REC = NaN(4,1500);
                                        Pl_REC(1,[app.PalazzoliLSTMMN])        = 10*mean(app.PalazzoliLSTMData,1);
                                        Pl_REC(2,[app.PalazzoliLSTMnoLCSIFMN]) = 10*mean(app.PalazzoliLSTMnoLCSIFData,1);
                                        Pl_REC(3,[app.PalazzoliBiLSTMMN])      = 10*mean(app.PalazzoliBiLSTMData,1);
                                        Pl_REC(4,[app.PalazzoliBiLSTMnoLCSIFMN]) = 10*mean(app.PalazzoliBiLSTMnoLCSIFData,1);

                                        % Convert monthly to decadal mean
                                        Pl_REC_decadal = NaN(4, numel(decadeStarts));
                                        for datasetIdx = 1:4
                                            monthly_data = Pl_REC(datasetIdx,:);
                                            for decadeIdx = 1:numel(decadeStarts)
                                                startYear = decadeStarts(decadeIdx);
                                                endYear = min(startYear + 9, tEnd);
                                                % Convert years to month indices
                                                startMonth = (startYear - startYr) * 12 + 1;
                                                endMonth = (endYear - startYr + 1) * 12;
                                                % Ensure we don't exceed data bounds
                                                startMonth = max(startMonth, 1);
                                                endMonth = min(endMonth, length(monthly_data));

                                                decade_data = monthly_data(startMonth:endMonth);
                                                if any(~isnan(decade_data))
                                                    Pl_REC_decadal(datasetIdx,decadeIdx) = mean(decade_data, 'omitnan');
                                                end
                                            end
                                        end

                                        REC = [
                                            min(Pl_REC_decadal, [], 1,'omitnan');
                                            max(Pl_REC_decadal, [], 1,'omitnan');
                                            mean(Pl_REC_decadal, 1,'omitnan')
                                            ];

                                        %% LI (single)
                                    case 'l21'
                                        Li_BA = 10*mean(app.LiRECData,1);
                                        Li_REC = NaN(1,1500);
                                        Li_REC(1,[app.LiRECMN]) = Li_BA;

                                        % Convert monthly to decadal mean
                                        Li_REC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Li_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Li_REC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Li_REC_decadal,3,1);

                                        %% DENG (single)
                                    case 'd23'
                                        Deng_BA = mean(app.DengRECData,1);
                                        Deng_REC = NaN(1,1500);
                                        Deng_REC(1,[app.DengRECMN]) = Deng_BA;

                                        % Convert monthly to decadal mean
                                        Deng_REC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Deng_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Deng_REC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Deng_REC_decadal,3,1);

                                        %% MANDAL (single)
                                    case 'm25'
                                        Mandal_BA = mean(app.MandalRECData,1);
                                        Mandal_REC = NaN(1,1500);
                                        Mandal_REC(1,[app.MandalRECMN]) = Mandal_BA;

                                        % Convert monthly to decadal mean
                                        Mandal_REC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Mandal_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Mandal_REC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Mandal_REC_decadal,3,1);
                                end

                                % Pick color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                % Fill band (flat since single dataset)
                                hold(app.UIAxes, 'on');

                                if strcmp(nodeKey, 'hg19') || strcmp(nodeKey, 'p25')
                                    % Plot filled area
                                    fill(app.UIAxes, [x fliplr(x)], [REC(2, x) fliplr(REC(1, x))],c, 'FaceAlpha', 0.2, 'EdgeColor', 'none','DisplayName', strcat(rawName," Range"));
                                end

                                % Plot main line
                                plot(app.UIAxes, x, REC(3, x), 'Color', c, 'LineWidth',1.5,'LineStyle','--','DisplayName', strcat(rawName));

                            end

                            % Get selected GRACE datasets
                            selectedNodes = app.TWSA.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for GRACE datasets (normalized keys)
                            colors = struct( ...
                                'jpl',  [0.2 0.2 0.2], ...  % dark gray
                                'csr',  [0.5 0.5 0.5], ...  % medium gray
                                'gsfc', [0.8 0.8 0.8]);     % light gray
                            markers= struct( ...
                                'jpl',  "o", ...  % dark gray
                                'csr',  "+", ...  % medium gray
                                'gsfc', '*' );     % light gray

                            for i = 1:numel(selectedNodes)
                                % Skip non-leaf nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                                nodeKey = lower(rawName);
                                REC = [];
                                switch nodeKey
                                    case 'jpl'
                                        JPL_BA = 10*mean(app.JPLTWSData,1);
                                        JPL_TWSA = NaN(1,1500);
                                        JPL_TWSA(1,[app.JPLTWSMN]) = JPL_BA;
                                        % Convert monthly to decadal mean
                                        JPL_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = JPL_TWSA(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                JPL_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(JPL_decadal,3,1);

                                    case 'csr'
                                        CSR_BA = 10*mean(app.CSRTWSData,1);
                                        CSR_TWSA = NaN(1,1500);
                                        CSR_TWSA(1,[app.CSRTWSMN]) = CSR_BA;
                                        % Convert monthly to decadal mean
                                        CSR_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = CSR_TWSA(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                CSR_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(CSR_decadal,3,1);

                                    case 'gsfc'
                                        GSFC_BA = 10*mean(app.GFSCTWSData,1);
                                        GSFC_TWSA = NaN(1,1500);
                                        GSFC_TWSA(1,[app.GFSCTWSMN]) = GSFC_BA;
                                        % Convert monthly to decadal mean
                                        GSFC_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = GSFC_TWSA(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                GSFC_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(GSFC_decadal,3,1);

                                    otherwise
                                        disp(['[TWSA] Skipping unhandled node: "', rawName, '"']);
                                        continue;
                                end

                                if isempty(REC), continue; end
                                x_plot = 1:size(REC,2);

                                % Choose dataset color (fallback if missing)
                                if isfield(colors, nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5];
                                end

                                if isfield(markers, nodeKey)
                                    d = markers.(nodeKey);
                                else
                                    d = 'square';
                                end

                                plot(app.UIAxes, x_plot, REC(3,x_plot), 'Color', c, 'LineWidth', 2,'Marker',d, 'DisplayName', strcat(upper(nodeKey)));
                            end

                            %% ==================== THIS STUDY TREE ====================
                            selectedNodes = app.This_Study.CheckedNodes;
                            hold(app.UIAxes,'on');
                            % Colors for each of your models
                            colors = struct( ...
                                'rf_imd',     [0.0 0.45 0.70], ...   % strong blue
                                'ann_g',      [0.85 0.33 0.10], ...  % bright orange
                                'ann_imd',     [0.93 0.69 0.13], ...  % yellow
                                'rf_g',       [0.49 0.18 0.56]);     % purple

                            for i = 1:numel(selectedNodes)
                                % Skip parent nodes
                                if ~isempty(selectedNodes(i).Children), continue; end

                                rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                                nodeKey = lower(rawName);                 % normalize to lowercase

                                REC = [];
                                switch nodeKey
                                    %% BEST
                                    case 'rf_imd'
                                        Best_BA = mean(app.ThisStudyBestData,1);
                                        Best_REC = NaN(1,1500);
                                        Best_REC(1,[app.ThisStudyBestMN]) = Best_BA;
                                        % Convert monthly to decadal mean
                                        Best_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = Best_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                Best_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(Best_decadal,3,1);

                                        %% ANN
                                    case 'ann_g'
                                        ANN_BA = mean(app.ThisStudyANNData,1);
                                        ANN_REC = NaN(1,1500);
                                        ANN_REC(1,[app.ThisStudyANNMN]) = ANN_BA;
                                        % Convert monthly to decadal mean
                                        ANN_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = ANN_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                ANN_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(ANN_decadal,3,1);

                                        %% LSTM
                                    case 'ann_imd'
                                        LSTM_BA = mean(app.ThisStudyLSTMData,1);
                                        LSTM_REC = NaN(1,1500);
                                        LSTM_REC(1,[app.ThisStudyLSTMMN]) = LSTM_BA;
                                        % Convert monthly to decadal mean
                                        LSTM_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = LSTM_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                LSTM_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(LSTM_decadal,3,1);

                                        %% RF
                                    case 'rf_g'
                                        RF_BA = mean(app.ThisStudyRFData,1);
                                        RF_REC = NaN(1,1500);
                                        RF_REC(1,[app.ThisStudyRFMN]) = RF_BA;
                                        % Convert monthly to decadal mean
                                        RF_decadal = NaN(1, numel(decadeStarts));
                                        monthly_data = RF_REC(1,:);
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(monthly_data));

                                            decade_data = monthly_data(startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                RF_decadal(1,decadeIdx) = mean(decade_data, 'omitnan');
                                            end
                                        end
                                        REC = repmat(RF_decadal,3,1);
                                end

                                if isempty(REC), continue; end
                                % Pick dataset color safely
                                if isfield(colors,nodeKey)
                                    c = colors.(nodeKey);
                                else
                                    c = [0.5 0.5 0.5]; % fallback gray
                                end

                                hold(app.UIAxes,'on');

                                % Mean line
                                plot(app.UIAxes, x, REC(3,x), 'Color', c, 'LineWidth',1.5,'DisplayName', strcat(rawName));

                            end

                            % Axes settings
                            grid(app.UIAxes,'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 14;
                            app.UIAxes.XLabel.String = 'Time (Decades)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18;
                            app.UIAxes.YLabel.String = '(R)TWSA (mm)';   % left axis label
                            app.UIAxes.YLabel.FontName = 'Times New Roman';
                            app.UIAxes.YLabel.FontWeight = 'bold';
                            app.UIAxes.YLabel.FontSize = 18;

                            hold(app.UIAxes,'off');

                            %% ==================== PRECIPITATION (inverted, on right axis, with gap to TWS) ====================
                            selectedNodes = app.Precipitation.CheckedNodes;
                            yyaxis(app.UIAxes,'right');
                            yl = ylabel(app.UIAxes,'Precipitation (mm)');  % get handle
                            yl.FontSize = 18;
                            yl.FontName = 'Times New Roman';
                            yl.FontWeight = 'bold';
                            set(app.UIAxes,'YDir','reverse');  % invert the axis so bars grow downward

                            precipData = []; precipLabels = {};
                            for i = 1:numel(selectedNodes)
                                if ~isempty(selectedNodes(i).Children), continue; end
                                rawName = strtrim(selectedNodes(i).Text);
                                nodeKey = lower(rawName);
                                clear data

                                switch nodeKey
                                    case 'imd'
                                        % IMD (already in mm monthly) - convert to decadal mean annual precipitation
                                        app.IMD_BA = mean(app.IMDData,1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.IMDMN) = app.IMD_BA;
                                        % Convert to decadal mean annual precipitation
                                        data = NaN(1, numel(decadeStarts));
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(data_monthly));

                                            decade_data = data_monthly(1,startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                % Calculate mean annual precipitation for the decade
                                                data(1,decadeIdx) = mean(decade_data, 'omitnan') * 12; % Convert monthly mean to annual equivalent
                                            end
                                        end

                                    case 'gpcp'
                                        % GPCP (mm/month) - convert to decadal mean annual precipitation
                                        app.GPCP_BA = mean(app.GPCPData,1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.GPCPMN) = app.GPCP_BA;
                                        % Convert to decadal mean annual precipitation
                                        data = NaN(1, numel(decadeStarts));
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(data_monthly));

                                            decade_data = data_monthly(1,startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                % Calculate mean annual precipitation for the decade
                                                data(1,decadeIdx) = mean(decade_data, 'omitnan') * 12; % Convert monthly mean to annual equivalent
                                            end
                                        end

                                    case 'era5'
                                        % ERA5 'tp' is meters; convert to mm and then decadal mean annual precipitation
                                        app.ERA5_BA = mean(app.ERA5Data,1);
                                        data_monthly = NaN(1,1500); data_monthly(1,app.ERA5MN) = app.ERA5_BA;
                                        % Convert to decadal mean annual precipitation
                                        data = NaN(1, numel(decadeStarts));
                                        for decadeIdx = 1:numel(decadeStarts)
                                            startYear = decadeStarts(decadeIdx);
                                            endYear = min(startYear + 9, tEnd);
                                            % Convert years to month indices
                                            startMonth = (startYear - startYr) * 12 + 1;
                                            endMonth = (endYear - startYr + 1) * 12;
                                            % Ensure we don't exceed data bounds
                                            startMonth = max(startMonth, 1);
                                            endMonth = min(endMonth, length(data_monthly));

                                            decade_data = data_monthly(1,startMonth:endMonth);
                                            if any(~isnan(decade_data))
                                                % Calculate mean annual precipitation for the decade
                                                data(1,decadeIdx) = mean(decade_data, 'omitnan') * 12; % Convert monthly mean to annual equivalent
                                            end
                                        end
                                end

                                if exist('data','var')
                                    precipData   = [precipData; data];    %#ok<AGROW>
                                    precipLabels = [precipLabels; nodeKey]; %#ok<AGROW>
                                end
                            end

                            if ~isempty(precipData)
                                % What's selected?
                                nSel      = size(precipData,1);
                                hasIMD    = any(strcmpi(precipLabels,'imd'));
                                % Stats in window
                                p_min  = min(precipData(:,x),[],1,'omitnan');
                                p_max  = max(precipData(:,x),[],1,'omitnan');
                                p_mean = mean(precipData(:,x),1,'omitnan');
                                Pmax   = max(p_max,[],'omitnan');

                                % ---- Plot according to selection rules ----
                                if nSel == 1
                                    % Only one dataset selected
                                    ydata = precipData(1,x);
                                    if strcmpi(precipLabels{1},'imd')
                                        hold(app.UIAxes,'on');
                                        % IMD as open circles (inverted)
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','IMD Precip');
                                        plot(app.UIAxes, x, ydata, 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    elseif strcmpi(precipLabels{1},'gpcp')
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','GPCP Precip');
                                    else % ERA5
                                        bar(app.UIAxes, x, ydata, 0.4, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','ERA5 Precip');
                                    end
                                elseif nSel == 2
                                    % Multiple selected
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    % Whiskers (min-max) behind mean
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end

                                    if hasIMD
                                        j = find(strcmpi(precipLabels,'imd'),1);
                                        plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    end
                                else
                                    hold(app.UIAxes,'on');
                                    bar(app.UIAxes, x, p_mean, 0.45, 'FaceColor','none','EdgeColor',[0.2 0.7 0.9],'LineWidth',1.2, 'DisplayName','Mean Precip');
                                    j = find(strcmpi(precipLabels,'imd'),1);
                                    plot(app.UIAxes, x, precipData(j,x), 'o', 'Color','k', 'MarkerFaceColor','w','MarkerSize',4,'DisplayName','IMD Precip');
                                    legendAdded = false;

                                    for k = 1:numel(x)
                                        if ~legendAdded
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'DisplayName','Precip Range');
                                            legendAdded = true;
                                        else
                                            line(app.UIAxes, [x(k) x(k)], [p_max(k) p_min(k)],'Color',[0.35 0.35 0.35], 'LineWidth',1.0,'LineStyle','-', 'Marker','none', 'HandleVisibility','off');
                                        end
                                    end
                                end

                                % ---------- Dynamic Axis coordination (TWSA + Precip Gap) ----------
                                yyaxis(app.UIAxes,'left');

                                % Collect ALL TWSA data from plotted series in the selected window
                                allTWSA = [];
                                h = findall(app.UIAxes,'Type','Line'); % get plotted lines
                                for k = 1:numel(h)
                                    ydata = h(k).YData;
                                    xdata = h(k).XData;
                                    % only keep values within the x window
                                    mask = (xdata >= min(x) & xdata <= max(x));
                                    if any(mask)
                                        allTWSA = [allTWSA, ydata(mask)];
                                    end
                                end

                                if ~isempty(allTWSA)
                                    % Dynamic limits based on selected time window
                                    twsMin = min(allTWSA, [], 'omitnan');
                                    twsMax = max(allTWSA, [], 'omitnan');
                                    % Add padding for readability
                                    pad = 0.15 * (twsMax - twsMin);
                                    twsLimits = [twsMin - pad, twsMax + pad];

                                    % Apply limits to left axis
                                    ylim(app.UIAxes, twsLimits);

                                    % Dynamic ticks
                                    step = round((twsMax - twsMin) / 5);
                                    if step == 0, step = 1; end
                                    app.UIAxes.YTick = round(twsMin):step:round(twsMax);

                                    % -------- Right axis (precipitation, inverted, with gap) --------
                                    yyaxis(app.UIAxes,'right');
                                    Pmax = max(p_max,[],'omitnan');
                                    gap  = 1.2 * (twsLimits(2) - twsLimits(1)); % proportional gap
                                    ylim(app.UIAxes, [0 Pmax + gap]);
                                    yticks(app.UIAxes, 0:round(Pmax/5):Pmax);
                                end
                            end

                            % Ensure grid and axis font
                            grid(app.UIAxes, 'on');
                            app.UIAxes.FontName = 'Times New Roman';
                            app.UIAxes.FontSize = 18* scale;;

                            % Axis labels
                            app.UIAxes.XLabel.String = 'Time (Decades)';
                            app.UIAxes.XLabel.FontName = 'Times New Roman';
                            app.UIAxes.XLabel.FontWeight = 'bold';
                            app.UIAxes.XLabel.FontSize = 18* scale;;

                            % Create legend AFTER plotting
                            lg = legend(app.UIAxes, 'show');
                            % Legend configuration
                            lg.NumColumns = 9; % fallback
                            lg.Location    = 'southoutside';
                            lg.Orientation = 'horizontal';
                            lg.Box         = 'on';
                            lg.Interpreter = 'none';
                            lg.AutoUpdate  = 'off';
                            lg.FontName    = 'Times New Roman';
                            lg.FontWeight  = 'bold';

                            if exist('scale','var') && ~isempty(scale)
                                lg.FontSize = 10* scale;
                            else
                                lg.FontSize = 12; % fallback
                            end

                            hold(app.UIAxes, 'off');

                    end

                case 'Spatial'
                    %% ==================== INITIALIZE ====================
                    cla(app.UIAxes,'reset');
                    hold(app.UIAxes,'on');

                    % Time axis (master monthly line: Jan-1901 ... Dec-2025)
                    startDate   = datetime(1901,1,1);
                    endDate     = datetime(2025,12,1);
                    allTimeDates = startDate:calmonths(1):endDate;  % length = 1500

                    % Slider-selected window
                    sIdx = round(app.TimeSpan19012025Slider.Value(1));
                    eIdx = round(app.TimeSpan19012025Slider.Value(2));
                    sIdx = max(sIdx,1);
                    eIdx = min(eIdx, numel(allTimeDates));

                    % Numeric time in **years** from window start (used for slopes in mm/yr)
                    winDates = allTimeDates(sIdx:eIdx);
                    t = years(winDates - winDates(1));  % column t has units of years
                    x= sIdx:eIdx;
                    % Retrieve the selected basin
                    selectedBasin = app.BasinSelectionButton.Value;
                    % Load shapefile data
                    basins = app.Basins;

                    % Find the polygon corresponding to the selected basin
                    basinPolygon = [];
                    for i = 1:length(basins)
                        if strcmpi(basins(i).RIVER_BASI, selectedBasin)
                            basinPolygon = [basins(i).X', basins(i).Y']; % Extract polygon coordinates
                            break;
                        end
                    end

                    % Clean polygon (remove NaNs)
                    basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);

                    % if isempty(basinPolygon)
                    %     uialert(app.UIFigure, ...
                    %         {'Please select a basin.', ...
                    %          'If selected, please ensure the selected', ...
                    %          'basin is available in the shapefile.'}, ...
                    %         'Warning', 'Icon','warning');
                    %     return;  % <<< Stop execution here
                    % end
                    % ==================== SELECTION VALIDATION ====================
                    % Collect all selected leaf nodes across all categories
                    selectedRTWSA = app.RTWSA.CheckedNodes;
                    selectedTWSA  = app.TWSA.CheckedNodes;
                    selectedThis  = app.This_Study.CheckedNodes;

                    % Combine only leaf nodes (no parents)
                    allSelected = [selectedRTWSA(~arrayfun(@(n) ~isempty(n.Children), selectedRTWSA)); ...
                        selectedTWSA(~arrayfun(@(n) ~isempty(n.Children), selectedTWSA)); ...
                        selectedThis(~arrayfun(@(n) ~isempty(n.Children), selectedThis))];

                    % Check how many datasets are selected
                    if numel(allSelected) > 1
                        uialert(app.UIFigure, ...
                            {'Spatial plots are available for one dataset at a time.', ...
                            'Please select only one dataset and try again.'}, ...
                            'Multiple Selections Detected', ...
                            'Icon', 'warning');
                        return;  % <<< Stop execution before any plotting
                    end

                    %% ==================== RECONSTRUCTED TREE ====================
                    selectedNodes = app.RTWSA.CheckedNodes;
                    for i = 1:numel(selectedNodes)
                        % Skip parent nodes
                        if ~isempty(selectedNodes(i).Children), continue; end
                        rawName = strtrim(selectedNodes(i).Text); % clean up
                        nodeKey = lower(rawName); % normalize to lowercase
                        switch nodeKey
                            %% HUMPHREY (6 datasets)
                            case 'hg19'
                                cla(app.UIAxes);
                                Hp_GERECmasked = app.Hp_GERECData;
                                latHp_GEREC = app.Hp_GERECLat;
                                lonHp_GEREC = app.Hp_GERECLon;
                                Hp_GEREC = NaN(size(latHp_GEREC,1),1500);
                                Hp_GEREC(:,[app.Hp_GERECMN])= Hp_GERECmasked;
                                Hp_GERECmean=mean(Hp_GEREC(:,x),2,'omitnan');
                                plotSpatialBasinMap(app, lonHp_GEREC, latHp_GEREC, Hp_GERECmean, basinPolygon, ...
                                    'Humphrey Basin Mean (selected timespan)');

                                %% PALAZZOLI (LSTM)
                            case 'p25'
                                % --- 1. Apply basin mask with coordinates ---
                                Pl_RECmasked =app.PalazzoliLSTMData;
                                latPl_REC=app.PalazzoliLSTMLat;
                                lonPl_REC=app.PalazzoliLSTMLon;

                                % Allocate container
                                Pl_REC = NaN(size(latPl_REC,1),1500);
                                Pl_REC(:,[app.PalazzoliLSTMMN]) = 10 * Pl_RECmasked;   % note the scaling factor *10

                                % Basin mean for selected time indices (x from slider)
                                Pl_RECmean = mean(Pl_REC(:,x),2,'omitnan');

                                plotSpatialBasinMap(app, lonPl_REC, latPl_REC, Pl_RECmean, basinPolygon, ...
                                    'Palazzoli Basin Mean (selected timespan)');
                                %% LI (single)
                            case 'l21'
                                % --- 1. Apply basin mask to Li data ---
                                Li_RECmasked = app.LiRECData;
                                latLi_REC = app.LiRECLat;
                                lonLi_REC = app.LiRECLon;
                                % Allocate container (like Hp_GEREC)
                                Li_REC = NaN(size(latLi_REC,1),1500);
                                Li_REC(:,[app.LiRECMN]) = 10*Li_RECmasked;

                                % Basin mean across selected time indices (x is from slider)
                                Li_RECmean = mean(Li_REC(:,x),2,'omitnan');

                                plotSpatialBasinMap(app, lonLi_REC, latLi_REC, Li_RECmean, basinPolygon, ...
                                    'Li Basin Mean (selected timespan)');

                                %% DENG (single)
                            case 'd23'
                                % --- 1. Apply basin mask with coordinates ---
                                Deng_RECmasked = app.DengRECData;
                                latDeng_REC = app.DengRECLat;
                                lonDeng_REC = app.DengRECLon;

                                % Allocate container (similar to Li/Hp)
                                Deng_REC = NaN(size(latDeng_REC,1),1500);
                                Deng_REC(:,[app.DengRECMN]) = Deng_RECmasked;

                                % Basin mean across selected time indices (x is from slider)
                                Deng_RECmean = mean(Deng_REC(:,x),2,'omitnan');
                                plotSpatialBasinMap(app, lonDeng_REC, latDeng_REC, Deng_RECmean, basinPolygon, ...
                                    'Deng Basin Mean (selected timespan)');

                                %
                                %% MANDAL (single)
                            case 'm25'
                                % --- 1. Apply basin mask with coordinates ---
                                Mandal_RECmasked = app.MandalRECData;
                                latMandal_REC = app.MandalRECLat;
                                lonMandal_REC = app.MandalRECLon;
                                % Allocate container (same style as hp/li/deng)
                                Mandal_REC = NaN(size(latMandal_REC,1),1500);
                                Mandal_REC(:,[app.MandalRECMN]) = Mandal_RECmasked;

                                % Basin mean for selected time indices (x from slider)
                                Mandal_RECmean = mean(Mandal_REC(:,x),2,'omitnan');
                                % Plot spatial map
                                plotSpatialBasinMap(app, lonMandal_REC, latMandal_REC, Mandal_RECmean, basinPolygon, ...
                                    'Mandal Basin Mean (selected timespan)');

                        end
                    end
                    % Get selected GRACE datasets
                    selectedNodes = app.TWSA.CheckedNodes;
                    hold(app.UIAxes,'on');
                    for i = 1:numel(selectedNodes)
                        % Skip non-leaf nodes
                        if ~isempty(selectedNodes(i).Children), continue; end

                        rawName = strtrim(selectedNodes(i).Text);  % remove spaces
                        nodeKey = lower(rawName);
                        switch nodeKey
                            %% JPL (mascon)
                            case 'jpl'
                                JPL_masked = app.JPLTWSData;
                                latJPL = app.JPLTWSLat;
                                lonJPL = app.JPLTWSLon;


                                JPL_REC = NaN(size(latJPL,1),1500);
                                JPL_REC(:,app.JPLTWSMN) = 10 * JPL_masked;

                                JPLmean = mean(JPL_REC(:,x),2,'omitnan');

                                % Interpolation + plotting
                                plotSpatialBasinMap(app, lonJPL, latJPL, JPLmean, basinPolygon, ...
                                    'JPL Mascon Basin Mean (selected timespan)');

                                %% CSR (mascon)
                            case 'csr'
                                CSR_masked = app.CSRTWSData;
                                latCSR = app.CSRTWSLat;
                                lonCSR = app.CSRTWSLon;
                                CSR_REC = NaN(size(latCSR,1),1500);
                                CSR_REC(:,app.CSRTWSMN) = 10 * CSR_masked;

                                CSRmean = mean(CSR_REC(:,x),2,'omitnan');

                                % Interpolation + plotting
                                plotSpatialBasinMap(app, lonCSR, latCSR, CSRmean, basinPolygon, ...
                                    'CSR Mascon Basin Mean (selected timespan)');

                                %% GSFC (mascon)
                            case 'gsfc'
                                GSFC_masked = app.GFSCTWSData;
                                latGSFC = app.GFSCTWSLat;
                                lonGSFC = app.GFSCTWSLon;
                                GSFC_REC = NaN(size(latGSFC,1),1500);
                                GSFC_REC(:,app.GFSCTWSMN) = 10 * GSFC_masked;

                                GSFCmean = mean(GSFC_REC(:,x),2,'omitnan');

                                % Interpolation + plotting
                                plotSpatialBasinMap(app, lonGSFC, latGSFC, GSFCmean, basinPolygon, ...
                                    'GSFC Mascon Basin Mean (selected timespan)');
                        end
                    end
                    selectedNodes = app.This_Study.CheckedNodes;
                    hold(app.UIAxes,'on');

                    for i = 1:numel(selectedNodes)
                        % Skip parent nodes
                        if ~isempty(selectedNodes(i).Children), continue; end

                        rawName = strtrim(selectedNodes(i).Text); % "Best", "ANN", etc.
                        nodeKey = lower(rawName);                 % normalize to lowercase
                        switch nodeKey
                            %% BEST
                            case 'rf_imd'
                                Best_masked = app.ThisStudyBestData;
                                latBest = app.ThisStudyBestLat;
                                lonBest = app.ThisStudyBestLon;

                                Best_REC = NaN(size(latBest,1),1500);
                                Best_REC(:,app.ThisStudyBestMN) = Best_masked;

                                BestMean = mean(Best_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonBest, latBest, BestMean, basinPolygon, ...
                                    'BEST Basin Mean (selected timespan)');

                                %% ANN
                            case 'ann_g'
                                ANN_masked = app.ThisStudyANNData;
                                latANN = app.ThisStudyANNLat;
                                lonANN = app.ThisStudyANNLon;


                                ANN_REC = NaN(size(latANN,1),1500);
                                ANN_REC(:,app.ThisStudyANNMN) = ANN_masked;

                                ANNMean = mean(ANN_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonANN, latANN, ANNMean, basinPolygon, ...
                                    'ANN Basin Mean (selected timespan)');

                                %% LSTM
                            case 'ann_imd'
                                LSTM_masked = app.ThisStudyLSTMData;
                                latLSTM = app.ThisStudyLSTMLat;
                                lonLSTM = app.ThisStudyLSTMLon;

                                LSTM_REC = NaN(size(latLSTM,1),1500);
                                LSTM_REC(:,app.ThisStudyLSTMMN) = LSTM_masked;

                                LSTMMean = mean(LSTM_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonLSTM, latLSTM, LSTMMean, basinPolygon, ...
                                    'LSTM Basin Mean (selected timespan)');

                                %% RF
                            case 'rf_g'
                                RF_masked = app.ThisStudyRFData;
                                latRF = app.ThisStudyRFLat;
                                lonRF = app.ThisStudyRFLon;

                                RF_REC = NaN(size(latRF,1),1500);
                                RF_REC(:,app.ThisStudyRFMN) = RF_masked;

                                RFMean = mean(RF_REC(:,x),2,'omitnan');

                                % Plot spatial map
                                plotSpatialBasinMap(app, lonRF, latRF, RFMean, basinPolygon, ...
                                    'RF Basin Mean (selected timespan)');
                        end
                    end
                case 'Regional Mean'
                    cla(app.UIAxes, 'reset');
                    hold(app.UIAxes, 'on');

                    % Check if more than one dataset is selected
                    totalSelected = 0;

                    % Count selected nodes from all sections
                    selectedNodesRTWSA = app.RTWSA.CheckedNodes;
                    for i = 1:numel(selectedNodesRTWSA)
                        if isempty(selectedNodesRTWSA(i).Children)
                            totalSelected = totalSelected + 1;
                        end
                    end

                    selectedNodesTWSA = app.TWSA.CheckedNodes;
                    for i = 1:numel(selectedNodesTWSA)
                        if isempty(selectedNodesTWSA(i).Children)
                            totalSelected = totalSelected + 1;
                        end
                    end

                    selectedNodesThisStudy = app.This_Study.CheckedNodes;
                    for i = 1:numel(selectedNodesThisStudy)
                        if isempty(selectedNodesThisStudy(i).Children)
                            totalSelected = totalSelected + 1;
                        end
                    end

                    % If more than one dataset selected, show warning and return
                    if totalSelected > 1
                        uialert(app.UIFigure, ...
                            'Please check only one dataset at a time for Regional Mean plot.', ...
                            'Multiple Datasets Selected', 'Icon', 'warning');
                        hold(app.UIAxes, 'off');
                        return;
                    end

                    % Define the starting and ending dates for the slider scale
                    startDate = datetime(1901, 1, 1); % Start date (01-1901)
                    endDate = datetime(2025, 12, 1);  % End date (12-2025)
                    % Generate all months between the start and end dates
                    allTimeDates = startDate:calmonths(1):endDate;
                    % Get selected time range from slider
                    sIdx = round(app.TimeSpan19012025Slider.Value(1));
                    eIdx = round(app.TimeSpan19012025Slider.Value(2));
                    sIdx = max(sIdx,1);
                    eIdx = min(eIdx, numel(allTimeDates));
                    selectedTimeRange = allTimeDates(sIdx:eIdx);
                    x = sIdx:eIdx;
                    % Get start & end years of current visible time range
                    tStart = year(selectedTimeRange(1));
                    tEnd   = year(selectedTimeRange(end));
                    xlim(app.UIAxes, [sIdx eIdx]);
                    % Calculate total span in years
                    spanYears = tEnd - tStart;

                    % Choose a step size dynamically (prevent overlap)
                    if spanYears > 40
                        step = 10;   % e.g., large range → coarser ticks
                    elseif spanYears > 20
                        step = 5;
                    elseif spanYears > 10
                        step = 2;
                    else
                        step = 1;    % short range → finer ticks
                    end

                    % Define ticks
                    tickYears = tStart:step:tEnd;

                    % % Axis ticks every 2 years
                    % tickYears = year(selectedTimeRange(1)) : 2 : year(selectedTimeRange(end));
                    xt = datetime(tickYears, 1, 1);
                    % Convert datetime to indices relative to allTimeDates
                    [~, idxTicks] = ismember(xt, allTimeDates);
                    % Apply ticks and labels
                    app.UIAxes.XTick = idxTicks;
                    app.UIAxes.XTickLabel = cellstr(datestr(xt, 'yyyy'));

                    % Initialize variables for regional plot
                    regionalData = [];
                    regionalLats = [];
                    datasetName = '';

                    %% ==================== PROCESS RECONSTRUCTED TREE DATA ====================
                    selectedNodes = app.RTWSA.CheckedNodes;
                    for i = 1:numel(selectedNodes)
                        if ~isempty(selectedNodes(i).Children), continue; end

                        rawName = strtrim(selectedNodes(i).Text);
                        nodeKey = lower(rawName);

                        switch nodeKey
                            %% HUMPHREY (6 datasets)
                            case 'hg19'
                                Hp_GERECmasked = app.Hp_GERECData;
                                latHp_GEREC = app.Hp_GERECLat;
                                lonHp_GEREC = app.Hp_GERECLon;

                                if ~isempty(Hp_GERECmasked)
                                    % Create full timeline container
                                    Hp_GEREC = NaN(size(latHp_GEREC,1), 1500);
                                    Hp_GEREC(:, app.Hp_GERECMN) = Hp_GERECmasked;

                                    % Extract selected time range
                                    Hp_GEREC_selected = Hp_GEREC(:, x);

                                    % Group by latitude and compute regional mean (latitude-time matrix)
                                    [uniqueLats, ~, idx] = unique(latHp_GEREC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latHp_GEREC == uniqueLats(j));
                                        % Average over all points at this latitude (regional mean)
                                        regionalMeanData(j, :) = mean(Hp_GEREC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Humphrey et al. 2019';
                                end

                                %% PALAZZOLI (LSTM)
                            case 'p25'
                                Pl_RECmasked =app.PalazzoliLSTMData;
                                latPl_REC=app.PalazzoliLSTMLat;
                                lonPl_REC=app.PalazzoliLSTMLon;
                                if ~isempty(Pl_RECmasked)
                                    Pl_REC = NaN(size(latPl_REC,1), 1500);
                                    Pl_REC(:, app.PalazzoliLSTMMN) = 10 * Pl_RECmasked;

                                    Pl_REC_selected = Pl_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latPl_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latPl_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Pl_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Palazzoli et al. 2025';
                                end

                                %% LI (single)
                            case 'l21'
                                Li_RECmasked = app.LiRECData;
                                latLi_REC = app.LiRECLat;
                                lonLi_REC = app.LiRECLon;
                                if ~isempty(Li_RECmasked)
                                    Li_REC = NaN(size(latLi_REC,1), 1500);
                                    Li_REC(:, app.LiRECMN) = 10 * Li_RECmasked;

                                    Li_REC_selected = Li_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latLi_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latLi_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Li_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Li et al. 2021';
                                end

                                %% DENG (single)
                            case 'd23'
                                Deng_RECmasked = app.DengRECData;
                                latDeng_REC = app.DengRECLat;
                                lonDeng_REC = app.DengRECLon;

                                if ~isempty(Deng_RECmasked)
                                    Deng_REC = NaN(size(latDeng_REC,1), 1500);
                                    Deng_REC(:, app.DengRECMN) = Deng_RECmasked;

                                    Deng_REC_selected = Deng_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latDeng_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latDeng_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Deng_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Deng et al. 2023';
                                end

                                %% MANDAL (single)
                            case 'm25'
                                Mandal_RECmasked = app.MandalRECData;
                                latMandal_REC = app.MandalRECLat;
                                lonMandal_REC = app.MandalRECLon;
                                if ~isempty(Mandal_RECmasked)
                                    Mandal_REC = NaN(size(latMandal_REC,1), 1500);
                                    Mandal_REC(:, app.MandalRECMN) = Mandal_RECmasked;

                                    Mandal_REC_selected = Mandal_REC(:, x);

                                    [uniqueLats, ~, idx] = unique(latMandal_REC);
                                    regionalMeanData = zeros(length(uniqueLats), length(x));

                                    for j = 1:length(uniqueLats)
                                        latMask = (latMandal_REC == uniqueLats(j));
                                        regionalMeanData(j, :) = mean(Mandal_REC_selected(latMask, :), 1, 'omitnan');
                                    end

                                    regionalData = regionalMeanData;
                                    regionalLats = uniqueLats;
                                    datasetName = 'Mandal et al. 2025';
                                end
                        end

                        if ~isempty(regionalData)
                            break; % Only process first selected dataset
                        end
                    end

                    %% ==================== PROCESS GRACE DATA ====================
                    if isempty(regionalData)
                        selectedNodes = app.TWSA.CheckedNodes;
                        for i = 1:numel(selectedNodes)
                            if ~isempty(selectedNodes(i).Children), continue; end

                            rawName = strtrim(selectedNodes(i).Text);
                            nodeKey = lower(rawName);

                            switch nodeKey
                                %% JPL (mascon)
                                case 'jpl'
                                    JPL_masked = app.JPLTWSData;
                                    latJPL = app.JPLTWSLat;
                                    lonJPL = app.JPLTWSLon;
                                    if ~isempty(JPL_masked)
                                        JPL_REC = NaN(size(latJPL,1), 1500);
                                        JPL_REC(:, app.JPLTWSMN) = 10 * JPL_masked;

                                        JPL_selected = JPL_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latJPL);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latJPL == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(JPL_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'JPL Mascon';
                                    end

                                    %% CSR (mascon)
                                case 'csr'
                                    CSR_masked = app.CSRTWSData;
                                    latCSR = app.CSRTWSLat;
                                    lonCSR = app.CSRTWSLon;

                                    if ~isempty(CSR_masked)
                                        CSR_REC = NaN(size(latCSR,1), 1500);
                                        CSR_REC(:, app.CSRTWSMN) = 10 * CSR_masked;

                                        CSR_selected = CSR_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latCSR);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latCSR == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(CSR_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'CSR Mascon';
                                    end

                                    %% GSFC (mascon)
                                case 'gsfc'
                                    GSFC_masked = app.GFSCTWSData;
                                    latGSFC = app.GFSCTWSLat;
                                    lonGSFC = app.GFSCTWSLon;
                                    if ~isempty(GSFC_masked)
                                        GSFC_REC = NaN(size(latGSFC,1), 1500);
                                        GSFC_REC(:, app.GFSCTWSMN) = 10 * GSFC_masked;

                                        GSFC_selected = GSFC_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latGSFC);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latGSFC == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(GSFC_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'GSFC Mascon';
                                    end
                            end

                            if ~isempty(regionalData)
                                break;
                            end
                        end
                    end

                    %% ==================== PROCESS THIS STUDY DATA ====================
                    if isempty(regionalData)
                        selectedNodes = app.This_Study.CheckedNodes;
                        for i = 1:numel(selectedNodes)
                            if ~isempty(selectedNodes(i).Children), continue; end

                            rawName = strtrim(selectedNodes(i).Text);
                            nodeKey = lower(rawName);

                            switch nodeKey
                                %% BEST
                                case 'rf_imd'
                                    Best_masked = app.ThisStudyBestData;
                                    latBest = app.ThisStudyBestLat;
                                    lonBest = app.ThisStudyBestLon;
                                    if ~isempty(Best_masked)
                                        Best_REC = NaN(size(latBest,1), 1500);
                                        Best_REC(:, app.ThisStudyBestMN) = Best_masked;

                                        Best_selected = Best_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latBest);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latBest == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(Best_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'RF_IMD (This Study)';
                                    end

                                    %% ANN
                                case 'ann_g'
                                    ANN_masked = app.ThisStudyANNData;
                                    latANN = app.ThisStudyANNLat;
                                    lonANN = app.ThisStudyANNLon;

                                    if ~isempty(ANN_masked)
                                        ANN_REC = NaN(size(latANN,1), 1500);
                                        ANN_REC(:, app.ThisStudyANNMN) = ANN_masked;

                                        ANN_selected = ANN_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latANN);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latANN == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(ANN_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'ANN_GLDAS (This Study)';
                                    end

                                    %% LSTM
                                case 'ann_imd'
                                    LSTM_masked = app.ThisStudyLSTMData;
                                    latLSTM = app.ThisStudyLSTMLat;
                                    lonLSTM = app.ThisStudyLSTMLon;

                                    if ~isempty(LSTM_masked)
                                        LSTM_REC = NaN(size(latLSTM,1), 1500);
                                        LSTM_REC(:, app.ThisStudyLSTMMN) = LSTM_masked;

                                        LSTM_selected = LSTM_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latLSTM);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latLSTM == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(LSTM_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'ANN_IMD (This Study)';
                                    end

                                    %% RF
                                case 'rf_g'
                                    RF_masked = app.ThisStudyRFData;
                                    latRF = app.ThisStudyRFLat;
                                    lonRF = app.ThisStudyRFLon;

                                    if ~isempty(RF_masked)
                                        RF_REC = NaN(size(latRF,1), 1500);
                                        RF_REC(:, app.ThisStudyRFMN) = RF_masked;

                                        RF_selected = RF_REC(:, x);

                                        [uniqueLats, ~, idx] = unique(latRF);
                                        regionalMeanData = zeros(length(uniqueLats), length(x));

                                        for j = 1:length(uniqueLats)
                                            latMask = (latRF == uniqueLats(j));
                                            regionalMeanData(j, :) = mean(RF_selected(latMask, :), 1, 'omitnan');
                                        end

                                        regionalData = regionalMeanData;
                                        regionalLats = uniqueLats;
                                        datasetName = 'RF_GLDAS (This Study)';
                                    end
                            end

                            if ~isempty(regionalData)
                                break;
                            end
                        end
                    end


                    %% ==================== CREATE REGIONAL MEAN CONTOUR PLOT ====================
                    if ~isempty(regionalData) && ~isempty(regionalLats)
                        % Convert datetime to simple year numbers
                        if isdatetime(selectedTimeRange)
                            numericTime = year(selectedTimeRange);
                        else
                            numericTime = double(selectedTimeRange);
                        end

                        % Ensure other variables are numeric
                        regionalLats = double(regionalLats);
                        regionalData = double(regionalData);


                        % Replace NaN with a sentinel value outside data range
                        dataMin = min(regionalData(:), [], 'omitnan');
                        dataMax = max(regionalData(:), [], 'omitnan');
                        maskValue = 0; %dataMin - 999; % something way below min

                        regionalDataMasked = regionalData;
                        % regionalDataMasked(isnan(regionalData)) = maskValue;

                        [T, L] = meshgrid(x, regionalLats);
                        % Clear the axes before plotting
                        cla(app.UIAxes);

                        % Ensure the axes are held correctly
                        hold(app.UIAxes, 'on');
                        contourf(app.UIAxes, T, L, regionalDataMasked, 5, 'LineStyle', 'none');
                        baseColors = [...
                            103 0   31;   % dark red
                            178 24  43;   % medium red
                            214 96  77;   % light red
                            244 165 130;  % very light red
                            247 247 247;  % white
                            146 197 222;  % light blue
                            67  147 195;  % medium blue
                            33  102 172;  % darker blue
                            5   48  97];  % darkest blue

                        % Normalize to [0,1]
                        baseColors = baseColors ./ 255;

                        % --- Interpolate colormap ---
                        x  = linspace(0, 1, size(baseColors,1));
                        xi = linspace(0, 1, 256);
                        cmap = interp1(x, baseColors, xi, 'linear');
                        colormap(app.UIAxes, cmap);
                        colorbar(app.UIAxes);
                        % --- Symmetric color axis ---

                        cabs = max(abs([dataMin dataMax]));
                        clim(app.UIAxes, [-cabs cabs]);


                        xlabel(app.UIAxes, 'Time (Years)', 'FontSize', 12, 'FontName', 'Times New Roman');
                        ylabel(app.UIAxes, 'Latitude', 'FontSize', 12, 'FontName', 'Times New Roman');
                        title(app.UIAxes, ['Regional Mean Plot - ' datasetName], 'FontSize', 14, 'FontName', 'Times New Roman');

                        app.UIAxes.FontName = 'Times New Roman';
                        app.UIAxes.FontSize = 11;
                        grid(app.UIAxes, 'on');
                        drawnow;


                        % Ensure the axes are held correctly
                        hold(app.UIAxes, 'off');

                    else
                        % No data selected or no data in basin
                        % Clear previous text
                        cla(app.UIAxes, 'reset');

                        % Hold off so text is centered cleanly
                        hold(app.UIAxes, 'off');

                        % Set normalized coordinate system
                        text(app.UIAxes, 0.5, 0.5, ...
                            'No data available for selected basin or no dataset selected', ...
                            'Units', 'normalized', ...  % <<< KEY FIX
                            'HorizontalAlignment', 'center', ...
                            'VerticalAlignment', 'middle', ...
                            'FontSize', 14, ...
                            'FontName', 'Times New Roman', ...
                            'FontWeight', 'bold');

                        % Optionally remove tick marks for cleaner look
                        app.UIAxes.XTick = [];
                        app.UIAxes.YTick = [];
                        title(app.UIAxes, '');
                        xlabel(app.UIAxes, '');
                        ylabel(app.UIAxes, '');

                    end

                    hold(app.UIAxes, 'off');

            end
        end

        % Value changed function: LoadDataButton
        function LoadDataButtonValueChanged(app, event)
            % value = app.LoadDataButton.Value;
            % Get screen size [x y width height]
            % screenSize = get(0,'ScreenSize');
            %
            % % Design size (from your UIFigure in Design View)
            % designWidth = 1212;
            % designHeight = 764;
            %
            % % Compute scale factor relative to screen size
            % scale = min(screenSize(3)/designWidth, screenSize(4)/designHeight);
            %
            % % Apply new scaled size (use 85% of available screen for safety)
            % newWidth = round(designWidth * scale* 0.85);%* 0.85
            % newHeight = round(designHeight * scale* 0.85);
            %
            % % Update UIFigure size
            % app.UIFigure.Position = [50 50 newWidth newHeight];
            %
            % % Center the app
            % movegui(app.UIFigure, 'onscreen');


            % Set auto-resize behavior
            %app.UIFigure.AutoResizeChildren = 'on';

            % % Set normalized units for axes to make them responsive
            % app.UIAxes.Units = 'normalized';
            % app.UIAxes.Position = [0.1 0.25 0.85 0.75]; % Adjust as needed
            % This function loads all required datasets into the app properties
            % Ensure to set the correct paths and variable names for each dataset
            % Path settings (update these paths as needed)
            try
                shapefilePath = "./Data/River_Shps/Indian_river_shps.shp";

                % Load the shapefile
                app.Basins = shaperead(shapefilePath);

            catch ME
                warning(ME.identifier, 'Shapefile is not available at %s. Message: %s', ...
                    shapefilePath, ME.message);
            end
            % value = app.LoadDataButton.Value;
            % Load Data Button callback


            % Clear existing data first (except basin shapefile)
            app.clearBasinData();

            % Get selected basin
            selectedBasin = app.BasinSelectionButton.Value;
            if strcmpi(selectedBasin, 'Select Basin') || isempty(selectedBasin)
                uialert(app.UIFigure, 'Please select a basin from the dropdown first.', 'No Basin Selected', 'Icon', 'warning');
                return;
            end

            % Load basin shapefile if not already loaded
            try
                if isempty(app.Basins)
                    shapefilePath = "./Data/River_Shps/Indian_river_shps.shp";
                    app.Basins = shaperead(shapefilePath);
                end

                % Check if selected basin exists in shapefile
                basinExists = false;
                basinPolygon = [];

                for i = 1:length(app.Basins)
                    if strcmpi(app.Basins(i).RIVER_BASI, selectedBasin)
                        basinExists = true;
                        basinPolygon = [app.Basins(i).X', app.Basins(i).Y'];
                        break;
                    end
                end

                if ~basinExists
                    uialert(app.UIFigure, ...
                        sprintf('Basin "%s" not found in the shapefile. Please select a valid basin.', selectedBasin), ...
                        'Basin Not Available', 'Icon', 'error');
                    return;
                end

                if ~isempty(basinPolygon)
                    basinPolygon = basinPolygon(~any(isnan(basinPolygon), 2), :);
                end

                if isempty(basinPolygon)
                    uialert(app.UIFigure, 'Invalid basin polygon in shapefile.', 'Error', 'Icon', 'error');
                    return;
                end

            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error loading basin shapefile: %s', ME.message), ...
                    'Shapefile Error', 'Icon', 'error');
                return;
            end

            % Create progress dialog
            d = uiprogressdlg(app.UIFigure, 'Title', 'Loading Data', ...
                'Message', sprintf('Loading data for %s basin...', selectedBasin), ...
                'Indeterminate', 'on');

            try
                % ==================== PRECIPITATION DATASETS ====================
                d.Message = 'Loading IMD Precipitation data...';
                IMDFile = "./Data/Precipitation/IMD/IMD_Precipitation_1901_2023_Godavari.nc";
                [data, time, lat, lon, app.IMDMN] = app.loadNetCDFData(IMDFile, 'RAINFALL','LATITUDE','LONGITUDE','time');
                [app.IMDData, app.IMDTime, app.IMDLat, app.IMDLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'IMD Precipitation');
                clear data time lat lon;

                d.Message = 'Loading GPCP Precipitation data...';
                GPCPFile = "./Data/Precipitation/GPCP/GPCP_Total_Monthly_Precipitation_mm_Godavari.nc";
                [data, time, lat, lon, app.GPCPMN] = app.loadNetCDFData(GPCPFile, 'tp_monthly_total','lat','lon','time');
                [app.GPCPData, app.GPCPTime, app.GPCPLat, app.GPCPLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GPCP Precipitation');
                clear data time lat lon;

                d.Message = 'Loading ERA5 Precipitation data...';
                ERA5File = "./Data/Precipitation/ERA5/ERA5_Total_Monthly_Precipitation_mm_Godavari.nc";
                [data, time, lat, lon, app.ERA5MN] = app.loadNetCDFData(ERA5File, 'tp_monthly_total','lat','lon','valid_time');
                [app.ERA5Data, app.ERA5Time, app.ERA5Lat, app.ERA5Lon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'ERA5 Precipitation');
                clear data time lat lon;

                % ==================== HUMHREY RECONSTRUCTION DATASETS ====================
                d.Message = 'Loading Humphrey reconstruction data...';
                baseDir = fullfile('Data', 'Reconstructed_TWSA', 'Humphrey_2019');
                Hp_GHFile = fullfile(baseDir, 'modified_GRACE_REC_v03_G_E_monthly_ensemble_mean_Godavari.nc');
                [data, time, lat, lon, app.Hp_GERECMN] = app.loadNetCDFData(Hp_GHFile, 'TWS','lat','lon','time');
                [app.Hp_GERECData, app.Hp_GERECTime, app.Hp_GERECLat, app.Hp_GERECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Humphrey G_E Reconstruction');
                clear data time lat lon;

                Hp_GGFile = fullfile(baseDir, 'modified_GRACE_REC_v03_GSFC_GSWP3_monthly_ensemble_mean_Godavari.nc');
                [data, time, lat, lon, app.Hp_GGRECMN] = app.loadNetCDFData(Hp_GGFile, 'TWS','lat','lon','time');
                [app.Hp_GGRECData, app.Hp_GGRECTime, app.Hp_GGRECLat, app.Hp_GGRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Humphrey G_GSWP3 Reconstruction');
                clear data time lat lon;

                Hp_GMFile = fullfile(baseDir, 'modified_GRACE_REC_v03_GSFC_MSWEP_monthly_ensemble_mean_Godavari.nc');
                [data, time, lat, lon, app.Hp_GMRECMN] = app.loadNetCDFData(Hp_GMFile, 'TWS','lat','lon','time');
                [app.Hp_GMRECData, app.Hp_GMRECTime, app.Hp_GMRECLat, app.Hp_GMRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Humphrey G_MSWEP Reconstruction');
                clear data time lat lon;

                Hp_JEFile = fullfile(baseDir, 'modified_GRACE_REC_v03_J_E_monthly_ensemble_mean_Godavari.nc');
                [data, time, lat, lon, app.Hp_JERECMN] = app.loadNetCDFData(Hp_JEFile, 'TWS','lat','lon','time');
                [app.Hp_JERECData, app.Hp_JERECTime, app.Hp_JERECLat, app.Hp_JERECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Humphrey J_E Reconstruction');
                clear data time lat lon;

                Hp_JGFile = fullfile(baseDir, 'modified_GRACE_REC_v03_JPL_GSWP3_monthly_ensemble_mean_Godavari.nc');
                [data, time, lat, lon, app.Hp_JGRECMN] = app.loadNetCDFData(Hp_JGFile, 'TWS','lat','lon','time');
                [app.Hp_JGRECData, app.Hp_JGRECTime, app.Hp_JGRECLat, app.Hp_JGRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Humphrey J_GSWP3 Reconstruction');
                clear data time lat lon;

                Hp_JMFile = fullfile(baseDir, 'modified_GRACE_REC_v03_JPL_MSWEP_monthly_ensemble_mean_Godavari.nc');
                [data, time, lat, lon, app.Hp_JMRECMN] = app.loadNetCDFData(Hp_JMFile, 'TWS','lat','lon','time');
                [app.Hp_JMRECData, app.Hp_JMRECTime, app.Hp_JMRECLat, app.Hp_JMRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Humphrey J_MSWEP Reconstruction');
                clear data time lat lon;

                % ==================== OTHER RECONSTRUCTION DATASETS ====================
                d.Message = 'Loading Deng 2023 reconstruction...';
                DengFile = "./Data/Reconstructed_TWSA/Deng_2023/Deng_Godavari.nc";
                [data, time, lat, lon, app.DengRECMN] = app.loadNetCDFData(DengFile, 'twsa_rec','lat','lon','time');
                [app.DengRECData, app.DengRECTime, app.DengRECLat, app.DengRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Deng 2023 Reconstruction');
                clear data time lat lon;

                d.Message = 'Loading Li 2021 reconstruction...';
                LiFile = "./Data/Reconstructed_TWSA/Li_2021/GRACE_REC_Li_Godavari.nc";
                [data, time, lat, lon, app.LiRECMN] = app.loadNetCDFData(LiFile, 'lwe_thickness','lat','lon','time');
                [app.LiRECData, app.LiRECTime, app.LiRECLat, app.LiRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Li 2021 Reconstruction');
                clear data time lat lon;

                d.Message = 'Loading Mandal 2025 reconstruction...';
                MandalFile = "./Data/Reconstructed_TWSA/Mandal_2025/BNML_TWSA_Global_Godavari.nc";
                [data, time, lat, lon, app.MandalRECMN] = app.loadNetCDFData(MandalFile, 'bnml_twsa','lat','lon','time');
                [app.MandalRECData, app.MandalRECTime, app.MandalRECLat, app.MandalRECLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Mandal 2025 Reconstruction');
                clear data time lat lon;

                % ==================== PALAZZOLI RECONSTRUCTION DATASETS ====================
                d.Message = 'Loading Palazzoli 2025 reconstructions...';
                PalazzoliFile1 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_LSTM_Godavari.nc";
                [data, time, lat, lon, app.PalazzoliLSTMMN] = app.loadNetCDFData(PalazzoliFile1, 'TWSA','lat','lon','time');
                [app.PalazzoliLSTMData, app.PalazzoliLSTMTime, app.PalazzoliLSTMLat, app.PalazzoliLSTMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Palazzoli LSTM Reconstruction');
                clear data time lat lon;

                PalazzoliFile2 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_LSTMnoLCSIF_Godavari.nc";
                [data, time, lat, lon, app.PalazzoliLSTMnoLCSIFMN] = app.loadNetCDFData(PalazzoliFile2, 'TWSA','lat','lon','time');
                [app.PalazzoliLSTMnoLCSIFData, app.PalazzoliLSTMnoLCSIFTime, app.PalazzoliLSTMnoLCSIFLat, app.PalazzoliLSTMnoLCSIFLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Palazzoli LSTMnoLCSIF Reconstruction');
                clear data time lat lon;

                PalazzoliFile3 = "./Data/Reconstructed_TWSA/Palazzoli_2025/Palazzoli_BiLSTM_Godavari.nc";
                [data, time, lat, lon, app.PalazzoliBiLSTMMN] = app.loadNetCDFData(PalazzoliFile3, 'TWSA','lat','lon','time');
                [app.PalazzoliBiLSTMData, app.PalazzoliBiLSTMTime, app.PalazzoliBiLSTMLat, app.PalazzoliBiLSTMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Palazzoli BiLSTM Reconstruction');
                clear data time lat lon;

                PalazzoliFile4 = "./Data/Reconstructed_TWSA/Palazzoli_2025/GRAiCE_BiLSTMnoLCSIF_Godavari.nc";
                [data, time, lat, lon, app.PalazzoliBiLSTMnoLCSIFMN] = app.loadNetCDFData(PalazzoliFile4, 'TWSA','lat','lon','time');
                [app.PalazzoliBiLSTMnoLCSIFData, app.PalazzoliBiLSTMnoLCSIFTime, app.PalazzoliBiLSTMnoLCSIFLat, app.PalazzoliBiLSTMnoLCSIFLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'Palazzoli BiLSTMnoLCSIF Reconstruction');
                clear data time lat lon;

                % ==================== THIS STUDY DATASETS ====================
                d.Message = 'Loading This Study reconstructions...';
                ThisStudyFile2 = "./Data/This_Study/RTWSA_GRACE_ANN_Godavari.nc";
                [data, time, lat, lon, app.ThisStudyANNMN] = app.loadNetCDFData(ThisStudyFile2, 'TWSA','lat','lon','time');
                [app.ThisStudyANNData, app.ThisStudyANNTime, app.ThisStudyANNLat, app.ThisStudyANNLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'This Study ANN+GLDAS');
                clear data time lat lon;

                ThisStudyFile4 = "./Data/This_Study/RTWSA_GRACE_RF_Godavari.nc";
                [data, time, lat, lon, app.ThisStudyRFMN] = app.loadNetCDFData(ThisStudyFile4, 'TWSA','lat','lon','time');
                [app.ThisStudyRFData, app.ThisStudyRFTime, app.ThisStudyRFLat, app.ThisStudyRFLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'This Study RF+GLDAS');
                clear data time lat lon;

                ThisStudyFile3 = "./Data/This_Study/RTWSA_GRACE_IMD_ANN_Godavari.nc";
                [data, time, lat, lon, app.ThisStudyLSTMMN] = app.loadNetCDFData(ThisStudyFile3, 'TWSA','lat','lon','time');
                [app.ThisStudyLSTMData, app.ThisStudyLSTMTime, app.ThisStudyLSTMLat, app.ThisStudyLSTMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'This Study ANN+IMD');
                clear data time lat lon;

                ThisStudyFile1 = "./Data/This_Study/RTWSA_GRACE_IMD_RF_Godavari.nc";
                [data, time, lat, lon, app.ThisStudyBestMN] = app.loadNetCDFData(ThisStudyFile1, 'TWSA','lat','lon','time');
                [app.ThisStudyBestData, app.ThisStudyBestTime, app.ThisStudyBestLat, app.ThisStudyBestLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'This Study RF+IMD');
                clear data time lat lon;

                % ==================== GRACE DATASETS ====================
                d.Message = 'Loading GRACE JPL data...';
                JPLFile = "./Data/GRACE/JPL/GRCTellus.JPL.200204_202501.GLO.RL06.3M.MSCNv04CRI_Godavari.nc";
                [data, time, lat, lon, app.JPLTWSMN] = app.loadNetCDFData(JPLFile,'lwe_thickness','lat','lon','time');
                [app.JPLTWSData, app.JPLTWSTime, app.JPLTWSLat, app.JPLTWSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GRACE JPL');
                clear data time lat lon;

                d.Message = 'Loading GRACE CSR data...';
                CSRFile = "./Data/GRACE/CSR/CSR_GRACE_GRACE-FO_RL0603_Mascons_all-corrections_Godavari.nc";
                [data, time, lat, lon, app.CSRTWSMN] = app.loadNetCDFData(CSRFile, 'lwe_thickness','lat','lon','time');
                [app.CSRTWSData, app.CSRTWSTime, app.CSRTWSLat, app.CSRTWSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GRACE CSR');
                clear data time lat lon;

                d.Message = 'Loading GRACE GFSC data...';
                GFSCFile = "./Data/GRACE/GFSC/GSFC_200204_202410_Godavari.nc";
                [data, time, lat, lon, app.GFSCTWSMN] = app.loadNetCDFData(GFSCFile,'lwe_thickness','lat','lon','time');
                [app.GFSCTWSData, app.GFSCTWSTime, app.GFSCTWSLat, app.GFSCTWSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GRACE GSFC');
                clear data time lat lon;

                % ==================== GLDAS DATASETS ====================
                d.Message = 'Loading GLDAS datasets...';
                GLDASFile = "./Data/GLDAS/GLDAS_NOAH_1948_2024_JPLgrid_Godavari.nc";

                % Precipitation
                [data, time, lat, lon, app.GLDAS_PMN] = app.loadNetCDFData(GLDASFile, 'Rainf_f_tavg', 'lat', 'lon', 'time');
                [app.GLDAS_PData, app.GLDAS_PTime, app.GLDAS_PLat, app.GLDAS_PLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Precipitation');
                clear data time lat lon;

                % Evapotranspiration
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Evap_tavg', 'lat', 'lon', 'time');
                [app.GLDAS_ETData, app.GLDAS_ETTime, app.GLDAS_ETLat, app.GLDAS_ETLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Evapotranspiration');
                clear data time lat lon;

                % Soil Moisture
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Soil_Moisture', 'lat', 'lon', 'time');
                [app.GLDAS_SMData, app.GLDAS_SMTime, app.GLDAS_SMLat, app.GLDAS_SMLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Soil Moisture');
                clear data time lat lon;

                % Air Temperature
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Tair_f_inst', 'lat', 'lon', 'time');
                [app.GLDAS_TData, app.GLDAS_TTime, app.GLDAS_TLat, app.GLDAS_TLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Temperature');
                clear data time lat lon;

                % Wind Speed
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Wind_f_inst', 'lat', 'lon', 'time');
                [app.GLDAS_WSData, app.GLDAS_WSTime, app.GLDAS_WSLat, app.GLDAS_WSLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Wind Speed');
                clear data time lat lon;

                % Specific Humidity
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'Qair_f_inst', 'lat', 'lon', 'time');
                [app.GLDAS_SHData, app.GLDAS_SHTime, app.GLDAS_SHLat, app.GLDAS_SHLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Specific Humidity');
                clear data time lat lon;

                % Longwave Radiation
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'LWdown_f_tavg', 'lat', 'lon', 'time');
                [app.GLDAS_LWData, app.GLDAS_LWTime, app.GLDAS_LWLat, app.GLDAS_LWLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Longwave Radiation');
                clear data time lat lon;

                % Shortwave Radiation
                [data, time, lat, lon, ~] = app.loadNetCDFData(GLDASFile, 'SWdown_f_tavg', 'lat', 'lon', 'time');
                [app.GLDAS_SWData, app.GLDAS_SWTime, app.GLDAS_SWLat, app.GLDAS_SWLon] = app.extractBasinData(data, time, lat, lon, basinPolygon, 'GLDAS Shortwave Radiation');
                clear data time lat lon;

                % Get NetCDF info
                infoIMD = ncinfo(IMDFile);
                timename = 'time';

                % Find time variable
                timeVarIMD = infoIMD.Variables(strcmp({infoIMD.Variables.Name}, timename));

                % Read time values
                timeIMD = ncread(IMDFile, timename);

                % Get units attribute
                timeUnitsIMD = '';
                for i = 1:length(timeVarIMD.Attributes)
                    if strcmpi(timeVarIMD.Attributes(i).Name, 'units')
                        timeUnitsIMD = timeVarIMD.Attributes(i).Value;
                        break;
                    end
                end

                % --- Convert to MATLAB datetime ---
                if contains(timeUnitsIMD, 'since', 'IgnoreCase', true)
                    parts = regexp(timeUnitsIMD, 'since\s*([\d-]+)', 'tokens', 'once');
                    if ~isempty(parts)
                        baseDateIMD = datetime(parts{1}, 'InputFormat', 'yyyy-MM-dd');

                        if contains(timeUnitsIMD, 'days', 'IgnoreCase', true)
                            app.IMD_datetime = baseDateIMD + days(timeIMD);
                        else
                            error('Unsupported time units for IMD data: %s', timeUnitsIMD);
                        end
                    else
                        error('Failed to parse base date from time units: %s', timeUnitsIMD);
                    end
                else
                    error('Time units not recognized or missing in the NetCDF file.');
                end
                % Read NetCDF info
                info1 = ncinfo(JPLFile);

                % Find time variable
                timeVar = info1.Variables(strcmp({info1.Variables.Name}, timename));

                % Read time values
                time = ncread(JPLFile, timename);

                % Get units attribute
                timeUnits = '';
                for i = 1:length(timeVar.Attributes)
                    if strcmpi(timeVar.Attributes(i).Name, 'units')
                        timeUnits = timeVar.Attributes(i).Value;
                        break;
                    end
                end

                % --- Convert to MATLAB datetime ---
                if contains(timeUnits, 'since', 'IgnoreCase', true)
                    % Extract reference date
                    parts = regexp(timeUnits, 'since\s*([\d-]+)', 'tokens', 'once');
                    if ~isempty(parts)
                        baseDate = datetime(parts{1}, 'InputFormat', 'yyyy-MM-dd');

                        % Handle different units
                        if contains(timeUnits, 'days', 'IgnoreCase', true)
                            app.JPL_datetime = baseDate + days(time);
                        elseif contains(timeUnits, 'months', 'IgnoreCase', true)
                            app.JPL_datetime = baseDate + calmonths(time);
                        elseif contains(timeUnits, 'years', 'IgnoreCase', true)
                            % Fractional years -> convert to months/days
                            yearsInt = floor(time);
                            yearsFrac = time - yearsInt;
                            app.JPL_datetime = baseDate + calyears(yearsInt) + days(yearsFrac*365.25);
                        else
                            error('Unsupported time units: %s', timeUnits);
                        end
                    else
                        error('Failed to parse base date from time units: %s', timeUnits);
                    end
                else
                    error('Time units not recognized or missing in the NetCDF file.');
                end


                % Get info
                info2 = ncinfo(GLDASFile);

                % Find time variable
                timeVar = info2.Variables(strcmp({info2.Variables.Name}, timename));

                % Read time values
                time = ncread(GLDASFile, timename);

                % Get units attribute
                timeUnits = '';
                for i = 1:length(timeVar.Attributes)
                    if strcmpi(timeVar.Attributes(i).Name, 'units')
                        timeUnits = timeVar.Attributes(i).Value;
                        break;
                    end
                end

                % --- Convert to MATLAB datetime ---
                if contains(timeUnits, 'since', 'IgnoreCase', true)
                    % Extract reference date
                    parts = regexp(timeUnits, 'since\s*([\d-]+)', 'tokens', 'once');
                    if ~isempty(parts)
                        baseDate = datetime(parts{1}, 'InputFormat', 'yyyy-MM-dd');

                        % Handle different units
                        if contains(timeUnits, 'days', 'IgnoreCase', true)
                            app.GLDAS_datetime = baseDate + days(time);
                        elseif contains(timeUnits, 'hours', 'IgnoreCase', true)
                            app.GLDAS_datetime = baseDate + hours(time);
                        elseif contains(timeUnits, 'minutes', 'IgnoreCase', true)
                            app.GLDAS_datetime = baseDate + minutes(time);
                        elseif contains(timeUnits, 'months', 'IgnoreCase', true)
                            app.GLDAS_datetime = baseDate + calmonths(time);
                        elseif contains(timeUnits, 'years', 'IgnoreCase', true)
                            % Fractional years -> convert to days
                            yearsInt = floor(time);
                            yearsFrac = time - yearsInt;
                            app.GLDAS_datetime = baseDate + calyears(yearsInt) + days(yearsFrac*365.25);
                        else
                            error('Unsupported time units: %s', timeUnits);
                        end
                    else
                        error('Failed to parse base date from time units: %s', timeUnits);
                    end
                else
                    error('Time units not recognized or missing in the NetCDF file.');
                end
                % ==================== CGWB DATA ====================
                d.Message = 'Loading CGWB groundwater data...';
                try
                    excelFile = "./Data/CGWB/OB_Wells_Godavari.xlsx";
                    app.CGWBData = readtable(excelFile);

                    % Extract year columns (2002 to 2018)
                    yearCols = [];
                    years = [];
                    for i = 1:width(app.CGWBData)
                        colName = app.CGWBData.Properties.VariableNames{i};
                        yearMatch = regexp(colName, '\d{4}', 'match');
                        if ~isempty(yearMatch)
                            year = str2double(yearMatch{1});
                            if year >= 2002 && year <= 2018
                                yearCols = [yearCols, i];
                                years = [years, year];
                            end
                        end
                    end

                    [years, sortIdx] = sort(years);
                    yearCols = yearCols(sortIdx);

                    T_data = app.CGWBData{:, yearCols};
                    T_scaled = 1000 * 0.05 * T_data;

                    climYears = [2004, 2005, 2006, 2007, 2008, 2009];
                    climIdx = ismember(years, climYears);
                    T_clim = mean(T_scaled(:, climIdx), 2, 'omitnan');

                    app.CGWBAnomalies = T_scaled - T_clim;
                    app.CGWBAvg = mean(app.CGWBAnomalies, 1, 'omitnan');

                    app.CGWBLat = app.CGWBData{:, 'LAT'};
                    app.CGWBLon = app.CGWBData{:, 'LON'};

                    app.CGWBTime = datetime.empty;
                    years = 2002:2018;
                    for y = years
                        for m = [1,5,8,11]
                            app.CGWBTime = [app.CGWBTime; datetime(y,m,1)];
                        end
                    end

                catch ME2
                    warning('Could not load CGWB data: %s', ME2.message);
                    app.CGWBData = [];
                    app.CGWBAvg = [];
                    app.CGWBLat = [];
                    app.CGWBLon = [];
                    app.CGWBTime = [];
                end

                % ==================== CLEANUP AND FINALIZATION ====================
                close(d); % Close progress dialog
                % Force garbage collection
                pause(0.1);
                clearvars -except app selectedBasin;
                % Store selected basin name for reference
                app.CurrentBasin = selectedBasin;

                % Enable analysis buttons if needed
                if ~isempty(app.IMDData)
                    % Enable other buttons in your GUI
                    % app.AnalysisButton.Enable = 'on';
                    % app.PlotButton.Enable = 'on';
                end

                uialert(app.UIFigure, ...
                    sprintf('Data for %s basin loaded successfully!', selectedBasin), ...
                    'Data Loading Complete', 'Icon', 'success');

            catch ME
                % Close progress dialog if it exists
                if exist('d', 'var') && isvalid(d)
                    close(d);
                end

                uialert(app.UIFigure, ...
                    sprintf('Error loading data: %s\n\nLine: %d', ME.message, ME.stack(1).line), ...
                    'Data Loading Failed', 'Icon', 'error');

                % Clear any partially loaded data
                app.clearBasinData();
            end


        end

        % Value changed function: TimeSpan19012025Slider
        function TimeSpanTimeSpan19012025SliderValueChanging(app, event)
            timescales = app.TimescaleDropDown.Value;
            %Callback function for TimeSpanSlider value changing
            %Get the current timescale selection
            switch timescales
                case 'Monthly'
                    % Define the starting and ending dates for the slider scale
                    startDate = datetime(1901, 1, 1);
                    endDate   = datetime(2025, 12, 1);

                    % Generate all months between the start and end dates
                    allTimeDates = startDate:calmonths(1):endDate;
                    totalMonths = numel(allTimeDates);

                    % Slider limits
                    app.TimeSpan19012025Slider.Limits = [1, totalMonths];

                    % --- Compute tick positions ---
                    years = year(startDate):year(endDate);

                    % Choose step (e.g., every 10 years to avoid overlap)
                    tickStep = 10;
                    yearsShown = years(mod(years, tickStep) == 0);  % pick 1910, 1920, ...

                    % Find January positions for chosen years
                    yearDates = datetime(yearsShown,1,1);
                    [~, yearTicks] = ismember(yearDates, allTimeDates);

                    % Assign ticks and labels
                    app.TimeSpan19012025Slider.MajorTicks = yearTicks;
                    app.TimeSpan19012025Slider.MajorTickLabels = string(yearsShown);

                    % --- Update slider value safely ---
                    currentSliderValue = round(event.Value);
                    currentSliderValue(1) = max(currentSliderValue(1), app.TimeSpan19012025Slider.Limits(1));
                    currentSliderValue(2) = min(currentSliderValue(2), app.TimeSpan19012025Slider.Limits(2));
                    app.TimeSpan19012025Slider.Value = currentSliderValue;

                    % --- Map back to month-year strings ---
                    app.selectedStartTime = datestr(allTimeDates(currentSliderValue(1)), 'yyyy');
                    app.selectedEndTime   = datestr(allTimeDates(currentSliderValue(2)), 'yyyy');

                    % --- Update UI labels ---
                    app.StartTimeLabel.Text = app.selectedStartTime;
                    app.EndTimeLabel.Text   = app.selectedEndTime;

                case 'Annual'
                    % Show error message and revert to Monthly
                    uialert(app.UIFigure, 'Annual timescale is not available.', 'Feature Not Available');
                    %app.TimescaleDropDown.Value = 'Monthly';
                    % The callback will trigger again with 'Monthly' selection

                case 'Decadal'
                    % Show error message and revert to Monthly
                    uialert(app.UIFigure, 'Decadal timescale is not available.', 'Feature Not Available');
                    %app.TimescaleDropDown.Value = 'Monthly';
                    % The callback will trigger again with 'Monthly' selection
            end
            updateAllPlots(app);
        end

        % Value changed function: PlotTypeDropDown
        function PlotTypeDropDownValueChanged(app, event)
            updateAllPlots(app);
        end

        % Value changed function: TimescaleDropDown
        function TimescaleDropDownValueChanged(app, event)
            updateAllPlots(app);
        end

        % Callback function: RTWSA
        function RTWSACheckedNodesChanged(app, event)
            updateAllPlots(app);
        end

        % Callback function: TWSA
        function TWSACheckedNodesChanged(app, event)
            updateAllPlots(app);
        end

        % Callback function: This_Study
        function This_StudyCheckedNodesChanged(app, event)
            updateAllPlots(app);
            if any(app.This_Study.CheckedNodes == app.ReconstructionNode)
                disp('Reconstruction tab is selected.');


                choice = questdlg('Select reconstruction type:', ...
                    'Reconstruction Type', ...
                    'Basin Average', 'All Points', 'Basin Average'); % Ask user for reconstruction method
                choice2 = questdlg('Select reconstruction method:', ...
                    'Reconstruction Method', ...
                    'RF', 'ANN', 'RF');

                switch choice
                    case 'Basin Average'
                        switch choice2
                            case 'RF'
                                app.reconstructTWSWithMethod('basin_average','RF');
                            case 'ANN'
                                app.reconstructTWSWithMethod('basin_average','ANN');
                        end
                    case 'All Points'
                        switch choice2
                            case 'RF'
                                app.reconstructTWSWithMethod('all_points','RF');
                            case 'ANN'
                                app.reconstructTWSWithMethod('all_points','ANN');
                        end
                end
            end
        end

        % Callback function: Precipitation
        function PrecipitationCheckedNodesChanged(app, event)
            updateAllPlots(app);
        end

        % Value changed function: ExportPlotButton
        function ExportPlotButtonValueChanged(app, event)
            % Get the button value (useful if it's a toggle button)
            value = app.ExportPlotButton.Value;

            % Only export if button is "pressed" (true)
            if value
                % Ask user where to save the plot
                [file, path] = uiputfile({'*.png'; '*.jpg'; '*.pdf'}, 'Save Plot As');

                % Check if user canceled
                if isequal(file,0) || isequal(path,0)
                    % Reset button value if canceled
                    app.ExportPlotButton.Value = false;
                    return;
                end

                % Full path to save
                filename = fullfile(path, file);

                % ========= Ask user for figure size =========
                prompt = {'Enter figure width (inches):','Enter figure height (inches):'};
                dlgtitle = 'Figure Size';
                dims = [1 35];
                definput = {'6','4'};  % default size
                answer = inputdlg(prompt, dlgtitle, dims, definput);

                % If user cancels input dialog
                if isempty(answer)
                    app.ExportPlotButton.Value = false;
                    return;
                end

                % Convert input to numeric
                widthInches  = str2double(answer{1});
                heightInches = str2double(answer{2});

                if isnan(widthInches) || isnan(heightInches) || widthInches<=0 || heightInches<=0
                    uialert(app.UIFigure, "Invalid figure size entered.", 'Input Error', 'Icon', 'error');
                    app.ExportPlotButton.Value = false;
                    return;
                end

                % ========= Figure export with DPI fallback =========
                % Get the figure containing the UIAxes
                fig = ancestor(app.UIAxes, 'figure');

                % Store original figure settings
                originalUnits = fig.PaperUnits;
                originalPos = fig.PaperPosition;

                % Set units to inches and adjust figure size
                fig.PaperUnits = 'inches';
                fig.PaperPosition = [0 0 widthInches heightInches];

                % DPI fallback list
                dpiList = [2500, 1500, 800, 600];
                success = false;
                lastError = '';

                for dpi = dpiList
                    try
                        exportgraphics(app.UIAxes, filename, 'Resolution', dpi);
                        success = true;
                        break; % stop trying if successful
                    catch ME
                        lastError = ME.message;
                        % Try the next DPI
                    end
                end

                if success
                    uialert(app.UIFigure, ...
                        sprintf("Plot saved successfully!\nSize: %.1f x %.1f in @ %d DPI", ...
                        widthInches, heightInches, dpi), ...
                        'Success', 'Icon', 'success');
                else
                    uialert(app.UIFigure, ...
                        sprintf("Failed to export plot at all tested resolutions.\nLast error:\n%s", lastError), ...
                        "Export Error");
                end

                % Restore original figure settings
                fig.PaperUnits = originalUnits;
                fig.PaperPosition = originalPos;

                % Reset the button value after saving
                app.ExportPlotButton.Value = false;
            end


        end

        % Callback function: Trend
        function TrendCheckedNodesChanged(app, event)

            % Get selected trend methods
            trendNodes = app.Trend.CheckedNodes;

            % Handle case when no nodes are selected
            if isempty(trendNodes)
                checkedMethods = {};
            else
                checkedMethods = lower(strtrim(string({trendNodes.Text})));
            end

            % All possible trend methods (excluding the parent 'trends' node if present)
            allMethods = {'linear', 'polynomial', 'loess', 'sens slope'};

            % Remove trends for unchecked methods
            for m = 1:numel(allMethods)
                method = allMethods{m};
                tagName = ['trend-' strrep(method, ' ', '-')];
                if ~ismember(method, checkedMethods)
                    % Safely delete trend lines and text
                    lineObjs = findobj(app.UIAxes, 'Type', 'line', 'Tag', tagName);
                    if ~isempty(lineObjs)
                        delete(lineObjs);
                    end
                    textObjs = findobj(app.UIAxes, 'Type', 'text', 'Tag', tagName);
                    if ~isempty(textObjs)
                        delete(textObjs);
                    end
                end
            end

            % Stop if nothing is checked
            if isempty(checkedMethods) || (numel(checkedMethods) == 1 && strcmp(checkedMethods{1}, 'trends'))
                % Refresh legend and return
                legend(app.UIAxes, 'off');
                legend(app.UIAxes, 'on');
                return;
            end

            % Filter out the parent 'trends' node if present
            validMethods = setdiff(checkedMethods, 'trends');
            if isempty(validMethods)
                legend(app.UIAxes, 'off');
                legend(app.UIAxes, 'on');
                return;
            end

            % Find base lines (skip existing trends)
            lineObjects = findobj(app.UIAxes, 'Type', 'line', '-not', 'LineStyle', '--');
            if isempty(lineObjects)
                msgbox('No time series lines found on the plot. Please plot some data first.', 'Info');
                return;
            end

            % Define consistent style mapping
            trendStyles = containers.Map( ...
                {'linear', 'polynomial', 'loess', 'sens slope'}, ...
                {{'--', 2.5}, {':', 2.5}, {'-.', 2.5}, {'--', 2.5}} ...
                );

            hold(app.UIAxes, 'on');
            trendResults = {};

            % === Loop through each checked method ===
            for t = 1:numel(validMethods)
                trendMethod = validMethods{t};
                tagName = ['trend-' strrep(trendMethod, ' ', '-')];

                % Remove any existing plot of this method (avoid duplicates)
                delete(findobj(app.UIAxes, 'Type', 'line', 'Tag', tagName));
                delete(findobj(app.UIAxes, 'Type', 'text', 'Tag', tagName));

                % === Loop through each base data line ===
                for i = 1:numel(lineObjects)
                    lineObj = lineObjects(i);

                    % Skip if this is already a trend line
                    if ~isempty(lineObj.Tag) && contains(lineObj.Tag, 'trends')
                        continue;
                    end

                    % Extract data
                    xData = lineObj.XData;
                    yData = lineObj.YData;
                    lineColor = lineObj.Color;
                    lineName = lineObj.DisplayName;

                    % Clean data
                    validMask = ~isnan(xData) & ~isnan(yData);
                    if sum(validMask) < 3
                        continue;
                    end
                    x_clean = xData(validMask);
                    y_clean = yData(validMask);

                    % Sort data
                    [x_clean, sortIdx] = sort(x_clean);
                    y_clean = y_clean(sortIdx);

                    % === Calculate trend ===
                    try
                        [trendLine, slope, ~, r2, pval] = app.calculateTrend(x_clean, y_clean, trendMethod);
                    catch ME
                        warning(ME.identifier,'Trend calculation failed for %s (%s): %s', lineName, trendMethod, ME.message);
                        continue;
                    end

                    if isempty(trendLine) || numel(trendLine) ~= numel(x_clean)
                        continue;
                    end

                    % Determine which Y-axis to use (default = left)
                    axSide = getappdata(lineObj, 'YAxisLocation');
                    if isempty(axSide)
                        axSide = 'left';
                    end
                    yyaxis(app.UIAxes, axSide);

                    % Respect inverted axes
                    set(app.UIAxes, 'YDir', get(app.UIAxes, 'YDir'));

                    % Style for this method
                    if isKey(trendStyles, trendMethod)
                        style = trendStyles(trendMethod);
                        lineStyle = style{1};
                        lineWidth = style{2};
                    else
                        lineStyle = '--';
                        lineWidth = 2;
                    end

                    % === Plot trend line ===
                    plot(app.UIAxes, x_clean, trendLine, lineStyle, ...
                        'Color', lineColor, ...
                        'LineWidth', lineWidth, ...
                        'DisplayName', sprintf('%s - %s Trend', lineName, trendMethod), ...
                        'Tag', tagName);

                    % === Add annotation text ===
                    yRange = range(app.UIAxes.YLim);
                    xRange = range(app.UIAxes.XLim);
                    textX = min(x_clean) + 0.02 * xRange;
                    textY = max(app.UIAxes.YLim) - (i-1) * 0.05 * yRange;

                    if strcmp(trendMethod, 'sens slope')
                        txt = sprintf('%s (%s): %.3f mm/yr (p=%.3f)', lineName, trendMethod, slope, pval);
                    else
                        txt = sprintf('%s (%s): %.3f mm/yr (R²=%.3f, p=%.3f)', lineName, trendMethod, slope, r2, pval);
                    end

                    text(app.UIAxes, textX, textY, txt, ...
                        'Color', lineColor, 'FontSize', 10, 'FontWeight', 'bold', ...
                        'BackgroundColor', 'white', 'EdgeColor', lineColor, ...
                        'Margin', 2, 'VerticalAlignment', 'bottom', ...
                        'Tag', tagName);

                    % Store results
                    trendResults{end+1} = struct('Name', lineName, ...
                        'Method', trendMethod, ...
                        'Axis', axSide, ...
                        'Slope', slope, ...
                        'R2', r2, ...
                        'PValue', pval);
                end
            end

            legend(app.UIAxes, 'show');
            hold(app.UIAxes, 'off');

            % === Print results ===
            if ~isempty(trendResults)
                fprintf('\n=== Trend Analysis Results ===\n');
                for i = 1:numel(trendResults)
                    r = trendResults{i};
                    fprintf('%s (%s, %s-axis): slope = %.4f mm/yr, R² = %.4f, p = %.4f\n', ...
                        r.Name, r.Method, r.Axis, r.Slope, r.R2, r.PValue);
                end
                fprintf('============================================\n\n');
            end


        end

        % Callback function: evalution_metrices
        function evalution_metricesCheckedNodesChanged(app, event)
            % Only run evaluation metrics if sensitivity analysis is NOT checked
            if ~any(app.evalution_metrices.CheckedNodes == app.SensitivityAnalysisNode)
                % --- Get selected evaluation metrics ---
                checkedNodes = app.evalution_metrices.CheckedNodes;

                % Remove previous evaluation metric annotations
                allText = findobj(app.UIAxes, 'Type', 'text');
                for i = 1:length(allText)
                    if contains(allText(i).String, 'CC:') || contains(allText(i).String, 'NSE:') || ...
                            contains(allText(i).String, 'RMSE:') || contains(allText(i).String, 'Evaluation:')
                        delete(allText(i));
                    end
                end

                % If no metrics are checked, exit silently
                if isempty(checkedNodes)
                    return;
                end

                % --- Get all line objects from the axes ---
                lineObjects = findobj(app.UIAxes, 'Type', 'line', '-and', 'LineStyle', '-');
                if isempty(lineObjects)
                    msgbox('No time series lines found on the plot. Please plot some data first.', 'Info');
                    return;
                end

                % Check if we have at least 2 datasets
                if length(lineObjects) < 2
                    msgbox('Evaluation metrics require at least 2 datasets for comparison. Please plot more datasets.', 'Info');
                    return;
                end

                % --- Let user choose reference dataset if more than 2 ---
                if length(lineObjects) > 2
                    datasetNames = {};
                    for i = 1:length(lineObjects)
                        name = lineObjects(i).DisplayName;
                        if isempty(name)
                            name = sprintf('Dataset %d', i);
                        end
                        datasetNames{end+1} = name;
                    end

                    [selection, ok] = listdlg('ListString', datasetNames, ...
                        'Name', 'Select Reference Dataset', ...
                        'PromptString', 'Choose the reference dataset (observed data):', ...
                        'SelectionMode', 'single', ...
                        'ListSize', [300 150]);

                    if ~ok
                        return;
                    end

                    referenceIdx = selection;
                    comparisonIndices = setdiff(1:length(lineObjects), referenceIdx);
                else
                    % With exactly 2 datasets, use first as reference
                    referenceIdx = 1;
                    comparisonIndices = 2;
                end

                hold(app.UIAxes, 'on');

                % --- Get the selected metrics ---
                selectedMetrics = {};
                for i = 1:length(checkedNodes)
                    if isempty(checkedNodes(i).Children)
                        metricName = lower(strtrim(checkedNodes(i).Text));
                        selectedMetrics{end+1} = metricName;
                    end
                end

                % --- Calculate metrics ---
                referenceLine = lineObjects(referenceIdx);
                referenceName = referenceLine.DisplayName;
                if isempty(referenceName)
                    referenceName = sprintf('Dataset %d', referenceIdx);
                end

                results = {};

                for i = 1:length(comparisonIndices)
                    compIdx = comparisonIndices(i);
                    compLine = lineObjects(compIdx);
                    compName = compLine.DisplayName;
                    if isempty(compName)
                        compName = sprintf('Dataset %d', compIdx);
                    end

                    % Get data
                    y_obs = referenceLine.YData;
                    y_sim = compLine.YData;

                    % Align data
                    [y_obs_aligned, y_sim_aligned] = alignData(app, y_obs, y_sim);

                    if length(y_obs_aligned) < 2
                        continue;
                    end

                    % Calculate metrics
                    metrics = struct();
                    for k = 1:length(selectedMetrics)
                        switch selectedMetrics{k}
                            case 'cc'
                                metrics.CC = calculateCorrelationCoefficient(app, y_obs_aligned, y_sim_aligned);
                            case 'nse'
                                metrics.NSE = calculateNSE(app, y_obs_aligned, y_sim_aligned);
                            case 'rmse'
                                metrics.RMSE = calculateRMSE(app, y_obs_aligned, y_sim_aligned);
                        end
                    end

                    results{end+1} = struct(...
                        'Reference', referenceName, ...
                        'Comparison', compName, ...
                        'Color', compLine.Color, ...
                        'Metrics', metrics, ...
                        'N', length(y_obs_aligned));
                end

                % --- Display results ---
                if ~isempty(results)
                    yRange = range(app.UIAxes.YLim);
                    xRange = range(app.UIAxes.XLim);
                    textX = min(app.UIAxes.XLim) + 0.02 * xRange;
                    textY = max(app.UIAxes.YLim) - 0.05 * yRange;

                    % Title with reference dataset
                    text(app.UIAxes, textX, textY, sprintf('Evaluation Metrics (Reference: %s):', referenceName), ...
                        'Color', 'k', 'FontSize', 12, 'FontWeight', 'bold', ...
                        'BackgroundColor', 'white', 'EdgeColor', 'k', ...
                        'Margin', 2, 'VerticalAlignment', 'bottom');

                    % Display each comparison result
                    for i = 1:length(results)
                        result = results{i};
                        textY = textY - 0.08 * yRange;

                        metricStr = sprintf('%s: ', result.Comparison);
                        metricParts = {};

                        if isfield(result.Metrics, 'CC')
                            metricParts{end+1} = sprintf('CC=%.3f', result.Metrics.CC);
                        end
                        if isfield(result.Metrics, 'NSE')
                            metricParts{end+1} = sprintf('NSE=%.3f', result.Metrics.NSE);
                        end
                        if isfield(result.Metrics, 'RMSE')
                            metricParts{end+1} = sprintf('RMSE=%.3f', result.Metrics.RMSE);
                        end

                        metricStr = [metricStr strjoin(metricParts, ', ')];
                        metricStr = [metricStr sprintf(' (n=%d)', result.N)];

                        text(app.UIAxes, textX, textY, metricStr, ...
                            'Color', result.Color, 'FontSize', 10, 'FontWeight', 'bold', ...
                            'BackgroundColor', 'white', 'EdgeColor', result.Color, ...
                            'Margin', 2, 'VerticalAlignment', 'bottom');
                    end
                end
                hold(app.UIAxes, 'off');
            end
            if any(app.evalution_metrices.CheckedNodes == app.SensitivityAnalysisNode)
                try
                    selectedBasin = app.BasinSelectionButton.Value;
                    if strcmpi(selectedBasin, 'Select Basin') || isempty(selectedBasin)
                        uialert(app.UIFigure, 'Please select a basin from the dropdown first.', 'No Basin Selected', 'Icon', 'warning');
                        return;
                    end
                    disp('Sensitivity Analysis tab is selected.');

                    % Ask user for reconstruction type
                    choice = questdlg('Select reconstruction type:', ...
                        'Sensitivity Analysis Type', ...
                        'Basin Average', 'All Points', 'Basin Average');

                    % Handle reconstruction type response
                    if isempty(choice)
                        return;
                    end

                    % Ask user for method type
                    methodChoice = questdlg('Select reconstruction method:', ...
                        'Reconstruction Method', ...
                        'Random Forest', 'Neural Network', 'Random Forest');

                    % Handle method type response
                    if isempty(methodChoice)
                        return;
                    end

                    % Map method choice to method type string
                    switch methodChoice
                        case 'Random Forest'
                            methodType = 'RF';
                        case 'Neural Network'
                            methodType = 'ANN';
                        otherwise
                            methodType = 'RF';
                    end

                    % Call the function with both parameters
                    switch choice
                        case 'Basin Average'
                            readAndPlotSensitivityAnalysis(app, 'basin_average', methodType);

                        case 'All Points'
                            readAndPlotSensitivityAnalysis(app, 'all_points', methodType);

                        otherwise
                            return;
                    end

                catch ME
                    % Provide more detailed error message
                    if contains(ME.message, 'file not found')
                        errMsg = sprintf(['Sensitivity analysis file not found.\n\n' ...
                            'Please ensure you have run the reconstruction with %s method ' ...
                            'for %s analysis type.\n\n' ...
                            'Error: %s'], methodChoice, choice, ME.message);
                    else
                        errMsg = sprintf('Error in sensitivity analysis: %s', ME.message);
                    end

                    uialert(app.UIFigure, errMsg, "Plotting Error", "Icon", "error");
                    fprintf('Error details: %s\n', ME.message);
                end
            end
        end

        % Callback function: validation1
        function validation1CheckedNodesChanged(app, event)
            updateAllPlots(app);
            % if ~ismember(app.SensitivityAnalysisNode, app.validation1.CheckedNodes)
            % --- Step 1: Get checked nodes ---
            checkedNodes = app.validation1.CheckedNodes;
            if isempty(checkedNodes)
                checkedTexts = {};
            elseif isa(checkedNodes, 'matlab.ui.container.TreeNode')
                checkedTexts = {checkedNodes.Text};
            elseif iscell(checkedNodes)
                checkedTexts = cellfun(@(n) n.Text, checkedNodes, 'UniformOutput', false);
            else
                checkedTexts = {};
            end

            % --- Step 2: Handle dynamic axes based on checked state ---
            if isempty(checkedTexts)  % Nothing selected - remove dynamic axes
                validation1Clicked(app);
            else  % Something selected - create/show dynamic axes
                createDynamicAxes(app);
                plotOnDynamicAxes(app, checkedTexts);
            end

            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Welcome to the Global TWSA analysing GUI: TWS100')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TWSA = uitree(app.UIFigure, 'checkbox');
            app.GRACETWSANode = uitreenode(app.TWSA);
            app.GRACETWSANode.Text = 'GRACE TWSA';
            app.JPLNode = uitreenode(app.GRACETWSANode);
            app.JPLNode.Text = 'JPL';
            app.CSRNode = uitreenode(app.GRACETWSANode);
            app.CSRNode.Text = 'CSR';
            app.GSFCNode = uitreenode(app.GRACETWSANode);
            app.GSFCNode.Text = 'GSFC';
            % Assign Checked Nodes
            app.TWSA.CheckedNodesChangedFcn = createCallbackFcn(app, @TWSACheckedNodesChanged, true);
            app.RTWSA = uitree(app.UIFigure, 'checkbox');
            app.Previous_TWSANode = uitreenode(app.RTWSA);
            app.Previous_TWSANode.Text = 'Previous_TWSA';
            app.HG19Node = uitreenode(app.Previous_TWSANode);
            app.HG19Node.Text = 'HG19';
            app.D23Node = uitreenode(app.Previous_TWSANode);
            app.D23Node.Text = 'D23';
            app.L21Node = uitreenode(app.Previous_TWSANode);
            app.L21Node.Text = 'L21';
            app.M25Node = uitreenode(app.Previous_TWSANode);
            app.M25Node.Text = 'M25';
            app.P25Node = uitreenode(app.Previous_TWSANode);
            app.P25Node.Text = 'P25';
            % Assign Checked Nodes
            app.RTWSA.CheckedNodesChangedFcn = createCallbackFcn(app, @RTWSACheckedNodesChanged, true);
            app.This_Study = uitree(app.UIFigure, 'checkbox');
            app.ThisStudyNode = uitreenode(app.This_Study);
            app.ThisStudyNode.Text = 'This Study';
            app.ANN_GNode = uitreenode(app.ThisStudyNode);
            app.ANN_GNode.Text = 'ANN_G';
            app.RF_GNode = uitreenode(app.ThisStudyNode);
            app.RF_GNode.Text = 'RF_G';
            app.ANN_IMDNode = uitreenode(app.ThisStudyNode);
            app.ANN_IMDNode.Text = 'ANN_IMD';
            app.RF_IMDNode = uitreenode(app.ThisStudyNode);
            app.RF_IMDNode.Text = 'RF_IMD';
            app.ReconstructionNode = uitreenode(app.This_Study);
            app.ReconstructionNode.Text = 'Reconstruction';
            % Assign Checked Nodes
            app.This_Study.CheckedNodesChangedFcn = createCallbackFcn(app, @This_StudyCheckedNodesChanged, true);
            app.Precipitation = uitree(app.UIFigure, 'checkbox');
            app.PrecipitationNode = uitreenode(app.Precipitation);
            app.PrecipitationNode.Text = 'Precipitation';
            app.IMDNode = uitreenode(app.PrecipitationNode);
            app.IMDNode.Text = 'IMD';
            app.ERA5Node = uitreenode(app.PrecipitationNode);
            app.ERA5Node.Text = 'ERA5';
            app.GPCPNode = uitreenode(app.PrecipitationNode);
            app.GPCPNode.Text = 'GPCP';
            % Assign Checked Nodes
            app.Precipitation.CheckedNodesChangedFcn = createCallbackFcn(app, @PrecipitationCheckedNodesChanged, true);
            app.Trend = uitree(app.UIFigure, 'checkbox');
            app.TrendsNode = uitreenode(app.Trend);
            app.TrendsNode.Text = 'Trends';
            app.LinearNode = uitreenode(app.TrendsNode);
            app.LinearNode.Text = 'Linear';
            app.PolynomialNode = uitreenode(app.TrendsNode);
            app.PolynomialNode.Text = 'Polynomial';
            app.LoessNode = uitreenode(app.TrendsNode);
            app.LoessNode.Text = 'Loess';
            app.SensslopeNode = uitreenode(app.TrendsNode);
            app.SensslopeNode.Text = 'Sens slope';
            % Assign Checked Nodes
            app.Trend.CheckedNodesChangedFcn = createCallbackFcn(app, @TrendCheckedNodesChanged, true);
            app.ExportPlotButton = uibutton(app.UIFigure, 'state');
            app.ExportPlotButton.ValueChangedFcn = createCallbackFcn(app, @ExportPlotButtonValueChanged, true);
            app.ExportPlotButton.Text = 'Export Plot';
            app.evalution_metrices = uitree(app.UIFigure, 'checkbox');
            app.EvaluationMetricsNode = uitreenode(app.evalution_metrices);
            app.EvaluationMetricsNode.Text = 'Evaluation Metrics';
            app.CCNode = uitreenode(app.EvaluationMetricsNode);
            app.CCNode.Text = 'CC';
            app.NSENode = uitreenode(app.EvaluationMetricsNode);
            app.NSENode.Text = 'NSE';
            app.RSMENode = uitreenode(app.EvaluationMetricsNode);
            app.RSMENode.Text = 'RSME';
            app.SensitivityAnalysisNode = uitreenode(app.evalution_metrices);
            app.SensitivityAnalysisNode.Text = 'Sensitivity Analysis';
            % Assign Checked Nodes
            app.evalution_metrices.CheckedNodesChangedFcn = createCallbackFcn(app, @evalution_metricesCheckedNodesChanged, true);
            app.validation1 = uitree(app.UIFigure, 'checkbox');
            app.GroundwaterNode = uitreenode(app.validation1);
            app.GroundwaterNode.Text = 'Groundwater';
            app.CGWBNode = uitreenode(app.GroundwaterNode);
            app.CGWBNode.Text = 'CGWB';
            app.GRACENode = uitreenode(app.GroundwaterNode);
            app.GRACENode.Text = 'GRACE';
            app.PrecipitationNode_2 = uitreenode(app.validation1);
            app.PrecipitationNode_2.Text = 'Precipitation';
            app.IMDNode_2 = uitreenode(app.PrecipitationNode_2);
            app.IMDNode_2.Text = 'IMD';
            % Assign Checked Nodes
            app.validation1.CheckedNodesChangedFcn = createCallbackFcn(app, @validation1CheckedNodesChanged, true);
            app.TimeSpan19012025SliderLabel = uilabel(app.UIFigure);
            app.TimeSpan19012025SliderLabel.Text = 'Time Span (1901-2025)';
            app.TimeSpan19012025Slider = uislider(app.UIFigure, 'range');
            app.TimeSpan19012025Slider.Limits = [0 1500];
            app.TimeSpan19012025Slider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500];
            app.TimeSpan19012025Slider.MajorTickLabels = {'0', '100', '200', '300', '400', '500', '600', '700', '800', '900', '1000', '1100', '1200', '1300', '1400', '1500'};
            app.TimeSpan19012025Slider.ValueChangedFcn = createCallbackFcn(app, @TimeSpanTimeSpan19012025SliderValueChanging, true);
            app.TimeSpan19012025Slider.Step = 100;
            app.TimeSpan19012025Slider.Value = [0 1500];
            app.LoadDataButton = uibutton(app.UIFigure, 'state');
            app.LoadDataButton.ValueChangedFcn = createCallbackFcn(app, @LoadDataButtonValueChanged, true);
            app.LoadDataButton.Text = 'Load Data';
            app.BasinDropDownLabel = uilabel(app.UIFigure);
            app.BasinDropDownLabel.Text = 'Basin';
            app.BasinSelectionButton = uidropdown(app.UIFigure);
            app.BasinSelectionButton.Items = {'Select Basin', 'GODAVARI'};
            app.BasinSelectionButton.Value = 'Select Basin';
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Temporal', 'Spatial', 'Regional Mean'};
            app.PlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotTypeDropDownValueChanged, true);
            app.PlotTypeDropDown.Value = 'Temporal';
            app.TimescaleDropDown = uidropdown(app.UIFigure);
            app.TimescaleDropDown.Items = {'Monthly', 'Annual', 'Decadal'};
            app.TimescaleDropDown.ValueChangedFcn = createCallbackFcn(app, @TimescaleDropDownValueChanged, true);
            app.TimescaleDropDown.Value = 'Monthly';
            app.TimescaleDropDownLabel = uilabel(app.UIFigure);
            app.TimescaleDropDownLabel.Text = 'Time scale';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TWS100

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @updateAllPlots)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end